classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KOM613EHomeworkAppUIFigure     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        MotionPanel                    matlab.ui.container.Panel
        TrajectoryButtonGroup          matlab.ui.container.ButtonGroup
        CircularButton                 matlab.ui.control.RadioButton
        UShapeButton                   matlab.ui.control.RadioButton
        ProbabilisticmodelButtonGroup  matlab.ui.container.ButtonGroup
        DeadReckoningButton            matlab.ui.control.RadioButton
        OdometryButton                 matlab.ui.control.RadioButton
        ComputingalgoButtonGroup       matlab.ui.container.ButtonGroup
        SamplingButton                 matlab.ui.control.RadioButton
        DirectEvaluationButton         matlab.ui.control.RadioButton
        ofsampleEditFieldLabel         matlab.ui.control.Label
        ofsampleEditField              matlab.ui.control.NumericEditField
        ButtonGroup_4                  matlab.ui.container.ButtonGroup
        NormalButton                   matlab.ui.control.RadioButton
        TriangularButton               matlab.ui.control.RadioButton
        NoisedistLabel                 matlab.ui.control.Label
        ErrorparametersPanel           matlab.ui.container.Panel
        e2Label_1                      matlab.ui.control.Label
        e2Label_2                      matlab.ui.control.Label
        e2Label_3                      matlab.ui.control.Label
        e2Label_4                      matlab.ui.control.Label
        e2Label_5                      matlab.ui.control.Label
        e2Label_6                      matlab.ui.control.Label
        SpinnerLabel_3                 matlab.ui.control.Label
        Spinner_3                      matlab.ui.control.Spinner
        SpinnerLabel_2                 matlab.ui.control.Label
        Spinner_2                      matlab.ui.control.Spinner
        SpinnerLabel_5                 matlab.ui.control.Label
        Spinner_5                      matlab.ui.control.Spinner
        SpinnerLabel_4                 matlab.ui.control.Label
        Spinner_4                      matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        Spinner_1                      matlab.ui.control.Spinner
        SpinnerLabel_6                 matlab.ui.control.Label
        Spinner_6                      matlab.ui.control.Spinner
        MainPanel                      matlab.ui.container.Panel
        ExecuteButton                  matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        TotalofsamplesEditFieldLabel   matlab.ui.control.Label
        TotalofsamplesEditField        matlab.ui.control.NumericEditField
        NavigationPanel                matlab.ui.container.Panel
        SensorPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        nStp = 0;
        nSmp = 1;
        
        nTotalSmp = 0;
        
        prob_type = 0;
        traj_type = 0;
        comp_type = 0;
        
        x;
        y;
        theta;
        
        v
        omega;
        
        x_array;
        y_array;
        theta_array;
        
        x_t_array;
        v_t_array;
    end
    
    properties (Access = public)
        
        dt = 1;
        dist_type = 0;
        
        alpha1 = 0;
        alpha2 = 0;
        alpha3 = 0;
        alpha4 = 0;
        alpha5 = 0;
        alpha6 = 0;
        
    end
    
    methods (Access = private)
        
        function updateTrajectory(app)
            
            if app.traj_type == 0
                app.nStp = 8;
                
                app.x = 1;
                app.y = 0;
                app.theta = -pi/2;
                
                app.v = pi/4*ones(1,app.nStp); % m/s
                app.omega = -pi/4*ones(1,app.nStp); % rad/s
                
                app.x_array     = app.x*ones(1,app.nStp);
                app.y_array     = app.y*ones(1,app.nStp);
                app.theta_array = app.theta*ones(1,app.nStp);
                
                for i = 1:app.nStp-1
                    app.x_array(1,i+1) = app.x_array(1,i) - (app.v(1,i)/app.omega(1,i))*(sin(app.theta_array(1,i)) - sin(app.theta_array(1,i)+app.omega(1,i)));
                    app.y_array(1,i+1) = app.y_array(1,i) + (app.v(1,i)/app.omega(1,i))*(cos(app.theta_array(1,i)) - cos(app.theta_array(1,i)+app.omega(1,i)));
                    app.theta_array(1,i+1) = app.theta_array(1,i) + app.omega(1,i);
                end
                
                ylim(app.UIAxes,[min(app.y_array)-0.25 max(app.y_array)+0.25])
            else
                if app.prob_type == 0
                    app.nStp = 15;
                    
                    app.x_array = [0,2,4,6,8,8,8,8,8,8,8,6,4,2,0];
                    app.y_array = [0,0,0,0,0,0,2,4,6,8,8,8,8,8,8];
                    app.theta_array = [0,0,0,0,0,pi/2,pi/2,pi/2,pi/2,pi/2,pi,pi,pi,pi,pi];
                else
                    app.nStp = 13;
                    
                    app.x_array = [0,2,4,6,8,8,8,8,8,6,4,2,0];
                    app.y_array = [0,0,0,0,0,2,4,6,8,8,8,8,8];
                    app.theta_array = [0,0,0,0,0,pi/2,pi/2,pi/2,pi,pi,pi,pi,pi];
                end
                
                app.v = zeros(1,app.nStp); % m/s
                app.omega = zeros(1,app.nStp); % rad/s
                
                for i = 1:app.nStp-1
                    app.v(1,i) = sqrt((app.x_array(1,i+1)-app.x_array(1,i))^2+(app.y_array(1,i+1)-app.y_array(1,i))^2); % m/s
                    app.omega(1,i) = app.theta_array(1,i+1)-app.theta_array(1,i); % rad/s
                end
                
                ylim(app.UIAxes,[min(app.y_array)-1 max(app.y_array)+1])
            end
            
            app.x_t_array = [app.x_array;app.y_array;app.theta_array];
            app.v_t_array = [app.v;app.omega];
            
            cla(app.UIAxes)
            plot(app.UIAxes,app.x_array,app.y_array,'.k')
        end            
        
        function updateProbModel(app)
            
            if app.prob_type == 0
                app.Spinner_5.Visible = 'on';
                app.SpinnerLabel_5.Visible = 'on';
                app.e2Label_3.Visible = 'on';
                app.Spinner_6.Visible = 'on';
                app.SpinnerLabel_6.Visible = 'on';
                app.e2Label_6.Visible = 'on';
            else
                app.Spinner_5.Visible = 'off';
                app.SpinnerLabel_5.Visible = 'off';
                app.e2Label_3.Visible = 'off';
                app.Spinner_6.Visible = 'off';
                app.SpinnerLabel_6.Visible = 'off';
                app.e2Label_6.Visible = 'off';
            end
            
            updateTrajectory(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            updateTrajectory(app)
            axis(app.UIAxes,'equal')
            
        end

        % Size changed function: KOM613EHomeworkAppUIFigure
        function KOM613EHomeworkAppUIFigureSizeChanged(app, event)
%             position = app.KOM613EHomeworkAppUIFigure.Position;
%             
%             if position(3) < 700
%                 app.KOM613EHomeworkAppUIFigure.Position(3) = 700;
%             end
%             if position(4) < 450
%                 app.KOM613EHomeworkAppUIFigure.Position(4) = 450;
%             end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            
            if app.comp_type == 1
                
                % direct evaluation
            
                if app.traj_type == 0
                    x_grid = -2:0.01:2;
                    y_grid = -2:0.01:2;
                else
                    x_grid = -1:0.01:10;
                    y_grid = -1:0.01:10;
                end
            
                p_x_t = zeros(length(x_grid),length(y_grid),app.nStp);
                for k = 1:app.nStp-1
            
                    for j = 1:length(x_grid)
                        for i = 1:length(y_grid)
                            x_t = [x_grid(j);y_grid(i);0];
                            if app.prob_type == 0
                                u_t = app.v_t_array(:,k);
                                p_x_t(i,j,k) = motion_model_velocity(app,x_t,u_t,app.x_t_array(:,k));
                            else
                                u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                                p_x_t(i,j,k) = motion_model_odometry(app,x_t,u_t,app.x_t_array(:,k));
                            end
                        end
                    end
            
                end
            
                dim = 3;
            
                for k = 1:app.nStp-1
                    figure
                    plot(app.x_t_array(1,:),app.x_t_array(2,:),'.k')
                    hold on
                    azSurf1 = surf(x_grid,y_grid,p_x_t(:,:,k));
                    xlabel('x')
                    ylabel('y')
                    shading interp
                    set(azSurf1, 'FaceAlpha', 'interp','AlphaData',p_x_t(:,:,k));
                    % axis equal
                    if app.traj_type == 0
                        if app.prob_type == 0
                            zlim auto
                        else
                            zlim([0 9])
                        end
                    else
                        if app.prob_type == 0
                            zlim auto
                        else
                            zlim([0 5])
                        end
                    end
                    view(dim)
                end
                
                dim = 2;
            
                for k = 1:app.nStp-1
                    figure
                    plot(app.x_t_array(1,:),app.x_t_array(2,:),'.k')
                    hold on
                    azSurf1 = surf(x_grid,y_grid,p_x_t(:,:,k));
                    xlabel('x')
                    ylabel('y')
                    shading interp
                    set(azSurf1, 'FaceAlpha', 'interp','AlphaData',p_x_t(:,:,k));
                    if dim == 2
                        axis equal
                    end
                    view(dim)
                end
    
%                 x_grid = -2:0.01:2;
%                 y_grid = -2:0.01:2;
%                 count = 0;
%                 
%                 x_t_i = zeros(3,app.nStp);
%                 x_t_i(:,1) = [app.x_array(1);app.y_array(1);app.theta_array(1)];
%                 p_x_t = zeros(length(x_grid),length(y_grid),app.nStp);
%                 for k = 1:app.nStp-1
%                     
%                     u_t = [[app.x_array(k);app.y_array(k);app.theta_array(k)];[app.x_array(k+1);app.y_array(k+1);app.theta_array(k+1)]]
%                     
%                     x_t_i(:,k+1) = hypothesized_motion_model_velocity(app,u_t,x_t_i(:,k))
%                     
%                     for j = 1:length(x_grid)
%                         for i = 1:length(y_grid)
%                             x_t = [x_grid(j);y_grid(i);0];
%                             p_x_t(i,j,k) = motion_model_odometry(app,x_t,u_t,x_t_i(:,k));
%                         end
%                     end
%                     p_x_t(:,:,k)
% %                     figure
% %                     ax = surf(app.UIAxes,x_grid,y_grid,p_x_t(:,:,k));
% %                     shading('interp')
% %                     set(ax, 'FaceAlpha', 'interp','AlphaData',p_x_t(:,:,k));
%                     
% %                     cla(app.UIAxes)
% %                     ax = surf(app.UIAxes,x_grid,y_grid,p_x_t(:,:,k));
% %                     shading(app.UIAxes,'interp')
% %                     set(ax, 'FaceAlpha', 'interp','AlphaData',p_x_t(:,:,k));
% %                     zlim(app.UIAxes,'auto')
%                     
%                     figure(k)
% %                     cla(app.UIAxes)
%                     ax = surf(x_grid,y_grid,p_x_t(:,:,k));
%                     shading('interp')
%                     set(ax, 'FaceAlpha', 'interp','AlphaData',p_x_t(:,:,k));
% %                     zlim('auto')
%                     pause(1)
%                     count = count + 1
%                 end
                    
%                 x_t = zeros(3,app.nStp);
%                 x_t(:,1) = [app.x_array(1);app.y_array(1);app.theta_array(1)];
%                 p_x_t = zeros(app.nStp,1);
%                 for i = 1:app.nStp-1
%                     
%                     x_tprev = x_t;
%                     
%                     if app.prob_type == 0
%                         u_t = [app.v(i);app.omega(i)];
%                         x_t = hypothesized_motion_model_velocity(app,u_t,x_tprev);
%                         p_x_t(i) = motion_model_velocity(app,x_t,u_t,x_tprev);
%                     end
%                     
%                 end
%                 p_x_t
            else
                app.nTotalSmp = app.nTotalSmp + app.nSmp;
                app.TotalofsamplesEditField.Value = app.nTotalSmp;
                
                x_t = zeros(3,app.nSmp*app.nStp);
                for j = 1:app.nSmp
                    
                    k = 1;
                    x_t(:,1+(j-1)*app.nStp) = app.x_t_array(:,k);
    
                    for i = 1+(j-1)*app.nStp:j*(app.nStp)-1
                        
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                            x_t(:,i+1) = sample_motion_model_velocity(app,u_t,x_t(:,i));
                        else
                            u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                            x_t(:,i+1) = sample_motion_model_odometry(app,u_t,x_t(:,i));
                        end
    
                        k = k+1;
                    end
                end
    
                hold(app.UIAxes,'on')
                cmap = jet(app.nStp);
                h = zeros(app.nStp,1);
                for i = 1:app.nStp
                    h(i) = plot(app.UIAxes,x_t(1,i:app.nStp:app.nSmp*(app.nStp)),x_t(2,i:app.nStp:app.nSmp*(app.nStp)),'.','Color', cmap(i, :));
                end
                set(h(1),'Marker','x')
                
                ylim(app.UIAxes,'auto')
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
            close all
            updateTrajectory(app)
            
            app.nTotalSmp = 0;
            app.TotalofsamplesEditField.Value = app.nTotalSmp;
            
        end

        % Value changing function: Spinner_1
        function Spinner_1_7ValueChanging(app, event)
            app.alpha1 = event.Value*0.01;
            updateTrajectory(app)
        end

        % Value changing function: Spinner_2
        function Spinner_2_2ValueChanging(app, event)
            app.alpha2 = event.Value*0.01;
            updateTrajectory(app)
        end

        % Value changing function: Spinner_3
        function Spinner_3ValueChanging(app, event)
            app.alpha3 = event.Value*0.01;
            updateTrajectory(app)
        end

        % Value changing function: Spinner_4
        function Spinner_4_4ValueChanging(app, event)
            app.alpha4 = event.Value*0.01;
            updateTrajectory(app)
        end

        % Value changing function: Spinner_5
        function Spinner_5_3ValueChanging(app, event)
            app.alpha5 = event.Value*0.01;
            updateTrajectory(app)
        end

        % Value changing function: Spinner_6
        function Spinner_6_6ValueChanging(app, event)
            app.alpha6 = event.Value*0.01;
            updateTrajectory(app)
        end

        % Value changed function: ofsampleEditField
        function ofsampleEditFieldValueChanged(app, event)
            app.nSmp = app.ofsampleEditField.Value;
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_4.SelectedObject;
            
            if strcmp(selectedButton.Text,'Triangular')
                app.dist_type = 1;
            else
                app.dist_type = 0;
            end
            
            updateTrajectory(app)
        end

        % Selection changed function: ProbabilisticmodelButtonGroup
        function ProbabilisticmodelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProbabilisticmodelButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Odometry')
                app.prob_type = 1;
            else
                app.prob_type = 0;
            end
            
            updateProbModel(app)
        end

        % Selection changed function: TrajectoryButtonGroup
        function TrajectoryButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrajectoryButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'U-Shape')
                app.traj_type = 1;
            else
                app.traj_type = 0;
            end
            
            updateTrajectory(app)
        end

        % Selection changed function: ComputingalgoButtonGroup
        function ComputingalgoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ComputingalgoButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Sampling')
                app.ofsampleEditField.Editable = 'on';
                app.comp_type = 0;
            else
                app.ofsampleEditField.Editable = 'off';
                app.comp_type = 1;
            end
            
            updateTrajectory(app)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('KOM 613E-Homework #3\nMertcan Kaya\n518192004');
            uialert(app.KOM613EHomeworkAppUIFigure,message,'About','Icon','info');
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KOM613EHomeworkAppUIFigure = uifigure('Visible', 'off');
            app.KOM613EHomeworkAppUIFigure.Name = 'KOM613E Homework App';
            app.KOM613EHomeworkAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @KOM613EHomeworkAppUIFigureSizeChanged, true);
            app.FileMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.KOM613EHomeworkAppUIFigure);
            app.MotionPanel = uipanel(app.GridLayout);
            app.MotionPanel.Title = 'Motion';
            app.TrajectoryButtonGroup = uibuttongroup(app.MotionPanel);
            app.TrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrajectoryButtonGroupSelectionChanged, true);
            app.TrajectoryButtonGroup.Title = 'Trajectory';
            app.CircularButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.UShapeButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.UShapeButton.Text = 'U-Shape';
            app.ProbabilisticmodelButtonGroup = uibuttongroup(app.MotionPanel);
            app.ProbabilisticmodelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProbabilisticmodelButtonGroupSelectionChanged, true);
            app.ProbabilisticmodelButtonGroup.Title = 'Probabilistic model';
            app.DeadReckoningButton = uiradiobutton(app.ProbabilisticmodelButtonGroup);
            app.DeadReckoningButton.Text = 'Dead Reckoning';
            app.DeadReckoningButton.Value = true;
            app.OdometryButton = uiradiobutton(app.ProbabilisticmodelButtonGroup);
            app.OdometryButton.Text = 'Odometry';
            app.ComputingalgoButtonGroup = uibuttongroup(app.MotionPanel);
            app.ComputingalgoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComputingalgoButtonGroupSelectionChanged, true);
            app.ComputingalgoButtonGroup.Title = 'Computing algo';
            app.SamplingButton = uiradiobutton(app.ComputingalgoButtonGroup);
            app.SamplingButton.Text = 'Sampling';
            app.SamplingButton.Value = true;
            app.DirectEvaluationButton = uiradiobutton(app.ComputingalgoButtonGroup);
            app.DirectEvaluationButton.Text = 'Direct Evaluation';
            app.ofsampleEditFieldLabel = uilabel(app.ComputingalgoButtonGroup);
            app.ofsampleEditFieldLabel.Text = '# of sample';
            app.ofsampleEditField = uieditfield(app.ComputingalgoButtonGroup, 'numeric');
            app.ofsampleEditField.Limits = [1 999];
            app.ofsampleEditField.ValueChangedFcn = createCallbackFcn(app, @ofsampleEditFieldValueChanged, true);
            app.ofsampleEditField.Value = 1;
            app.ButtonGroup_4 = uibuttongroup(app.MotionPanel);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.NormalButton = uiradiobutton(app.ButtonGroup_4);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.TriangularButton = uiradiobutton(app.ButtonGroup_4);
            app.TriangularButton.Text = 'Triangular';
            app.NoisedistLabel = uilabel(app.ButtonGroup_4);
            app.NoisedistLabel.Text = 'Noise dist:';
            app.ErrorparametersPanel = uipanel(app.MotionPanel);
            app.ErrorparametersPanel.Title = 'Error parameters';
            app.e2Label_1 = uilabel(app.ErrorparametersPanel);
            app.e2Label_1.Text = 'e-2';
            app.e2Label_2 = uilabel(app.ErrorparametersPanel);
            app.e2Label_2.Text = 'e-2';
            app.e2Label_3 = uilabel(app.ErrorparametersPanel);
            app.e2Label_3.Text = 'e-2';
            app.e2Label_4 = uilabel(app.ErrorparametersPanel);
            app.e2Label_4.Text = 'e-2';
            app.e2Label_5 = uilabel(app.ErrorparametersPanel);
            app.e2Label_5.Text = 'e-2';
            app.e2Label_6 = uilabel(app.ErrorparametersPanel);
            app.e2Label_6.Text = 'e-2';
            app.SpinnerLabel_3 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_3.Text = 'α 3';
            app.Spinner_3 = uispinner(app.ErrorparametersPanel);
            app.Spinner_3.Step = 0.001;
            app.Spinner_3.ValueChangingFcn = createCallbackFcn(app, @Spinner_3ValueChanging, true);
            app.Spinner_3.Limits = [0 10];
            app.SpinnerLabel_2 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_2.Text = 'α 2';
            app.Spinner_2 = uispinner(app.ErrorparametersPanel);
            app.Spinner_2.Step = 0.001;
            app.Spinner_2.ValueChangingFcn = createCallbackFcn(app, @Spinner_2_2ValueChanging, true);
            app.Spinner_2.Limits = [0 10];
            app.SpinnerLabel_5 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_5.Text = 'α 5';
            app.Spinner_5 = uispinner(app.ErrorparametersPanel);
            app.Spinner_5.Step = 0.001;
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_5_3ValueChanging, true);
            app.Spinner_5.Limits = [0 10];
            app.SpinnerLabel_4 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_4.Text = 'α 4';
            app.Spinner_4 = uispinner(app.ErrorparametersPanel);
            app.Spinner_4.Step = 0.001;
            app.Spinner_4.ValueChangingFcn = createCallbackFcn(app, @Spinner_4_4ValueChanging, true);
            app.Spinner_4.Limits = [0 10];
            app.Label = uilabel(app.ErrorparametersPanel);
            app.Label.Text = 'α 1';
            app.Spinner_1 = uispinner(app.ErrorparametersPanel);
            app.Spinner_1.Step = 0.001;
            app.Spinner_1.ValueChangingFcn = createCallbackFcn(app, @Spinner_1_7ValueChanging, true);
            app.Spinner_1.Limits = [0 10];
            app.SpinnerLabel_6 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_6.Text = 'α 6';
            app.Spinner_6 = uispinner(app.ErrorparametersPanel);
            app.Spinner_6.Step = 0.001;
            app.Spinner_6.ValueChangingFcn = createCallbackFcn(app, @Spinner_6_6ValueChanging, true);
            app.Spinner_6.Limits = [0 10];
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanel.Title = 'Main';
            app.ExecuteButton = uibutton(app.MainPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ClearButton = uibutton(app.MainPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TotalofsamplesEditFieldLabel = uilabel(app.MainPanel);
            app.TotalofsamplesEditFieldLabel.Text = 'Total # of samples';
            app.TotalofsamplesEditField = uieditfield(app.MainPanel, 'numeric');
            app.TotalofsamplesEditField.Editable = 'off';
            app.NavigationPanel = uipanel(app.GridLayout);
            app.NavigationPanel.Title = 'Navigation';
            app.SensorPanel = uipanel(app.GridLayout);
            app.SensorPanel.Title = 'Sensor';
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.KOM613EHomeworkAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KOM613EHomeworkAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KOM613EHomeworkAppUIFigure)
        end
    end
end