classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KOM613EHomeworkAppUIFigure     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        DrawsymbolicrobotMenu          matlab.ui.container.Menu
        DrawtrajectoryMenu             matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        MotionPanel                    matlab.ui.container.Panel
        TrajectoryButtonGroup          matlab.ui.container.ButtonGroup
        CircularButton                 matlab.ui.control.RadioButton
        UShapeButton                   matlab.ui.control.RadioButton
        ProbabilisticmodelButtonGroup  matlab.ui.container.ButtonGroup
        DeadReckoningButton            matlab.ui.control.RadioButton
        OdometryButton                 matlab.ui.control.RadioButton
        ComputingalgoButtonGroup       matlab.ui.container.ButtonGroup
        SamplingButton                 matlab.ui.control.RadioButton
        DirectEvaluationButton         matlab.ui.control.RadioButton
        SampleSpinnerLabel             matlab.ui.control.Label
        SampleSpinner                  matlab.ui.control.Spinner
        NoisedistButtonGroup           matlab.ui.container.ButtonGroup
        NormalButton                   matlab.ui.control.RadioButton
        TriangularButton               matlab.ui.control.RadioButton
        NoisedistLabel                 matlab.ui.control.Label
        ErrorparametersPanel           matlab.ui.container.Panel
        e2Label_1                      matlab.ui.control.Label
        e2Label_2                      matlab.ui.control.Label
        e2Label_3                      matlab.ui.control.Label
        e2Label_4                      matlab.ui.control.Label
        e2Label_5                      matlab.ui.control.Label
        e2Label_6                      matlab.ui.control.Label
        SpinnerLabel_3                 matlab.ui.control.Label
        Spinner_3                      matlab.ui.control.Spinner
        SpinnerLabel_2                 matlab.ui.control.Label
        Spinner_2                      matlab.ui.control.Spinner
        SpinnerLabel_5                 matlab.ui.control.Label
        Spinner_5                      matlab.ui.control.Spinner
        SpinnerLabel_4                 matlab.ui.control.Label
        Spinner_4                      matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        Spinner_1                      matlab.ui.control.Spinner
        SpinnerLabel_6                 matlab.ui.control.Label
        Spinner_6                      matlab.ui.control.Spinner
        MainPanel                      matlab.ui.container.Panel
        ClearButton                    matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        SamplePositionsButton          matlab.ui.control.StateButton
        MonteCarloButton               matlab.ui.control.StateButton
        EKFPredictionsButton           matlab.ui.control.StateButton
        UKFPredictionsButton           matlab.ui.control.StateButton
        NavigationPanel                matlab.ui.container.Panel
        SensorPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        nStp = 0;
        nSmp = 0;
        
        prob_type = 0;
        traj_type = 0;
        comp_type = 0;
        
        toggle_traj = 1;
        toggle_robot = 0;
        toggle_points = 1;
        toggle_monte = 0;
        toggle_ekfp = 0;
        toggle_ukfp = 0;
        
        x_array;
        y_array;
        theta_array;
        
        x_t_array;
        v_t_array;
        
        x_t_sample;
    end
    
    properties (Access = public)
        
        dt = 1;
        dist_type = 0;
        
        alpha1 = 0;
        alpha2 = 0;
        alpha3 = 0;
        alpha4 = 0;
        alpha5 = 0;
        alpha6 = 0;
        
    end
    
    methods (Access = private)
        
        function x_t = g_t(app,u_t,x_tprev)
        
            x       = x_tprev(1);
            y       = x_tprev(2);
            theta   = x_tprev(3);
            
            if app.prob_type == 0
                v       = u_t(1);
                omega   = u_t(2);
            
                if abs(omega) > 1e-5
                    x_t = [ x - (v/omega)*(sin(theta) - sin(theta+omega*app.dt))
                            y + (v/omega)*(cos(theta) - cos(theta+omega*app.dt))
                            theta + omega*app.dt ];
                else
                    x_t = [ x + v*cos(theta + omega*app.dt)
                            y + v*sin(theta + omega*app.dt)
                            theta + omega*app.dt ];
                end
            else
                x_bar       = u_t(1);
                y_bar       = u_t(2);
                theta_bar	= u_t(3);
        
                x_bar_prm       = u_t(4);
                y_bar_prm       = u_t(5);
                theta_bar_prm   = u_t(6);
            
                x_t = [	x + sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*cos(theta + atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar)
                        y + sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*sin(theta + atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar)
                        theta + theta_bar_prm - theta_bar ];
            end
        
        end
        
        function g_t_prm = G_t(app,u_t,x_tprev)
        
            theta   = x_tprev(3);
            
            if app.prob_type == 0
                v       = u_t(1);
                omega   = u_t(2);
            
                if abs(omega) > 1e-5
                    g_t_prm = [	1,0,(v/omega)*(cos(omega*app.dt + theta) - cos(theta))
                                0,1,(v/omega)*(sin(omega*app.dt + theta) - sin(theta))
                                0,0,1 ];
                else
                    g_t_prm = [	1,0,-v*sin(omega*app.dt + theta)
                                0,1,v*cos(omega*app.dt + theta)
                                0,0,1 ];
                end
            else
                x_bar       = u_t(1);
                y_bar       = u_t(2);
                theta_bar	= u_t(3);
        
                x_bar_prm 	= u_t(4);
                y_bar_prm 	= u_t(5);
            
                g_t_prm = [	1, 0, -sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*sin(atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar + theta)
                          	0, 1,  sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*cos(atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar + theta)
                           	0, 0,  1 ];
            end
        
        end

        function drawFunc(app)
            
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            
            drawTrajectory(app)
            
            if app.nSmp > 0
                cmap = jet(app.nStp);
                
                x_steps = zeros(3,app.nSmp,app.nStp);
                for i = 1:app.nStp
                    x_steps(:,:,i) = app.x_t_sample(:,i:app.nStp:app.nSmp*app.nStp);
                end
    
                if app.toggle_points == 1
                    h = zeros(app.nStp,1);
                    for i = 1:app.nStp
                        h(i) = plot(app.UIAxes,x_steps(1,:,i),x_steps(2,:,i),'.','Color', cmap(i, :));
                    end
                    set(h(1),'Marker','x')
                end
                
                mu = zeros(3,app.nStp);
                Sigma = zeros(3,3,app.nStp);
                for k = 1:app.nStp
                    mu(:,k) = mean(x_steps(:,:,k),2);
                    if app.nSmp > 1
                        S12 = ((x_steps(1,:,k)-mu(1,k))*(x_steps(2,:,k)-mu(2,k))')/(app.nSmp-1);
                        S13 = ((x_steps(1,:,k)-mu(1,k))*(x_steps(3,:,k)-mu(3,k))')/(app.nSmp-1);
                        S23 = ((x_steps(2,:,k)-mu(2,k))*(x_steps(3,:,k)-mu(3,k))')/(app.nSmp-1);
                    else
                        S12 = 0;
                        S13 = 0;
                        S23 = 0;
                    end
                    Sigma(:,:,k) = [var(x_steps(1,:,k)) S12 S13;S12 var(x_steps(2,:,k)) S23;S13 S23 var(x_steps(3,:,k))];
                end
                
                Sigma = real(Sigma);
                
                R = Sigma(:,:,2);
    
                if app.toggle_monte == 1
                    for k = 1:app.nStp
                        drawEllipse(app,mu(:,k),Sigma(:,:,k),cmap(k, :))
                    end
                end
                
                if app.toggle_ekfp == 1
                    
                    mu_bar = zeros(3,app.nStp-1);
                    Sigma_bar = zeros(3,3,app.nStp-1);
                    
                    % prediction
                    mu_bar(:,1)         = app.x_t_array(:,1);
                    Sigma_bar(:,:,1)	= Sigma(:,:,1);
                    
                    drawEllipse(app,mu_bar(:,1),Sigma_bar(:,:,1),'#00FFFF')
                    
                    for k = 1:app.nStp-1
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                        else
                          	u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                        end
                        g_tk = g_t(app,u_t,mu_bar(:,k));
                        G_tk = G_t(app,u_t,mu_bar(:,k));
                    
                        % prediction
                        mu_bar(:,k+1)       = g_tk;
                        Sigma_bar(:,:,k+1)	= G_tk*Sigma_bar(:,:,k)*G_tk' + R;
                    
                        drawEllipse(app,mu_bar(:,k+1),Sigma_bar(:,:,k+1),'#00FFFF')
                    end
                    plot(app.UIAxes,app.x_t_array(1,:),app.x_t_array(2,:),'.c')
    
                end
            
                if app.toggle_ukfp == 1
                    
                    n = 3;
                    chiNb = 2*n + 1;
                    
                    kappa = 10;      % kappa >= 0
                    alpha = 0.5;    % alpha € (0,1]
                    lambda = alpha^2*(n+kappa)-n;
                    beta = 2;
                    
                    mu_bar_u = zeros(3,1,app.nStp);
                    Sigma_bar_u = zeros(3,3,app.nStp);
                    
                    mu_bar_u(:,:,1) = app.x_t_array(:,1);
                    Sigma_bar_u(:,:,1) = Sigma(:,:,1);

                    drawEllipse(app,mu_bar_u(:,:,1),Sigma_bar_u(:,:,1),'#FF00FF')
                    
                    chi_bar = zeros(3,chiNb,app.nStp-1);
                    for k = 1:app.nStp-1
                        chi = zeros(3,chiNb);
                        if sum(Sigma_bar_u(:,:,k),'all') > 1e-7
                            mu_offset = real(sqrtm((n+lambda)*Sigma_bar_u(:,:,k)));
                        else
                            mu_offset = zeros(size(Sigma_bar_u(:,:,k)));
                        end
                        for j = 1:chiNb
                            if j == 1
                                chi(:,j) = mu_bar_u(:,:,k);
                            elseif j <= n+1
                                chi(:,j) = mu_bar_u(:,:,k) + mu_offset(:,j-1);
                            else
                                chi(:,j) = mu_bar_u(:,:,k) - mu_offset(:,j-n-1);
                            end
                        end

                        omega_m = zeros(1,chiNb);
                        omega_c = zeros(1,chiNb);
                        for j = 1:chiNb
                            if j == 1
                                omega_m(j) = lambda/(n+lambda);
                                omega_c(j) = lambda/(n+lambda)+1-alpha^2+beta;
                            else
                                omega_m(j) = 1/(2*(n+lambda));
                                omega_c(j) = 1/(2*(n+lambda));
                            end
                        end
                    
                        chi_tk_bar_s = zeros(3,chiNb);
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                        else
                          	u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                        end
                        for j = 1:chiNb
                            chi_tk_bar_s(:,j) = g_t(app,u_t,chi(:,j));
                        end

                        for j = 1:chiNb
                            mu_bar_u(:,:,k+1) = mu_bar_u(:,:,k+1) + omega_m(j)*chi_tk_bar_s(:,j);
                        end

                        for j = 1:chiNb
                            Sigma_bar_u(:,:,k+1) = Sigma_bar_u(:,:,k+1) + omega_c(j)*(chi_tk_bar_s(:,j)-mu_bar_u(:,:,k+1))*(chi_tk_bar_s(:,j)-mu_bar_u(:,:,k+1))';
                        end
                        Sigma_bar_u(:,:,k+1) = Sigma_bar_u(:,:,k+1) + R;

                        if sum(Sigma_bar_u(:,:,k+1),'all') > 1e-7
                            mu_tk_offset_bar = real(sqrtm((n+lambda)*Sigma_bar_u(:,:,k+1)));
                        else
                            mu_tk_offset_bar = zeros(size(Sigma_bar_u(:,:,k+1)));
                        end
                        for j = 1:chiNb
                            if j == 1
                                chi_bar(:,j,k+1) = mu_bar_u(:,:,k+1);
                            elseif j <= n+1
                                chi_bar(:,j,k+1) = mu_bar_u(:,:,k+1) + mu_tk_offset_bar(:,j-1);
                            else
                                chi_bar(:,j,k+1) = mu_bar_u(:,:,k+1) - mu_tk_offset_bar(:,j-n-1);
                            end
                        end

                        drawEllipse(app,mu_bar_u(:,:,k+1),Sigma_bar_u(:,:,k+1),'#FF00FF')
                        plot(app.UIAxes,chi_bar(1,[1:3 5:6],k+1),chi_bar(2,[1:3 5:6],k+1),'Marker','.','LineStyle','none','Color','#FF00FF','LineWidth',1);

                    end
    
                end
                
                ylim(app.UIAxes,'auto')
            end
        end
        
        function drawTrajectory(app)
            
            hold(app.UIAxes,'on')
            if app.toggle_robot == 1                
                for k = 1:app.nStp
                    drawRobot(app,app.x_t_array(:,k))
                end
            else
                plot(app.UIAxes,app.x_array,app.y_array,'.k','Marker','*','MarkerSize',5,'LineWidth',2)
            end
            
            if app.toggle_traj == 1
                if app.traj_type == 0
                    plot(app.UIAxes,cos(linspace(0,-7*pi/4)),sin(linspace(0,-7*pi/4)),':k')
                else
                    plot(app.UIAxes,app.x_array,app.y_array,':k')
                end
            end
            
        end
        
        function drawEllipse(app,mu,Sigma,color)
        
            if sum(isnan(Sigma),'all') || sum(~isreal(Sigma),'all')
                return
            else
        %         the 95% confidence interval error ellipse
                chisquare_val = sqrt(5.991);
                
        %         % the 99% confidence interval error ellipse
        %         chisquare_val = sqrt(9.210);
                
                theta_grid = linspace(0,2*pi);
        
                [eigenvec, eigenval] = eig(Sigma(1:2,1:2));
        
                % Get the index of the largest eigenvector
                [largest_eigenvec_ind_c, ~] = find(eigenval == max(max(eigenval)));
                largest_eigenvec = eigenvec(:, largest_eigenvec_ind_c);
        
                % Get the largest eigenvalue
                largest_eigenval = max(max(eigenval));
        
                % Get the smallest eigenvector and eigenvalue
                if(largest_eigenvec_ind_c == 1)
                    smallest_eigenval = max(eigenval(:,2));
        %             smallest_eigenvec = eigenvec(:,2);
                else
                    smallest_eigenval = max(eigenval(:,1));
        %             smallest_eigenvec = eigenvec(1,:);
                end
        
                % Calculate the angle between the x-axis and the largest eigenvector
                angle = atan2(largest_eigenvec(2), largest_eigenvec(1));
        
                % This angle is between -pi and pi.
                % Let's shift it such that the angle is between 0 and 2pi
                if(angle < 0)
                    angle = angle + 2*pi;
                end
        
                phi = angle;
                a = chisquare_val * sqrt(largest_eigenval);
                b = chisquare_val * sqrt(smallest_eigenval);
        
                % the ellipse in x and y coordinates 
                ellipse_x_r  = a*cos(theta_grid);
                ellipse_y_r  = b*sin(theta_grid);
        
                % rotation matrix of angle phi
                rot = [cos(phi) sin(phi); -sin(phi) cos(phi)];
        
                % rotation of the ellipse to angle phi
                r_ellipse = [ellipse_x_r;ellipse_y_r]' * rot;
        
                plot(app.UIAxes,mu(1),mu(2),'Marker','*','Color',color);
                plot(app.UIAxes,r_ellipse(:,1) + mu(1),r_ellipse(:,2) + mu(2),'Color',color);
            end
            
        end

        function drawRobot(app,x_vec)
                        
            xR = x_vec(1);
            yR = x_vec(2);
            tR = x_vec(3);
            
            if app.traj_type == 0
                r = 0.1;
            else
                r = 0.35;
            end
            
            hold(app.UIAxes,"on")
            plot(app.UIAxes,xR + r*cos(linspace(0,2*pi)),yR + r*sin(linspace(0,2*pi)),'-k',[xR,xR + r*cos(tR)],[yR,yR + r*sin(tR)],'-k',xR,yR,'.k')
            
        end
        
        function runDirectEval(app)
            
            if app.traj_type == 0
                x_grid = -2:0.01:2;
                y_grid = -2:0.01:2;
            else
                x_grid = -1:0.05:10;
                y_grid = -1:0.05:10;
            end
            [x_mesh,y_mesh] = meshgrid(x_grid,y_grid);
        
            p_x_t = zeros(length(x_grid),length(y_grid),app.nStp-1);
            for k = 1:app.nStp-1
        
                for i = 1:length(x_grid)
                    for j = 1:length(y_grid)
                        x_t = [x_mesh(i,j);y_mesh(i,j);app.x_t_array(3,k+1)];
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                            p_x_t(i,j,k) = motion_model_velocity(app,x_t,u_t,app.x_t_array(:,k));
                        else
                            u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                            p_x_t(i,j,k) = motion_model_odometry(app,x_t,u_t,app.x_t_array(:,k));
                        end
                    end
                end
        
            end
        
            n_term = 1;
            cla(app.UIAxes)
            drawTrajectory(app)
            hold(app.UIAxes,'on')
            for k = 1:app.nStp-1
                surf(app.UIAxes,x_mesh,y_mesh,n_term*p_x_t(:,:,k),'FaceAlpha','interp','AlphaData',n_term*p_x_t(:,:,k));
                shading(app.UIAxes,'interp')
                if app.traj_type == 0
                    axis(app.UIAxes,[-2 2 -2 2])
                else
                    axis(app.UIAxes,[-1 9 -1 9])
                end
                zlim(app.UIAxes,'auto')
            end

            for k = 1:app.nStp-1
                figure
                plot(app.x_t_array(1,:),app.x_t_array(2,:),'.k')
                surf(x_mesh,y_mesh,p_x_t(:,:,k),'FaceAlpha','interp','AlphaData',p_x_t(:,:,k));
                xlabel('x')
                ylabel('y')
                shading('interp')
                if app.traj_type == 0
                    axis([-2 2 -2 2])
                else
                    axis([-1 9 -1 9])
                end
                zlim('auto')
            end
    
        end
        
        function runFunc(app)
            
            if app.comp_type == 1
                
                % direct evaluation (Do not auto execute because of the
                % computational time requirement)
            
            else
                if app.nSmp == 0
                    drawFunc(app)
                    return;
                end
                
                x_t = zeros(3,app.nSmp*app.nStp);
                for j = 1:app.nSmp
                    
                    k = 1;
                    x_t(:,1+(j-1)*app.nStp) = app.x_t_array(:,k);
    
                    for i = 1+(j-1)*app.nStp:j*(app.nStp)-1
                        
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                            x_t(:,i+1) = sample_motion_model_velocity(app,u_t,x_t(:,i));
                        else
                            u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                            x_t(:,i+1) = sample_motion_model_odometry(app,u_t,x_t(:,i));
                        end
    
                        k = k+1;
                    end
                end
    
                app.x_t_sample = x_t;
                
                drawFunc(app)
                
            end
            
        end
        
        function updateTrajectory(app)
            
            if app.traj_type == 0
                % circular trajectory
                
                app.nStp = 8;
                
                x = 1;
                y = 0;
                theta = -pi/2;
                
                v = pi/4*ones(1,app.nStp); % m/s
                omega = -pi/4*ones(1,app.nStp); % rad/s
                
                app.x_array     = x*ones(1,app.nStp);
                app.y_array     = y*ones(1,app.nStp);
                app.theta_array = theta*ones(1,app.nStp);
                
                for i = 1:app.nStp-1
                    app.x_array(1,i+1) = app.x_array(1,i) - (v(1,i)/omega(1,i))*(sin(app.theta_array(1,i)) - sin(app.theta_array(1,i)+omega(1,i)));
                    app.y_array(1,i+1) = app.y_array(1,i) + (v(1,i)/omega(1,i))*(cos(app.theta_array(1,i)) - cos(app.theta_array(1,i)+omega(1,i)));
                    app.theta_array(1,i+1) = app.theta_array(1,i) + omega(1,i);
                end
                
                ylim(app.UIAxes,[min(app.y_array)-0.25 max(app.y_array)+0.25])
            else
                % u-shape trajectory
                
                if app.prob_type == 0
                    app.nStp = 15;
                    
                    app.x_array = [0,2,4,6,8,8,8,8,8,8,8,6,4,2,0];
                    app.y_array = [0,0,0,0,0,0,2,4,6,8,8,8,8,8,8];
                    app.theta_array = [0,0,0,0,0,pi/2,pi/2,pi/2,pi/2,pi/2,pi,pi,pi,pi,pi];
                else
                    app.nStp = 13;
                    
                    app.x_array = [0,2,4,6,8,8,8,8,8,6,4,2,0];
                    app.y_array = [0,0,0,0,0,2,4,6,8,8,8,8,8];
                    app.theta_array = [0,0,0,0,0,pi/2,pi/2,pi/2,pi,pi,pi,pi,pi];
                end
                
                v = zeros(1,app.nStp); % m/s
                omega = zeros(1,app.nStp); % rad/s
                
                for i = 1:app.nStp-1
                    v(1,i) = sqrt((app.x_array(1,i+1)-app.x_array(1,i))^2+(app.y_array(1,i+1)-app.y_array(1,i))^2); % m/s
                    omega(1,i) = app.theta_array(1,i+1)-app.theta_array(1,i); % rad/s
                end
                
                ylim(app.UIAxes,[min(app.y_array)-1 max(app.y_array)+1])
            end
            
            app.x_t_array = [app.x_array;app.y_array;app.theta_array];
            app.v_t_array = [v;omega];
            
            drawTrajectory(app)
        end            
        
        function updateProbModel(app)
            
            if app.prob_type == 0
                app.Spinner_5.Visible = 'on';
                app.SpinnerLabel_5.Visible = 'on';
                app.e2Label_3.Visible = 'on';
                app.Spinner_6.Visible = 'on';
                app.SpinnerLabel_6.Visible = 'on';
                app.e2Label_6.Visible = 'on';
            else
                app.Spinner_5.Visible = 'off';
                app.SpinnerLabel_5.Visible = 'off';
                app.e2Label_3.Visible = 'off';
                app.Spinner_6.Visible = 'off';
                app.SpinnerLabel_6.Visible = 'off';
                app.e2Label_6.Visible = 'off';
            end
            
            updateTrajectory(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            updateTrajectory(app)
            axis(app.UIAxes,'equal')
            
        end

        % Size changed function: KOM613EHomeworkAppUIFigure
        function KOM613EHomeworkAppUIFigureSizeChanged(app, event)
%             position = app.KOM613EHomeworkAppUIFigure.Position;
%             
%             if position(3) < 700
%                 app.KOM613EHomeworkAppUIFigure.Position(3) = 700;
%             end
%             if position(4) < 450
%                 app.KOM613EHomeworkAppUIFigure.Position(4) = 450;
%             end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
            if app.prob_type == 0
                runFunc(app)
            else
                runDirectEval(app)
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
            close all
            cla(app.UIAxes)
            updateTrajectory(app)
            
%             set(app.UIAxes,'Position',get(app.UIAxes,'UserData'))
%             set(app.UIAxes, 'OuterPosition', [0 0 1 1])
            
        end

        % Value changing function: Spinner_1
        function Spinner_1_7ValueChanging(app, event)
            app.alpha1 = event.Value*0.01;

            runFunc(app)
        end

        % Value changing function: Spinner_2
        function Spinner_2_2ValueChanging(app, event)
            app.alpha2 = event.Value*0.01;
            
            runFunc(app)
        end

        % Value changing function: Spinner_3
        function Spinner_3ValueChanging(app, event)
            app.alpha3 = event.Value*0.01;

            runFunc(app)
        end

        % Value changing function: Spinner_4
        function Spinner_4_4ValueChanging(app, event)
            app.alpha4 = event.Value*0.01;

            runFunc(app)
        end

        % Value changing function: Spinner_5
        function Spinner_5_3ValueChanging(app, event)
            app.alpha5 = event.Value*0.01;

            runFunc(app)
        end

        % Value changing function: Spinner_6
        function Spinner_6_6ValueChanging(app, event)
            app.alpha6 = event.Value*0.01;

            runFunc(app)
        end

        % Selection changed function: NoisedistButtonGroup
        function NoisedistButtonGroupSelectionChanged(app, event)
            selectedButton = app.NoisedistButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Triangular')
                app.dist_type = 1;
            else
                app.dist_type = 0;
            end
            
            runFunc(app)
        end

        % Selection changed function: ProbabilisticmodelButtonGroup
        function ProbabilisticmodelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProbabilisticmodelButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Odometry')
                app.prob_type = 1;
            else
                app.prob_type = 0;
            end
            
            updateProbModel(app)            
            runFunc(app)
        end

        % Selection changed function: TrajectoryButtonGroup
        function TrajectoryButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrajectoryButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'U-Shape')
                app.traj_type = 1;
            else
                app.traj_type = 0;
            end
            
            updateTrajectory(app)
            runFunc(app)
        end

        % Selection changed function: ComputingalgoButtonGroup
        function ComputingalgoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ComputingalgoButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Sampling')
                app.SampleSpinner.Enable = 'on';
                app.comp_type = 0;
                app.RefreshButton.Text = 'Refresh';
                runFunc(app)
            else
                app.SampleSpinner.Enable = 'off';
                app.comp_type = 1;
                app.RefreshButton.Text = 'Execute';
                cla(app.UIAxes)
                drawTrajectory(app)
            end
            
        end

        % Value changing function: SampleSpinner
        function SampleSpinnerValueChanging(app, event)
            app.nSmp = event.Value;
            
            if app.nSmp > 0
                runFunc(app)
            else            
                cla(app.UIAxes)
                updateTrajectory(app)
            end
        end

        % Value changed function: SamplePositionsButton
        function SamplePositionsButtonValueChanged(app, event)
            app.toggle_points = app.SamplePositionsButton.Value;
            
            drawFunc(app)
        end

        % Value changed function: MonteCarloButton
        function MonteCarloButtonValueChanged(app, event)
            app.toggle_monte = app.MonteCarloButton.Value;
            
            drawFunc(app)
        end

        % Value changed function: EKFPredictionsButton
        function EKFPredictionsButtonValueChanged(app, event)
            app.toggle_ekfp = app.EKFPredictionsButton.Value;
            
            drawFunc(app)
        end

        % Value changed function: UKFPredictionsButton
        function UKFPredictionsButtonValueChanged(app, event)
            app.toggle_ukfp = app.UKFPredictionsButton.Value;
            
            drawFunc(app)
        end

        % Menu selected function: DrawsymbolicrobotMenu
        function DrawsymbolicrobotMenuSelected(app, event)
            if app.toggle_robot == 0
                app.toggle_robot = 1;
                app.DrawsymbolicrobotMenu.Checked = 'on';
            else
                app.toggle_robot = 0;
                app.DrawsymbolicrobotMenu.Checked = 'off';
            end
            
            drawFunc(app)
        end

        % Menu selected function: DrawtrajectoryMenu
        function DrawtrajectoryMenuSelected(app, event)
            if app.toggle_traj == 0
                app.toggle_traj = 1;
                app.DrawtrajectoryMenu.Checked = 'on';
            else
                app.toggle_traj = 0;
                app.DrawtrajectoryMenu.Checked = 'off';
            end
            
            drawFunc(app)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('KOM 613E-Homework #3a\nMertcan Kaya\n518192004');
            uialert(app.KOM613EHomeworkAppUIFigure,message,'About','Icon','info');
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KOM613EHomeworkAppUIFigure = uifigure('Visible', 'off');
            app.KOM613EHomeworkAppUIFigure.Name = 'KOM613E Homework App';
            app.KOM613EHomeworkAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @KOM613EHomeworkAppUIFigureSizeChanged, true);
            app.FileMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.ViewMenu.Text = 'View';
            app.DrawsymbolicrobotMenu = uimenu(app.ViewMenu);
            app.DrawsymbolicrobotMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawsymbolicrobotMenuSelected, true);
            app.DrawsymbolicrobotMenu.Text = 'Draw symbolic robot';
            app.DrawtrajectoryMenu = uimenu(app.ViewMenu);
            app.DrawtrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawtrajectoryMenuSelected, true);
            app.DrawtrajectoryMenu.Checked = 'on';
            app.DrawtrajectoryMenu.Text = 'Draw trajectory';
            app.HelpMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.KOM613EHomeworkAppUIFigure);
            app.MotionPanel = uipanel(app.GridLayout);
            app.MotionPanel.Title = 'Motion';
            app.TrajectoryButtonGroup = uibuttongroup(app.MotionPanel);
            app.TrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrajectoryButtonGroupSelectionChanged, true);
            app.TrajectoryButtonGroup.Title = 'Trajectory';
            app.CircularButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.UShapeButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.UShapeButton.Text = 'U-Shape';
            app.ProbabilisticmodelButtonGroup = uibuttongroup(app.MotionPanel);
            app.ProbabilisticmodelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProbabilisticmodelButtonGroupSelectionChanged, true);
            app.ProbabilisticmodelButtonGroup.Title = 'Probabilistic model';
            app.DeadReckoningButton = uiradiobutton(app.ProbabilisticmodelButtonGroup);
            app.DeadReckoningButton.Text = 'Dead Reckoning';
            app.DeadReckoningButton.Value = true;
            app.OdometryButton = uiradiobutton(app.ProbabilisticmodelButtonGroup);
            app.OdometryButton.Text = 'Odometry';
            app.ComputingalgoButtonGroup = uibuttongroup(app.MotionPanel);
            app.ComputingalgoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComputingalgoButtonGroupSelectionChanged, true);
            app.ComputingalgoButtonGroup.Title = 'Computing algo';
            app.SamplingButton = uiradiobutton(app.ComputingalgoButtonGroup);
            app.SamplingButton.Text = 'Sampling';
            app.SamplingButton.Value = true;
            app.DirectEvaluationButton = uiradiobutton(app.ComputingalgoButtonGroup);
            app.DirectEvaluationButton.Text = 'Direct Evaluation';
            app.SampleSpinnerLabel = uilabel(app.ComputingalgoButtonGroup);
            app.SampleSpinnerLabel.Text = 'Sample:';
            app.SampleSpinner = uispinner(app.ComputingalgoButtonGroup);
            app.SampleSpinner.ValueChangingFcn = createCallbackFcn(app, @SampleSpinnerValueChanging, true);
            app.SampleSpinner.Limits = [0 9999];
            app.NoisedistButtonGroup = uibuttongroup(app.MotionPanel);
            app.NoisedistButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoisedistButtonGroupSelectionChanged, true);
            app.NormalButton = uiradiobutton(app.NoisedistButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.TriangularButton = uiradiobutton(app.NoisedistButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.NoisedistLabel = uilabel(app.NoisedistButtonGroup);
            app.NoisedistLabel.Text = 'Noise dist:';
            app.ErrorparametersPanel = uipanel(app.MotionPanel);
            app.ErrorparametersPanel.Title = 'Error parameters';
            app.e2Label_1 = uilabel(app.ErrorparametersPanel);
            app.e2Label_1.Text = 'e-2';
            app.e2Label_2 = uilabel(app.ErrorparametersPanel);
            app.e2Label_2.Text = 'e-2';
            app.e2Label_3 = uilabel(app.ErrorparametersPanel);
            app.e2Label_3.Text = 'e-2';
            app.e2Label_4 = uilabel(app.ErrorparametersPanel);
            app.e2Label_4.Text = 'e-2';
            app.e2Label_5 = uilabel(app.ErrorparametersPanel);
            app.e2Label_5.Text = 'e-2';
            app.e2Label_6 = uilabel(app.ErrorparametersPanel);
            app.e2Label_6.Text = 'e-2';
            app.SpinnerLabel_3 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_3.Text = 'α 3';
            app.Spinner_3 = uispinner(app.ErrorparametersPanel);
            app.Spinner_3.Step = 0.001;
            app.Spinner_3.ValueChangingFcn = createCallbackFcn(app, @Spinner_3ValueChanging, true);
            app.Spinner_3.Limits = [0 10];
            app.SpinnerLabel_2 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_2.Text = 'α 2';
            app.Spinner_2 = uispinner(app.ErrorparametersPanel);
            app.Spinner_2.Step = 0.001;
            app.Spinner_2.ValueChangingFcn = createCallbackFcn(app, @Spinner_2_2ValueChanging, true);
            app.Spinner_2.Limits = [0 10];
            app.SpinnerLabel_5 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_5.Text = 'α 5';
            app.Spinner_5 = uispinner(app.ErrorparametersPanel);
            app.Spinner_5.Step = 0.001;
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_5_3ValueChanging, true);
            app.Spinner_5.Limits = [0 10];
            app.SpinnerLabel_4 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_4.Text = 'α 4';
            app.Spinner_4 = uispinner(app.ErrorparametersPanel);
            app.Spinner_4.Step = 0.001;
            app.Spinner_4.ValueChangingFcn = createCallbackFcn(app, @Spinner_4_4ValueChanging, true);
            app.Spinner_4.Limits = [0 10];
            app.Label = uilabel(app.ErrorparametersPanel);
            app.Label.Text = 'α 1';
            app.Spinner_1 = uispinner(app.ErrorparametersPanel);
            app.Spinner_1.Step = 0.001;
            app.Spinner_1.ValueChangingFcn = createCallbackFcn(app, @Spinner_1_7ValueChanging, true);
            app.Spinner_1.Limits = [0 10];
            app.SpinnerLabel_6 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_6.Text = 'α 6';
            app.Spinner_6 = uispinner(app.ErrorparametersPanel);
            app.Spinner_6.Step = 0.001;
            app.Spinner_6.ValueChangingFcn = createCallbackFcn(app, @Spinner_6_6ValueChanging, true);
            app.Spinner_6.Limits = [0 10];
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanel.Title = 'Main';
            app.ClearButton = uibutton(app.MainPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RefreshButton = uibutton(app.MainPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SamplePositionsButton = uibutton(app.MainPanel, 'state');
            app.SamplePositionsButton.ValueChangedFcn = createCallbackFcn(app, @SamplePositionsButtonValueChanged, true);
            app.SamplePositionsButton.Text = 'Sample Positions';
            app.SamplePositionsButton.Value = true;
            app.MonteCarloButton = uibutton(app.MainPanel, 'state');
            app.MonteCarloButton.ValueChangedFcn = createCallbackFcn(app, @MonteCarloButtonValueChanged, true);
            app.MonteCarloButton.Text = 'Monte-Carlo';
            app.EKFPredictionsButton = uibutton(app.MainPanel, 'state');
            app.EKFPredictionsButton.ValueChangedFcn = createCallbackFcn(app, @EKFPredictionsButtonValueChanged, true);
            app.EKFPredictionsButton.Text = 'EKF Predictions';
            app.UKFPredictionsButton = uibutton(app.MainPanel, 'state');
            app.UKFPredictionsButton.ValueChangedFcn = createCallbackFcn(app, @UKFPredictionsButtonValueChanged, true);
            app.UKFPredictionsButton.Text = 'UKF Predictions';
            app.NavigationPanel = uipanel(app.GridLayout);
            app.NavigationPanel.Title = 'Navigation';
            app.SensorPanel = uipanel(app.GridLayout);
            app.SensorPanel.Title = 'Sensor';
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.KOM613EHomeworkAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KOM613EHomeworkAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KOM613EHomeworkAppUIFigure)
        end
    end
end