classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KOM613EHomeworkAppUIFigure     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        DrawsymbolicrobotMenu          matlab.ui.container.Menu
        DrawtrajectoryMenu             matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        MotionPanel                    matlab.ui.container.Panel
        TrajectoryButtonGroup          matlab.ui.container.ButtonGroup
        CircularButton                 matlab.ui.control.RadioButton
        UShapeButton                   matlab.ui.control.RadioButton
        ProbabilisticmodelButtonGroup  matlab.ui.container.ButtonGroup
        DeadReckoningButton            matlab.ui.control.RadioButton
        OdometryButton                 matlab.ui.control.RadioButton
        ComputingalgoButtonGroup       matlab.ui.container.ButtonGroup
        SamplingButton                 matlab.ui.control.RadioButton
        DirectEvaluationButton         matlab.ui.control.RadioButton
        SampleSpinnerLabel             matlab.ui.control.Label
        SampleSpinner                  matlab.ui.control.Spinner
        NoisedistButtonGroup           matlab.ui.container.ButtonGroup
        NormalButton                   matlab.ui.control.RadioButton
        TriangularButton               matlab.ui.control.RadioButton
        NoisedistLabel                 matlab.ui.control.Label
        ErrorparametersPanel           matlab.ui.container.Panel
        e2Label_1                      matlab.ui.control.Label
        e2Label_2                      matlab.ui.control.Label
        e2Label_3                      matlab.ui.control.Label
        e2Label_4                      matlab.ui.control.Label
        e2Label_5                      matlab.ui.control.Label
        e2Label_6                      matlab.ui.control.Label
        SpinnerLabel_3                 matlab.ui.control.Label
        Spinner_3                      matlab.ui.control.Spinner
        SpinnerLabel_2                 matlab.ui.control.Label
        Spinner_2                      matlab.ui.control.Spinner
        SpinnerLabel_5                 matlab.ui.control.Label
        Spinner_5                      matlab.ui.control.Spinner
        SpinnerLabel_4                 matlab.ui.control.Label
        Spinner_4                      matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        Spinner_1                      matlab.ui.control.Spinner
        SpinnerLabel_6                 matlab.ui.control.Label
        Spinner_6                      matlab.ui.control.Spinner
        MainPanel                      matlab.ui.container.Panel
        ClearButton                    matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        SwitchLabel                    matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        SPButton                       matlab.ui.control.StateButton
        MCButton                       matlab.ui.control.StateButton
        EKFButton                      matlab.ui.control.StateButton
        UKFButton                      matlab.ui.control.StateButton
        NextButton                     matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        NavigationPanel                matlab.ui.container.Panel
        meterLabel_2                   matlab.ui.control.Label
        meterLabel_3                   matlab.ui.control.Label
        degreeLabel_4                  matlab.ui.control.Label
        XSpinnerLabel                  matlab.ui.control.Label
        XSpinner                       matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        YSpinner                       matlab.ui.control.Spinner
        HeadingAngSpinnerLabel         matlab.ui.control.Label
        HeadingAngSpinner              matlab.ui.control.Spinner
        RobotPoseLabel                 matlab.ui.control.Label
        EnablemanualrobotposeCheckBox  matlab.ui.control.CheckBox
        SensorPanel                    matlab.ui.container.Panel
        RangeSpinnerLabel              matlab.ui.control.Label
        RangeSpinner                   matlab.ui.control.Spinner
        BearingSpinnerLabel            matlab.ui.control.Label
        BearingSpinner                 matlab.ui.control.Spinner
        ResSpinnerLabel                matlab.ui.control.Label
        ResSpinner                     matlab.ui.control.Spinner
        Marker1CheckBox                matlab.ui.control.CheckBox
        Marker2CheckBox                matlab.ui.control.CheckBox
        Marker3CheckBox                matlab.ui.control.CheckBox
        mLabel                         matlab.ui.control.Label
        degLabel                       matlab.ui.control.Label
        degLabel_2                     matlab.ui.control.Label
        MxSpinnerLabel                 matlab.ui.control.Label
        MxSpinner                      matlab.ui.control.Spinner
        MxSpinner_2Label               matlab.ui.control.Label
        MxSpinner_2                    matlab.ui.control.Spinner
        MxSpinner_3Label               matlab.ui.control.Label
        MxSpinner_3                    matlab.ui.control.Spinner
        MySpinnerLabel                 matlab.ui.control.Label
        MySpinner                      matlab.ui.control.Spinner
        MySpinner_2Label               matlab.ui.control.Label
        MySpinner_2                    matlab.ui.control.Spinner
        MySpinner_3Label               matlab.ui.control.Label
        MySpinner_3                    matlab.ui.control.Spinner
        SgnDropDownLabel               matlab.ui.control.Label
        SgnDropDown                    matlab.ui.control.DropDown
        SgnDropDown_2Label             matlab.ui.control.Label
        SgnDropDown_2                  matlab.ui.control.DropDown
        SgnDropDown_3Label             matlab.ui.control.Label
        SgnDropDown_3                  matlab.ui.control.DropDown
        sigma_dSpinnerLabel            matlab.ui.control.Label
        sigma_dSpinner                 matlab.ui.control.Spinner
        sigma_phiSpinnerLabel          matlab.ui.control.Label
        sigma_phiSpinner               matlab.ui.control.Spinner
        sigma_sSpinnerLabel            matlab.ui.control.Label
        sigma_sSpinner                 matlab.ui.control.Spinner
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        hw_switch = 1;
        manual_on = 1;
        
        x_0 = 0.9;
        y_0 = 0.1;
        theta_0 = pi/3.025;

        d = 0.5;
        tS = pi/4;
        dtS = pi/20;
            
        nF = 2;
        
        marker = [1,1,0];

        p1x = 1.2;
        p1y = 0.3;
        p1s = 'o';
        
        p2x = 0.9;
        p2y = 0.5;
        p2s = '^';
            
        p3x = 1.15;
        p3y = 0.35;
        p3s = 'p';
            
        nStp = 0;
        nSmp = 0;
        
        prob_type = 1;
        traj_type = 1;
        comp_type = 0;
        
        toggle_traj = 1;
        toggle_robot = 0;
        toggle_points = 1;
        toggle_monte = 0;
        toggle_ekfp = 0;
        toggle_ukfp = 0;
        
        x_array;
        y_array;
        theta_array;
        
        i_r = 0;
        
        x_r;
        y_r;
        theta_r;
        
        dt_s = 1;
        
        x_s;
        y_s;
        theta_s;
        
        x_t_array;
        v_t_array;
        
        x_t_sample;
        
        sample_fixed;
    end
    
    properties (Access = public)
        
        dt = 1;
        dist_type = 0;
        
        alpha1 = 0;
        alpha2 = 0;
        alpha3 = 0;
        alpha4 = 0;
        alpha5 = 0;
        alpha6 = 0;
        
        sigma_d = 0.025;
        sigma_phi = deg2rad(2.5);
        sigma_s = 0.4;
    end
    
    methods (Access = private)
        
        function x_t = g_t(app,u_t,x_tprev)
        
            x       = x_tprev(1);
            y       = x_tprev(2);
            theta   = x_tprev(3);
            
            if app.prob_type == 0
                v       = u_t(1);
                omega   = u_t(2);
            
                if abs(omega) > 1e-5
                    x_t = [ x - (v/omega)*(sin(theta) - sin(theta+omega*app.dt))
                            y + (v/omega)*(cos(theta) - cos(theta+omega*app.dt))
                            theta + omega*app.dt ];
                else
                    x_t = [ x + v*app.dt*cos(theta + omega*app.dt)
                            y + v*app.dt*sin(theta + omega*app.dt)
                            theta + omega*app.dt ];
                end
            else
                x_bar       = u_t(1);
                y_bar       = u_t(2);
                theta_bar	= u_t(3);
        
                x_bar_prm       = u_t(4);
                y_bar_prm       = u_t(5);
                theta_bar_prm   = u_t(6);
            
                x_t = [	x + sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*cos(theta + atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar)
                        y + sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*sin(theta + atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar)
                        theta + theta_bar_prm - theta_bar ];
            end
        
        end
        
        function g_t_prm = G_t(app,u_t,x_tprev)
        
            theta   = x_tprev(3);
            
            if app.prob_type == 0
                v       = u_t(1);
                omega   = u_t(2);
            
                if abs(omega) > 1e-5
                    g_t_prm = [	1,0,(v/omega)*(cos(omega*app.dt + theta) - cos(theta))
                                0,1,(v/omega)*(sin(omega*app.dt + theta) - sin(theta))
                                0,0,1 ];
                else
                    g_t_prm = [	1,0,-v*app.dt*sin(omega*app.dt + theta)
                                0,1,v*app.dt*cos(omega*app.dt + theta)
                                0,0,1 ];
                end
            else
                x_bar       = u_t(1);
                y_bar       = u_t(2);
                theta_bar	= u_t(3);
        
                x_bar_prm 	= u_t(4);
                y_bar_prm 	= u_t(5);
            
                g_t_prm = [	1, 0, -sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*sin(atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar + theta)
                          	0, 1,  sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*cos(atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar + theta)
                           	0, 0,  1 ];
            end
        
        end

        function drawFunc(app)
            
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            
            drawTrajectory(app)
            
            if app.nSmp > 0
                cmap = jet(app.nStp);
                
                x_steps = zeros(3,app.nSmp,app.nStp);
                for i = 1:app.nStp
                    x_steps(:,:,i) = app.x_t_sample(:,i:app.nStp:app.nSmp*app.nStp);
                end
    
                if app.toggle_points == 1
                    h = zeros(app.nStp,1);
                    for i = 1:app.nStp
                        h(i) = plot(app.UIAxes,x_steps(1,:,i),x_steps(2,:,i),'.','Color', cmap(i, :));
                    end
                    set(h(1),'Marker','x')
                end
                
                mu = zeros(3,app.nStp);
                Sigma = zeros(3,3,app.nStp);
                for k = 1:app.nStp
                    mu(:,k) = mean(x_steps(:,:,k),2);
                    if app.nSmp > 1
                        S12 = ((x_steps(1,:,k)-mu(1,k))*(x_steps(2,:,k)-mu(2,k))')/(app.nSmp-1);
                        S13 = ((x_steps(1,:,k)-mu(1,k))*(x_steps(3,:,k)-mu(3,k))')/(app.nSmp-1);
                        S23 = ((x_steps(2,:,k)-mu(2,k))*(x_steps(3,:,k)-mu(3,k))')/(app.nSmp-1);
                    else
                        S12 = 0;
                        S13 = 0;
                        S23 = 0;
                    end
                    Sigma(:,:,k) = [var(x_steps(1,:,k)) S12 S13;S12 var(x_steps(2,:,k)) S23;S13 S23 var(x_steps(3,:,k))];
                end
                
                Sigma = real(Sigma);
                
                R = Sigma(:,:,2);
    
                if app.toggle_monte == 1
                    for k = 1:app.nStp
                        drawEllipse(app,mu(:,k),Sigma(:,:,k),cmap(k, :))
                    end
                end
                
                if app.toggle_ekfp == 1
                    
                    mu_bar = zeros(3,app.nStp-1);
                    Sigma_bar = zeros(3,3,app.nStp-1);
                    
                    mu_bar(:,1)         = app.x_t_array(:,1);
                    Sigma_bar(:,:,1)	= Sigma(:,:,1);
                    
                    drawEllipse(app,mu_bar(:,1),Sigma_bar(:,:,1),'#00FFFF')
                    
                    for k = 1:app.nStp-1
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                        else
                          	u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                        end
                        g_tk = g_t(app,u_t,mu_bar(:,k));
                        G_tk = G_t(app,u_t,mu_bar(:,k));
                    
                        % prediction
                        mu_bar(:,k+1)       = g_tk;
                        Sigma_bar(:,:,k+1)	= G_tk*Sigma_bar(:,:,k)*G_tk' + R;
                    
                        drawEllipse(app,mu_bar(:,k+1),Sigma_bar(:,:,k+1),'#00FFFF')
                    end
                    plot(app.UIAxes,app.x_t_array(1,:),app.x_t_array(2,:),'.c')
    
                end
            
                if app.toggle_ukfp == 1
                    
                    n = 3;
                    chiNb = 2*n + 1;
                    
                    kappa = 10;      % kappa >= 0
                    alpha = 0.5;    % alpha € (0,1]
                    lambda = alpha^2*(n+kappa)-n;
                    beta = 2;
                    
                    mu_bar_u = zeros(3,1,app.nStp);
                    Sigma_bar_u = zeros(3,3,app.nStp);
                    
                    mu_bar_u(:,:,1) = app.x_t_array(:,1);
                    Sigma_bar_u(:,:,1) = Sigma(:,:,1);

                    drawEllipse(app,mu_bar_u(:,:,1),Sigma_bar_u(:,:,1),'#FF00FF')
                    
                    chi_bar = zeros(3,chiNb,app.nStp-1);
                    for k = 1:app.nStp-1
                        chi = zeros(3,chiNb);
                        if sum(Sigma_bar_u(:,:,k),'all') > 1e-7
                            mu_offset = real(sqrtm((n+lambda)*Sigma_bar_u(:,:,k)));
                        else
                            mu_offset = zeros(size(Sigma_bar_u(:,:,k)));
                        end
                        for j = 1:chiNb
                            if j == 1
                                chi(:,j) = mu_bar_u(:,:,k);
                            elseif j <= n+1
                                chi(:,j) = mu_bar_u(:,:,k) + mu_offset(:,j-1);
                            else
                                chi(:,j) = mu_bar_u(:,:,k) - mu_offset(:,j-n-1);
                            end
                        end

                        omega_m = zeros(1,chiNb);
                        omega_c = zeros(1,chiNb);
                        for j = 1:chiNb
                            if j == 1
                                omega_m(j) = lambda/(n+lambda);
                                omega_c(j) = lambda/(n+lambda)+1-alpha^2+beta;
                            else
                                omega_m(j) = 1/(2*(n+lambda));
                                omega_c(j) = 1/(2*(n+lambda));
                            end
                        end
                    
                        chi_tk_bar_s = zeros(3,chiNb);
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                        else
                          	u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                        end
                        for j = 1:chiNb
                            chi_tk_bar_s(:,j) = g_t(app,u_t,chi(:,j));
                        end

                        for j = 1:chiNb
                            mu_bar_u(:,:,k+1) = mu_bar_u(:,:,k+1) + omega_m(j)*chi_tk_bar_s(:,j);
                        end

                        for j = 1:chiNb
                            Sigma_bar_u(:,:,k+1) = Sigma_bar_u(:,:,k+1) + omega_c(j)*(chi_tk_bar_s(:,j)-mu_bar_u(:,:,k+1))*(chi_tk_bar_s(:,j)-mu_bar_u(:,:,k+1))';
                        end
                        Sigma_bar_u(:,:,k+1) = Sigma_bar_u(:,:,k+1) + R;

                        if sum(Sigma_bar_u(:,:,k+1),'all') > 1e-7
                            mu_tk_offset_bar = real(sqrtm((n+lambda)*Sigma_bar_u(:,:,k+1)));
                        else
                            mu_tk_offset_bar = zeros(size(Sigma_bar_u(:,:,k+1)));
                        end
                        for j = 1:chiNb
                            if j == 1
                                chi_bar(:,j,k+1) = mu_bar_u(:,:,k+1);
                            elseif j <= n+1
                                chi_bar(:,j,k+1) = mu_bar_u(:,:,k+1) + mu_tk_offset_bar(:,j-1);
                            else
                                chi_bar(:,j,k+1) = mu_bar_u(:,:,k+1) - mu_tk_offset_bar(:,j-n-1);
                            end
                        end

                        drawEllipse(app,mu_bar_u(:,:,k+1),Sigma_bar_u(:,:,k+1),'#FF00FF')
                        plot(app.UIAxes,chi_bar(1,[1:3 5:6],k+1),chi_bar(2,[1:3 5:6],k+1),'Marker','*','LineStyle','none','Color','#FF00FF','LineWidth',1);

                    end
    
                end
                
%                 ylim(app.UIAxes,'auto')
            end
        end
        
        function drawTrajectory(app)
            
            hold(app.UIAxes,'on')
            if app.toggle_robot == 1                
                for k = 1:app.nStp
                    drawRobot(app,app.x_t_array(:,k))
                end
            else
                plot(app.UIAxes,app.x_array,app.y_array,'.k','Marker','*','MarkerSize',5,'LineWidth',2)
            end
            
            if app.toggle_traj == 1
                if app.traj_type == 0
                    plot(app.UIAxes,cos(linspace(0,-7*pi/4)),sin(linspace(0,-7*pi/4)),':k')
                else
                    plot(app.UIAxes,app.x_array,app.y_array,':k')
                end
            end
            
        end
        
        function drawEllipse(app,mu,Sigma,color)
        
            if sum(isnan(Sigma),'all') || sum(~isreal(Sigma),'all')
                return
            else
        %         the 95% confidence interval error ellipse
                chisquare_val = sqrt(5.991);
                
        %         % the 99% confidence interval error ellipse
        %         chisquare_val = sqrt(9.210);
                
                theta_grid = linspace(0,2*pi);
        
                [eigenvec, eigenval] = eig(Sigma(1:2,1:2));
        
                % Get the index of the largest eigenvector
                [largest_eigenvec_ind_c, ~] = find(eigenval == max(max(eigenval)));
                largest_eigenvec = eigenvec(:, largest_eigenvec_ind_c);
        
                % Get the largest eigenvalue
                largest_eigenval = max(max(eigenval));
        
                % Get the smallest eigenvector and eigenvalue
                if(largest_eigenvec_ind_c == 1)
                    smallest_eigenval = max(eigenval(:,2));
        %             smallest_eigenvec = eigenvec(:,2);
                else
                    smallest_eigenval = max(eigenval(:,1));
        %             smallest_eigenvec = eigenvec(1,:);
                end
        
                % Calculate the angle between the x-axis and the largest eigenvector
                angle = atan2(largest_eigenvec(2), largest_eigenvec(1));
        
                % This angle is between -pi and pi.
                % Let's shift it such that the angle is between 0 and 2pi
                if(angle < 0)
                    angle = angle + 2*pi;
                end
        
                phi = angle;
                a = chisquare_val * sqrt(largest_eigenval);
                b = chisquare_val * sqrt(smallest_eigenval);
        
                % the ellipse in x and y coordinates 
                ellipse_x_r  = a*cos(theta_grid);
                ellipse_y_r  = b*sin(theta_grid);
        
                % rotation matrix of angle phi
                rot = [cos(phi) sin(phi); -sin(phi) cos(phi)];
        
                % rotation of the ellipse to angle phi
                r_ellipse = [ellipse_x_r;ellipse_y_r]' * rot;
        
                plot(app.UIAxes,mu(1),mu(2),'Marker','*','Color',color);
                plot(app.UIAxes,r_ellipse(:,1) + mu(1),r_ellipse(:,2) + mu(2),'Color',color);
            end
            
        end

        function drawRobot(app,x_vec)
                        
            xR = x_vec(1);
            yR = x_vec(2);
            tR = x_vec(3);
            
            if app.traj_type == 0
                r = 0.1;
            else
                r = 0.35;
            end
            
            hold(app.UIAxes,"on")
            plot(app.UIAxes,xR + r*cos(linspace(0,2*pi)),yR + r*sin(linspace(0,2*pi)),'-k',[xR,xR + r*cos(tR)],[yR,yR + r*sin(tR)],'-k',xR,yR,'.k')
            
        end
        
        function drawRobotSensor(app,x_vec,s_vec,f_vec)
        
            xR = x_vec(1);
            yR = x_vec(2);
            tR = x_vec(3);
        
            r = 0.1;
            ds = 0.05;
            rs = 0.025;
            
            xS = xR + ds*cos(tR);
            yS = yR + ds*sin(tR);
            
            sd = s_vec(1);
            stSR = s_vec(2);
            sdtS = s_vec(3);
            
            stS = sdtS*floor(stSR/sdtS);
            d_i = sd*ones(1,2*floor(stSR/sdtS));
            mrk = zeros(1,2*floor(stSR/sdtS));
            
            for j = 1:size(f_vec,2)
                if f_vec(2,j) > 0
                    d_i(f_vec(2,j)) = f_vec(1,j);
                    mrk(f_vec(2,j)) = f_vec(3,j);
                end
            end
            
            if app.nF > 2
                if f_vec(2,1) == f_vec(2,2) && f_vec(2,1) ~= 0
                    d_i(f_vec(2,1)) = min(f_vec(1,1),f_vec(1,2));
                    if f_vec(1,1) < f_vec(1,2)
                        mrk(f_vec(2,1)) = f_vec(3,1);
                    else
                        mrk(f_vec(2,1)) = f_vec(3,2);
                    end
                end
            end
            
            if app.nF > 3
                if f_vec(2,1) == f_vec(2,3) && f_vec(2,1) ~= 0
                    d_i(f_vec(2,1)) = min(f_vec(1,1),f_vec(1,3));
                    if f_vec(1,1) < f_vec(1,3)
                        mrk(f_vec(2,1)) = f_vec(3,1);
                    else
                        mrk(f_vec(2,1)) = f_vec(3,3);
                    end
                end
            
                if f_vec(2,2) == f_vec(2,3) && f_vec(2,2) ~= 0
                    d_i(f_vec(2,2)) = min(f_vec(1,2),f_vec(1,3));
                    if f_vec(1,2) < f_vec(1,3)
                        mrk(f_vec(2,2)) = f_vec(3,2);
                    else
                        mrk(f_vec(2,2)) = f_vec(3,3);
                    end
                end
            end

            hold(app.UIAxes,"on")
            plot(app.UIAxes,xR + r*cos(linspace(0,2*pi)),yR + r*sin(linspace(0,2*pi)),'-k',xR,yR,'.k')
            plot(app.UIAxes,[xR,xR + (ds-rs)*cos(tR)],[yR,yR + (ds-rs)*sin(tR)],'-k')
            plot(app.UIAxes,xS + rs*cos(linspace(0,2*pi)),yS + rs*sin(linspace(0,2*pi)),'-b',xS,yS,'.r')
            plot(app.UIAxes,xS + sd*cos(linspace(tR-stSR,tR+stSR)),yS + sd*sin(linspace(tR-stSR,tR+stSR)),':r')
            plot(app.UIAxes,[xS xS + sd*cos(tR-stSR)],[yS yS + sd*sin(tR-stSR)],':r')
            plot(app.UIAxes,[xS xS + sd*cos(tR+stSR)],[yS yS + sd*sin(tR+stSR)],':r')
            for i = 1:2*stS/sdtS+1
                plot(app.UIAxes,[xS xS + sd*cos(tR-stS+((i-1)*sdtS))],[yS yS + sd*sin(tR-stS+((i-1)*sdtS))],':r')
            end
            for i = 1:2*stS/sdtS
                if d_i(i) < sd
                    plot(app.UIAxes,xS + d_i(i)*cos(linspace(tR-stS+((i-1)*sdtS),tR-stS+(i*sdtS))),yS + d_i(i)*sin(linspace(tR-stS+((i-1)*sdtS),tR-stS+(i*sdtS))),'-r')
                    plot(app.UIAxes,[xS xS + d_i(i)*cos(tR-stS+(i*sdtS)-sdtS/2)],[yS yS + d_i(i)*sin(tR-stS+(i*sdtS)-sdtS/2)],'--r')
                    if mrk(i) == 1
                        mm = 's';
                    elseif mrk(i) == 2
                        mm = '^';
                    elseif mrk(i) >= 3
                        mm = 'p';
                    else
                        mm = 'o';
                    end
                    plot(app.UIAxes,xS + d_i(i)*cos(tR-stS+(i*sdtS)-sdtS/2),yS + d_i(i)*sin(tR-stS+(i*sdtS)-sdtS/2),'r','Marker',mm)
                end
                if d_i(i) < sd
                    plot(app.UIAxes,[xS xS + d_i(i)*cos(tR-stS+((i-1)*sdtS))],[yS yS + d_i(i)*sin(tR-stS+((i-1)*sdtS))],'-r')
                    plot(app.UIAxes,[xS xS + d_i(i)*cos(tR-stS+(i*sdtS))],[yS yS + d_i(i)*sin(tR-stS+(i*sdtS))],'-r')
                else
                    plot(app.UIAxes,[xS xS + sd*cos(tR-stS+((i-1)*sdtS))],[yS yS + sd*sin(tR-stS+((i-1)*sdtS))],'-r')
                    plot(app.UIAxes,[xS xS + sd*cos(tR-stS+(i*sdtS))],[yS yS + sd*sin(tR-stS+(i*sdtS))],'-r')
                end
            end
        end

        function runDirectEval(app)
            
            if app.traj_type == 0
                x_grid = -2:0.01:2;
                y_grid = -2:0.01:2;
            else
                x_grid = -1:0.05:10;
                y_grid = -1:0.05:10;
            end
            [x_mesh,y_mesh] = meshgrid(x_grid,y_grid);
        
            p_x_t = zeros(length(x_grid),length(y_grid),app.nStp-1);
            for k = 1:app.nStp-1
        
                for i = 1:length(x_grid)
                    for j = 1:length(y_grid)
                        x_t = [x_mesh(i,j);y_mesh(i,j);app.x_t_array(3,k+1)];
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                            p_x_t(i,j,k) = motion_model_velocity(app,x_t,u_t,app.x_t_array(:,k));
                        else
                            u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                            p_x_t(i,j,k) = motion_model_odometry(app,x_t,u_t,app.x_t_array(:,k));
                        end
                    end
                end
        
            end
        
            n_term = 1;
            cla(app.UIAxes)
            drawTrajectory(app)
            hold(app.UIAxes,'on')
            for k = 1:app.nStp-1
                surf(app.UIAxes,x_mesh,y_mesh,n_term*p_x_t(:,:,k),'FaceAlpha','interp','AlphaData',n_term*p_x_t(:,:,k));
                shading(app.UIAxes,'interp')
                if app.traj_type == 0
                    axis(app.UIAxes,[-2 2 -2 2])
                else
                    axis(app.UIAxes,[-1 9 -1 9])
                end
                zlim(app.UIAxes,'auto')
            end

            for k = 1:app.nStp-1
                figure
                plot(app.x_t_array(1,:),app.x_t_array(2,:),'.k')
                surf(x_mesh,y_mesh,p_x_t(:,:,k),'FaceAlpha','interp','AlphaData',p_x_t(:,:,k));
                xlabel('x')
                ylabel('y')
                shading('interp')
                if app.traj_type == 0
                    axis([-2 2 -2 2])
                else
                    axis([-1 9 -1 9])
                end
                zlim('auto')
            end
    
        end
        
        function updatePlot(app)
            
            cla(app.UIAxes)
            
            if app.manual_on == 1
                x_0_vec = [app.x_0;app.y_0;app.theta_0];
    
                ds = 0.05; % sensor distance
                
                xS = app.x_0 + ds*cos(app.theta_0);
                yS = app.y_0 + ds*sin(app.theta_0);
                
                point(1).x = app.p1x;
                point(1).y = app.p1y;
                point(1).s = app.p1s;
                
                point(2).x = app.p2x;
                point(2).y = app.p2y;
                point(2).s = app.p2s;
                    
                point(3).x = app.p3x;
                point(3).y = app.p3y;
                point(3).s = app.p3s;
                app.theta_0
                rti = zeros(1,3);
                phi = zeros(1,3);
                sti = zeros(1,3);
                for j = 1:3
                    rti(j) = sqrt((point(j).x-xS)^2 + (point(j).y-yS)^2) + sample(app,app.sigma_d^2);
                    phi(j) = atan2(point(j).y-yS, point(j).x-xS) - app.theta_0 + sample(app,app.sigma_phi^2);
    %                 if phi(j) < -pi
    %                     phi(j) = phi(j) + pi;
    %                 end
    %                 if phi(j) > pi
    %                     phi(j) = phi(j) - pi;
    %                 end
    %                 if phi(j) < -pi
    %                     phi(j) = phi(j) + pi;
    %                 end
    %                 if phi(j) > pi
    %                     phi(j) = phi(j) - pi;
    %                 end
                    if point(j).s(1) == 's'
                        sti(j) = 1;
                    elseif point(j).s(1) == '^'
                        sti(j) = 2;
                    elseif point(j).s(1) == 'p'
                        sti(j) = 3;
                    else
                        sti(j) = 0;
                    end
                    sti(j) = round(sti(j) + sample(app,app.sigma_s^2));
                end
                rad2deg(phi)
                rad2deg(floor(app.tS/app.dtS)*app.dtS)
                ind = zeros(1,3);
                for i = 1:1:2*floor(app.tS/app.dtS)+1
                    for j = 1:3
                        if app.marker(j) == 1
                            if phi(j) > -floor(app.tS/app.dtS)*app.dtS+((i-1)*app.dtS) && phi(j) < floor(app.tS/app.dtS)*app.dtS
                                ind(j) = i;
                            end
                        else
%                             ind(j) = 0;
                        end
                    end
                end
                
                s_vec = [app.d;app.tS;app.dtS];
                f_vec = [rti;ind;sti];
    
                drawRobotSensor(app,x_0_vec,s_vec,f_vec)
                
                if app.marker(1) == 1
                    plot(app.UIAxes,point(1).x,point(1).y,point(1).s,'MarkerEdgeColor','k')
                end
                if app.marker(2) == 1
                    plot(app.UIAxes,point(2).x,point(2).y,point(2).s,'MarkerEdgeColor','k')
                end
                if app.marker(3) == 1
                    plot(app.UIAxes,point(3).x,point(3).y,point(3).s,'MarkerEdgeColor','k')
                end
            else
                
                app.nF = 3;
                
                nL = 2*app.nStp;
                
                ds = 0.05; % sensor distance
                
                xS = app.x_r + ds*cos(app.theta_r);
                yS = app.y_r + ds*sin(app.theta_r);
                
                point(1).x = app.x_array(1,1);
                point(1).y = app.y_array(1,1);
                point(1).s = 'o';

                point(2).x = app.x_array(1,2);
                point(2).y = app.y_array(1,2);
                point(2).s = '^';
                
                point(3).x = app.x_array(1,3);
                point(3).y = app.y_array(1,3);
                point(3).s = '^';
                    
                point(4).x = app.x_array(1,4);
                point(4).y = app.y_array(1,4);
                point(4).s = '^';
                    
                point(5).x = app.x_array(1,5);
                point(5).y = app.y_array(1,5);
                point(5).s = 'o';
                    
                point(6).x = app.x_array(1,6);
                point(6).y = app.y_array(1,6);
                point(6).s = '^';
                    
                point(7).x = app.x_array(1,7);
                point(7).y = app.y_array(1,7);
                point(7).s = '^';
                    
                point(8).x = app.x_array(1,8);
                point(8).y = app.y_array(1,8);
                point(8).s = '^';
                    
                point(9).x = app.x_array(1,9);
                point(9).y = app.y_array(1,9);
                point(9).s = 'o';
                    
                point(10).x = app.x_array(1,10);
                point(10).y = app.y_array(1,10);
                point(10).s = '^';
                    
                point(11).x = app.x_array(1,11);
                point(11).y = app.y_array(1,11);
                point(11).s = '^';
                    
                point(12).x = app.x_array(1,12);
                point(12).y = app.y_array(1,12);
                point(12).s = '^';
                    
                point(13).x = app.x_array(1,13);
                point(13).y = app.y_array(1,13);
                point(13).s = 'p';
                    
                point(14).x = app.x_array(1,2)+0.2;
                point(14).y = app.y_array(1,2)+0.14;
                point(14).s = 's';

                point(15).x = app.x_array(1,3)-0.1;
                point(15).y = app.y_array(1,3)+0.25;
                point(15).s = 's';

                point(16).x = app.x_array(1,4)-0.2;
                point(16).y = app.y_array(1,4)-0.15;
                point(16).s = 's';

                point(17).x = app.x_array(1,5)-0.1;
                point(17).y = app.y_array(1,5)+0.25;
                point(17).s = 's';

                point(18).x = app.x_array(1,6)+0.17;
                point(18).y = app.y_array(1,6)+0.25;
                point(18).s = 's';

                point(19).x = app.x_array(1,7)+0.1;
                point(19).y = app.y_array(1,7)+0.25;
                point(19).s = 's';

                point(20).x = app.x_array(1,8)-0.1;
                point(20).y = app.y_array(1,8)-0.25;
                point(20).s = 's';

                point(21).x = app.x_array(1,9)-0.12;
                point(21).y = app.y_array(1,9)-0.28;
                point(21).s = 's';

                point(22).x = app.x_array(1,10)-0.28;
                point(22).y = app.y_array(1,10)-0.15;
                point(22).s = 's';

                point(23).x = app.x_array(1,11)+0.08;
                point(23).y = app.y_array(1,11)+0.21;
                point(23).s = 's';

                point(24).x = app.x_array(1,1)+0.4;
                point(24).y = app.y_array(1,1)-0.3;
                point(24).s = 's';

                point(25).x = app.x_array(1,12)-0.31;
                point(25).y = app.y_array(1,12)-0.16;
                point(25).s = 's';

                point(26).x = app.x_array(1,13)-0.28;
                point(26).y = app.y_array(1,13)+0.15;
                point(26).s = 's';

                rti = zeros(1,nL);
                phi = zeros(1,nL);
                sti = zeros(1,nL);
                for j = 1:nL
                    rti(1,j) = sqrt((point(j).x-xS)^2 + (point(j).y-yS)^2) + sample(app,app.sigma_d^2);
                    phi(1,j) = atan2(point(j).y-yS, point(j).x-xS) - app.theta_r + sample(app,app.sigma_phi^2);
                    if point(j).s(1) == 's'
                        sti(1,j) = 1;
                    elseif point(j).s(1) == '^'
                        sti(1,j) = 2;
                    elseif point(j).s(1) == 'p'
                        sti(1,j) = 3;
                    else
                        sti(1,j) = 0;
                    end
                    sti(1,j) = round(sti(1,j) + sample(app,app.sigma_s^2));
                end

%                 filo = rad2deg(phi)
%                 [rad2deg(-floor(app.tS/app.dtS)*app.dtS+((1-1)*app.dtS)) ...
%                 rad2deg(-floor(app.tS/app.dtS)*app.dtS+((2-1)*app.dtS)) ...
%                 rad2deg(-floor(app.tS/app.dtS)*app.dtS+((3-1)*app.dtS)) ...
%                 rad2deg(-floor(app.tS/app.dtS)*app.dtS+((4-1)*app.dtS)) ...
%                 rad2deg(-floor(app.tS/app.dtS)*app.dtS+((5-1)*app.dtS)) ...
%                 rad2deg(-floor(app.tS/app.dtS)*app.dtS+((6-1)*app.dtS))]
%                 rad2deg(floor(app.tS/app.dtS)*app.dtS)
%                 ind = zeros(1,app.nStp);
%                 for i = 1:1:2*floor(app.tS/app.dtS)+1
%                     if rti(1) < app.d
%                         if phi(1) > -floor(app.tS/app.dtS)*app.dtS+((i-1)*app.dtS) && phi(1,1) < floor(app.tS/app.dtS)*app.dtS
%                             ind(1,1) = i;
%                         end
%                     end
%                 end
% %                 ind
%                 for i = 1:1:2*floor(app.tS/app.dtS)+1
%                     if rti(1,2) < app.d
%                         if phi(1,2) > -floor(app.tS/app.dtS)*app.dtS+((i-1)*app.dtS) && phi(1,2) < floor(app.tS/app.dtS)*app.dtS
%                             ind(1,2) = i;
%                         end
%                     end
%                 end
% %                 ind
%                 for i = 1:1:2*floor(app.tS/app.dtS)+1
%                     if rti(1,3) < app.d
%                         if phi(1,3) > -floor(app.tS/app.dtS)*app.dtS+((i-1)*app.dtS) && phi(1,3) < floor(app.tS/app.dtS)*app.dtS
%                             ind(1,3) = i;
%                         end
%                     end
%                 end
% %                 ind

                ind = zeros(1,nL);
                for i = 1:1:2*floor(app.tS/app.dtS)+1
                    for j = 1:nL
                        if rti(1,j) < app.d && phi(1,j) > -floor(app.tS/app.dtS)*app.dtS+((i-1)*app.dtS) && phi(1,j) < floor(app.tS/app.dtS)*app.dtS
                            ind(1,j) = i;
                        end
                    end
                end
                
                s_vec = [app.d;app.tS;app.dtS];
                f_vec = [rti;ind;sti];
    
                drawRobotSensor(app,[app.x_r;app.y_r;app.theta_r],s_vec,f_vec)
                
                for j = 1:nL
                    plot(app.UIAxes,point(j).x,point(j).y,point(j).s,'MarkerEdgeColor','k')
                end
                    
            end

        end
        
        function runFunc(app)
            
            if app.comp_type == 1
                
                % direct evaluation (Do not auto execute because of the
                % computational time requirement)
            
            else
                if app.nSmp == 0
                    drawFunc(app)
                    return;
                end
                
                x_t = zeros(3,app.nSmp*app.nStp);
                for j = 1:app.nSmp
                    
                    k = 1;
                    x_t(:,1+(j-1)*app.nStp) = app.x_t_array(:,k);
    
                    for i = 1+(j-1)*app.nStp:j*(app.nStp)-1
                        
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                            x_t(:,i+1) = sample_motion_model_velocity(app,u_t,x_t(:,i));
                        else
                            u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                            x_t(:,i+1) = sample_motion_model_odometry(app,u_t,x_t(:,i));
                        end
    
                        k = k+1;
                    end
                end
    
                app.x_t_sample = x_t;
                
                drawFunc(app)
                
            end
            
        end
        
        function updateTrajectorySensor(app)
            
            % u-shape trajectory
            
            app.nStp = 13;
            
            app.x_array = [0,2,4,6,8,8,8,8,8,6,4,2,0]/2;
            app.y_array = [0,0,0,0,0,2,4,6,8,8,8,8,8]/2;
            app.theta_array = [0,0,0,0,pi/2,pi/2,pi/2,pi/2,pi,pi,pi,pi,pi];
            
            app.x_r = app.x_array(1,1);
            app.y_r = app.y_array(1,1);
            app.theta_r = app.theta_array(1,1);
            
            v = zeros(1,app.nStp); % m/s
            omega = zeros(1,app.nStp); % rad/s
            
            for i = 1:app.nStp-1
                v(1,i) = sqrt((app.x_array(1,i+1)-app.x_array(1,i))^2+(app.y_array(1,i+1)-app.y_array(1,i))^2); % m/s
                omega(1,i) = app.theta_array(1,i+1)-app.theta_array(1,i); % rad/s
            end
            
            app.x_t_array = [app.x_array;app.y_array;app.theta_array];
            app.v_t_array = [v;omega];
            
        end            
        
        function updateTrajectory(app)
            
            if app.traj_type == 0
                % circular trajectory
                
                app.nStp = 8;
                
                x = 1;
                y = 0;
                theta = -pi/2;
                
                v = pi/4*ones(1,app.nStp); % m/s
                omega = -pi/4*ones(1,app.nStp); % rad/s
                
                app.x_array     = x*ones(1,app.nStp);
                app.y_array     = y*ones(1,app.nStp);
                app.theta_array = theta*ones(1,app.nStp);
                
                for i = 1:app.nStp-1
                    app.x_array(1,i+1) = app.x_array(1,i) - (v(1,i)/omega(1,i))*(sin(app.theta_array(1,i)) - sin(app.theta_array(1,i)+omega(1,i)));
                    app.y_array(1,i+1) = app.y_array(1,i) + (v(1,i)/omega(1,i))*(cos(app.theta_array(1,i)) - cos(app.theta_array(1,i)+omega(1,i)));
                    app.theta_array(1,i+1) = app.theta_array(1,i) + omega(1,i);
                end
                
%                 ylim(app.UIAxes,[min(app.y_array)-0.25 max(app.y_array)+0.25])
            else
                % u-shape trajectory
                
                if app.prob_type == 0
                    app.nStp = 15;
                    
                    app.x_array = [0,2,4,6,8,8,8,8,8,8,8,6,4,2,0];
                    app.y_array = [0,0,0,0,0,0,2,4,6,8,8,8,8,8,8];
                    app.theta_array = [0,0,0,0,0,pi/2,pi/2,pi/2,pi/2,pi/2,pi,pi,pi,pi,pi];
                else
                    app.nStp = 13;
                    
                    app.x_array = [0,2,4,6,8,8,8,8,8,6,4,2,0];
                    app.y_array = [0,0,0,0,0,2,4,6,8,8,8,8,8];
                    app.theta_array = [0,0,0,0,0,pi/2,pi/2,pi/2,pi,pi,pi,pi,pi];
                end
                
                v = zeros(1,app.nStp); % m/s
                omega = zeros(1,app.nStp); % rad/s
                
                for i = 1:app.nStp-1
                    v(1,i) = sqrt((app.x_array(1,i+1)-app.x_array(1,i))^2+(app.y_array(1,i+1)-app.y_array(1,i))^2); % m/s
                    omega(1,i) = app.theta_array(1,i+1)-app.theta_array(1,i); % rad/s
                end
                
%                 ylim(app.UIAxes,[min(app.y_array)-1 max(app.y_array)+1])
            end
            
            app.x_t_array = [app.x_array;app.y_array;app.theta_array];
            app.v_t_array = [v;omega];
            
            drawTrajectory(app)
        end            
        
        function nextFunc(app)
            
%                 n = ceil(app.i_r*app.dt_s)
%                 j = rem(app.i_r-1,1/app.dt_s)
%                 
%                 if n < app.nStp-1
%                     dx = (app.x_array(n+1)-app.x_array(n))*app.dt_s;
%                     dy = (app.y_array(n+1)-app.y_array(n))*app.dt_s;
%                     dtheta = (app.theta_array(n+1)-app.theta_array(n))*app.dt_s;
%                 else
%                     dx = (app.x_array(n)-app.x_array(n-1))*app.dt_s;
%                     dy = (app.y_array(n)-app.y_array(n-1))*app.dt_s;
%                     dtheta = (app.theta_array(n)-app.theta_array(n-1))*app.dt_s;
%                 end
%                 
%                 x_bar = app.x_array(n)+j*dx
%                 y_bar = app.y_array(n)+j*dy
%                 theta_bar = app.theta_array(n)+j*dtheta
            
            x_bar       = app.x_array(:,app.i_r);
            y_bar       = app.y_array(:,app.i_r);
            theta_bar	= app.theta_array(:,app.i_r);
            
%                 n = ceil(app.i_r+1*app.dt_s)
%                 j = rem(app.i_r,1/app.dt_s)
%                 
%                 if n < app.nStp-1
%                     dx = (app.x_array(n+1)-app.x_array(n))*app.dt_s;
%                     dy = (app.y_array(n+1)-app.y_array(n))*app.dt_s;
%                     dtheta = (app.theta_array(n+1)-app.theta_array(n))*app.dt_s;
%                 else
%                     dx = (app.x_array(n)-app.x_array(n-1))*app.dt_s;
%                     dy = (app.y_array(n)-app.y_array(n-1))*app.dt_s;
%                     dtheta = (app.theta_array(n)-app.theta_array(n-1))*app.dt_s;
%                 end
%                 
%                 x_bar_prm = app.x_array(n)+j*dx
%                 y_bar_prm = app.y_array(n)+j*dy
%                 theta_bar_prm = app.theta_array(n)+j*dtheta
            
            x_bar_prm       = app.x_array(:,app.i_r+1);
            y_bar_prm       = app.y_array(:,app.i_r+1);
            theta_bar_prm   = app.theta_array(:,app.i_r+1);
            
            x       = app.x_r;
            y       = app.y_r;
            theta   = app.theta_r;
    
            delta_rot1  = atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar;
            delta_trans = sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2);
            delta_rot2  = theta_bar_prm - theta_bar - delta_rot1;
            
            b_sq1 = app.alpha1*delta_rot1^2 + app.alpha2*delta_trans^2;
            b_sq2 = app.alpha3*delta_trans^2 + app.alpha4*(delta_rot1^2+delta_rot2^2);
            b_sq3 = app.alpha1*delta_rot2^2 + app.alpha2*delta_trans^2;
            
            delta_rot1_hat	= delta_rot1 - sample(app,b_sq1);
            delta_trans_hat = delta_trans - sample(app,b_sq2);
            delta_rot2_hat	= delta_rot2 - sample(app,b_sq3);
            
            app.x_r = x + delta_trans_hat*cos(theta + delta_rot1_hat);
            app.y_r = y + delta_trans_hat*sin(theta + delta_rot1_hat);
            app.theta_r = theta + delta_rot1_hat + delta_rot2_hat;
    
            updatePlot(app)
            
        end
        
        function updateProbModel(app)
            
            if app.prob_type == 0
                app.Spinner_5.Visible = 'on';
                app.SpinnerLabel_5.Visible = 'on';
                app.e2Label_3.Visible = 'on';
                app.Spinner_6.Visible = 'on';
                app.SpinnerLabel_6.Visible = 'on';
                app.e2Label_6.Visible = 'on';
            else
                app.Spinner_5.Visible = 'off';
                app.SpinnerLabel_5.Visible = 'off';
                app.e2Label_3.Visible = 'off';
                app.Spinner_6.Visible = 'off';
                app.SpinnerLabel_6.Visible = 'off';
                app.e2Label_6.Visible = 'off';
            end
            
            updateTrajectory(app)
        end
        
        function updateSwitch(app)
            
            cla(app.UIAxes)
            
            if app.hw_switch == 0
                app.Switch.Value = "Prob. Motion";
                app.MotionPanel.Enable = 'on';
                app.TrajectoryButtonGroup.Enable = 'on';
                app.ComputingalgoButtonGroup.Enable = 'on';
                app.ProbabilisticmodelButtonGroup.Enable = 'on';
                app.SensorPanel.Enable = 'off';
                app.NavigationPanel.Enable = 'off';
                
                app.SPButton.Enable = 'on';
                app.MCButton.Enable = 'on';
                app.EKFButton.Enable = 'on';
                app.UKFButton.Enable = 'on';
                
                app.NextButton.Enable = 'off';
                app.RunButton.Enable = 'off';
                
                updateTrajectory(app)
            else
                app.Switch.Value = "Prob. Measur.";
                app.MotionPanel.Enable = 'off';
                app.TrajectoryButtonGroup.Enable = 'off';
                app.ComputingalgoButtonGroup.Enable = 'off';
                app.ProbabilisticmodelButtonGroup.Enable = 'off';
                app.SensorPanel.Enable = 'on';
                app.NavigationPanel.Enable = 'on';
                
                app.SPButton.Enable = 'off';
                app.MCButton.Enable = 'off';
                app.EKFButton.Enable = 'off';
                app.UKFButton.Enable = 'off';
                
                app.traj_type = 1;
                app.prob_type = 1;
                
                updateManual(app)
                updateTrajectorySensor(app)
                updatePlot(app)
            end
            
        end
        
        function updateManual(app)
            
            cla(app.UIAxes)
            
            if app.manual_on == 0
                app.NextButton.Enable = 'on';
                app.RunButton.Enable = 'on';
                
                app.MotionPanel.Enable = 'on';
                app.XSpinner.Enable = 'off';
                app.YSpinner.Enable = 'off';
                app.HeadingAngSpinner.Enable = 'off';
                
                app.Marker1CheckBox.Enable = 'off';
                app.Marker2CheckBox.Enable = 'off';
                app.Marker3CheckBox.Enable = 'off';
                
                app.MxSpinner.Enable = 'off';
                app.MxSpinner_2.Enable = 'off';
                app.MxSpinner_3.Enable = 'off';
                
                app.MySpinner.Enable = 'off';
                app.MySpinner_2.Enable = 'off';
                app.MySpinner_3.Enable = 'off';
                
                app.SgnDropDown.Enable = 'off';
                app.SgnDropDown_2.Enable = 'off';
                app.SgnDropDown_3.Enable = 'off';
            else
                updateTrajectorySensor(app)
                
                app.NextButton.Enable = 'off';
                app.RunButton.Enable = 'off';
                
                app.MotionPanel.Enable = 'off';
                app.XSpinner.Enable = 'on';
                app.YSpinner.Enable = 'on';
                app.HeadingAngSpinner.Enable = 'on';
                
                app.Marker1CheckBox.Enable = 'on';
                app.Marker2CheckBox.Enable = 'on';
                app.Marker3CheckBox.Enable = 'on';

                updateMarker(app)
            end
                
            updatePlot(app)
            
        end
        
        function updateMarker(app)
            if app.marker(1) == 1
                app.MxSpinner.Enable = 'on';
                app.MySpinner.Enable = 'on';
                app.SgnDropDown.Enable = 'on';
            else
                app.MxSpinner.Enable = 'off';
                app.MySpinner.Enable = 'off';
                app.SgnDropDown.Enable = 'off';
            end
            if app.marker(2) == 1
                app.MxSpinner_2.Enable = 'on';
                app.MySpinner_2.Enable = 'on';
                app.SgnDropDown_2.Enable = 'on';
            else
                app.MxSpinner_2.Enable = 'off';
                app.MySpinner_2.Enable = 'off';
                app.SgnDropDown_2.Enable = 'off';
            end
            if app.marker(3) == 1
                app.MxSpinner_3.Enable = 'on';
                app.MySpinner_3.Enable = 'on';
                app.SgnDropDown_3.Enable = 'on';
            else
                app.MxSpinner_3.Enable = 'off';
                app.MySpinner_3.Enable = 'off';
                app.SgnDropDown_3.Enable = 'off';
            end
            
            app.nF = app.marker(1) + app.marker(2) + app.marker(3);
            
            updatePlot(app)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.RangeSpinner.Value = app.d;
            app.BearingSpinner.Value = rad2deg(app.tS);
            app.ResSpinner.Value = rad2deg(app.dtS);
            
            app.sigma_dSpinner.Value = app.sigma_d;
            app.sigma_phiSpinner.Value = rad2deg(app.sigma_phi);
            app.sigma_sSpinner.Value = app.sigma_s;
            
            app.Marker1CheckBox.Value = app.marker(1);
            app.Marker2CheckBox.Value = app.marker(2);
            app.Marker3CheckBox.Value = app.marker(3);
            updateMarker(app)
            
            app.MxSpinner.Value = app.p1x;
            app.MySpinner.Value = app.p1y;
            app.SgnDropDown.Value = app.p1s;
            
            app.MxSpinner_2.Value = app.p2x;
            app.MySpinner_2.Value = app.p2y;
            app.SgnDropDown_2.Value = app.p2s;
            
            app.MxSpinner_3.Value = app.p3x;
            app.MySpinner_3.Value = app.p3y;
            app.SgnDropDown_3.Value = app.p3s;
            
            app.XSpinner.Value = app.x_0;
            app.YSpinner.Value = app.y_0;
            app.HeadingAngSpinner.Value = rad2deg(app.theta_0);
            
            app.EnablemanualrobotposeCheckBox.Value = app.manual_on;
            
            if app.traj_type == 1
                app.CircularButton.Value = 0;
            else
                app.CircularButton.Value = 1;
            end
                        
            if app.prob_type == 1
                app.DeadReckoningButton.Value = 0;
            else
                app.DeadReckoningButton.Value = 1;
            end
            
            app.NextButton.Enable = 'off';
            app.RunButton.Enable = 'off';
            
            updateProbModel(app)
            updateSwitch(app)
            
            grid(app.UIAxes,'on')
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'padded')
%             app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.PlotBoxAspectRatio = [1.6 1 1];
            
        end

        % Size changed function: KOM613EHomeworkAppUIFigure
        function KOM613EHomeworkAppUIFigureSizeChanged(app, event)
%             position = app.KOM613EHomeworkAppUIFigure.Position;
%             
%             if position(3) < 700
%                 app.KOM613EHomeworkAppUIFigure.Position(3) = 700;
%             end
%             if position(4) < 450
%                 app.KOM613EHomeworkAppUIFigure.Position(4) = 450;
%             end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
            if app.hw_switch == 0
                if app.prob_type == 0
                    runFunc(app)
                else
                    runDirectEval(app)
                end
            else
                updatePlot(app)
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.i_r = 0;
            
            close all
            cla(app.UIAxes)
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'padded')
            app.UIAxes.PlotBoxAspectRatio = [1.6 1 1];
            if app.hw_switch == 0
                updateTrajectory(app)
            else
                updateTrajectorySensor(app)
                updatePlot(app)
            end
            
        end

        % Value changing function: Spinner_1
        function Spinner_1_7ValueChanging(app, event)
            app.alpha1 = event.Value*0.01;
            
            if app.hw_switch == 0
                runFunc(app)
            end
        end

        % Value changing function: Spinner_2
        function Spinner_2_2ValueChanging(app, event)
            app.alpha2 = event.Value*0.01;
            
            if app.hw_switch == 0
                runFunc(app)
            end
        end

        % Value changing function: Spinner_3
        function Spinner_3ValueChanging(app, event)
            app.alpha3 = event.Value*0.01;

            if app.hw_switch == 0
                runFunc(app)
            end
        end

        % Value changing function: Spinner_4
        function Spinner_4_4ValueChanging(app, event)
            app.alpha4 = event.Value*0.01;

            if app.hw_switch == 0
                runFunc(app)
            end
        end

        % Value changing function: Spinner_5
        function Spinner_5_3ValueChanging(app, event)
            app.alpha5 = event.Value*0.01;

            if app.hw_switch == 0
                runFunc(app)
            end
        end

        % Value changing function: Spinner_6
        function Spinner_6_6ValueChanging(app, event)
            app.alpha6 = event.Value*0.01;

            if app.hw_switch == 0
                runFunc(app)
            end
        end

        % Selection changed function: NoisedistButtonGroup
        function NoisedistButtonGroupSelectionChanged(app, event)
            selectedButton = app.NoisedistButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Triangular')
                app.dist_type = 1;
            else
                app.dist_type = 0;
            end
            
            if app.hw_switch == 0
                runFunc(app)
            end
        end

        % Selection changed function: ProbabilisticmodelButtonGroup
        function ProbabilisticmodelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProbabilisticmodelButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Odometry')
                app.prob_type = 1;
            else
                app.prob_type = 0;
            end
            
            updateProbModel(app)            
            runFunc(app)
        end

        % Selection changed function: TrajectoryButtonGroup
        function TrajectoryButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrajectoryButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'U-Shape')
                app.traj_type = 1;
            else
                app.traj_type = 0;
            end
            
            updateTrajectory(app)
            runFunc(app)
        end

        % Selection changed function: ComputingalgoButtonGroup
        function ComputingalgoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ComputingalgoButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Sampling')
                app.SampleSpinner.Enable = 'on';
                app.comp_type = 0;
                app.RefreshButton.Text = 'Refresh';
                runFunc(app)
            else
                app.SampleSpinner.Enable = 'off';
                app.comp_type = 1;
                app.RefreshButton.Text = 'Execute';
                cla(app.UIAxes)
                drawTrajectory(app)
            end
            
        end

        % Value changing function: SampleSpinner
        function SampleSpinnerValueChanging(app, event)
            app.nSmp = event.Value;
            
            if app.nSmp > 0
                runFunc(app)
            else            
                cla(app.UIAxes)
                updateTrajectory(app)
            end
        end

        % Menu selected function: DrawsymbolicrobotMenu
        function DrawsymbolicrobotMenuSelected(app, event)
            if app.toggle_robot == 0
                app.toggle_robot = 1;
                app.DrawsymbolicrobotMenu.Checked = 'on';
            else
                app.toggle_robot = 0;
                app.DrawsymbolicrobotMenu.Checked = 'off';
            end
            
            drawFunc(app)
        end

        % Menu selected function: DrawtrajectoryMenu
        function DrawtrajectoryMenuSelected(app, event)
            if app.toggle_traj == 0
                app.toggle_traj = 1;
                app.DrawtrajectoryMenu.Checked = 'on';
            else
                app.toggle_traj = 0;
                app.DrawtrajectoryMenu.Checked = 'off';
            end
            
            drawFunc(app)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('KOM 613E-Homework #4\nMertcan Kaya\n518192004');
            uialert(app.KOM613EHomeworkAppUIFigure,message,'About','Icon','info');
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            if strcmp(app.Switch.Value,"Prob. Motion")
                app.hw_switch = 0;
            else
                app.hw_switch = 1;
            end
            
            updateSwitch(app)
            
        end

        % Value changed function: Marker1CheckBox
        function Marker1CheckBoxValueChanged(app, event)
            app.marker(1) = app.Marker1CheckBox.Value;
            updateMarker(app)
        end

        % Value changed function: Marker2CheckBox
        function Marker2CheckBoxValueChanged(app, event)
            app.marker(2) = app.Marker2CheckBox.Value;
            updateMarker(app)
        end

        % Value changed function: Marker3CheckBox
        function Marker3CheckBoxValueChanged(app, event)
            app.marker(3) = app.Marker3CheckBox.Value;
            updateMarker(app)
        end

        % Value changing function: MxSpinner
        function MxSpinnerValueChanging(app, event)
            app.p1x = event.Value;
            updatePlot(app)
        end

        % Value changing function: MySpinner
        function MySpinnerValueChanging(app, event)
            app.p1y = event.Value;
            updatePlot(app)
        end

        % Value changed function: SgnDropDown
        function SgnDropDownValueChanged(app, event)
            app.p1s = app.SgnDropDown.Value;                
            updatePlot(app)
        end

        % Value changing function: MxSpinner_2
        function MxSpinner_2ValueChanging(app, event)
            app.p2x = event.Value;
            updatePlot(app)
        end

        % Value changing function: MySpinner_2
        function MySpinner_2ValueChanging(app, event)
            app.p2y = event.Value;
            updatePlot(app)
        end

        % Value changed function: SgnDropDown_2
        function SgnDropDown_2ValueChanged(app, event)
            app.p2s = app.SgnDropDown_2.Value;                
            updatePlot(app)
        end

        % Value changing function: MxSpinner_3
        function MxSpinner_3ValueChanging(app, event)
            app.p3x = event.Value;
            updatePlot(app)
        end

        % Value changing function: MySpinner_3
        function MySpinner_3ValueChanging(app, event)
            app.p3y = event.Value;
            updatePlot(app)
        end

        % Value changed function: SgnDropDown_3
        function SgnDropDown_3ValueChanged(app, event)
            app.p3s = app.SgnDropDown_3.Value;                
            updatePlot(app)
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(app, event)
            app.x_0 = event.Value;
            updatePlot(app)
        end

        % Value changing function: YSpinner
        function YSpinnerValueChanging(app, event)
            app.y_0 = event.Value;
            updatePlot(app)
        end

        % Value changing function: HeadingAngSpinner
        function HeadingAngSpinnerValueChanging(app, event)
            app.theta_0 = deg2rad(event.Value);
            if app.theta_0 < 0
                app.theta_0 = app.theta_0 + 2*pi;
                app.HeadingAngSpinner.Value = rad2deg(app.theta_0);
            end
            if app.theta_0 > 2*pi
                app.theta_0 = app.theta_0 - 2*pi;
                app.HeadingAngSpinner.Value = rad2deg(app.theta_0);
            end
            updatePlot(app)
        end

        % Value changing function: RangeSpinner
        function RangeSpinnerValueChanging(app, event)
            app.d = event.Value;
            updatePlot(app)
        end

        % Value changing function: BearingSpinner
        function BearingSpinnerValueChanging(app, event)
            app.tS = deg2rad(event.Value);
            updatePlot(app)
        end

        % Value changing function: ResSpinner
        function ResSpinnerValueChanging(app, event)
            app.dtS = deg2rad(event.Value);
            updatePlot(app)
        end

        % Value changing function: sigma_dSpinner
        function sigma_dSpinnerValueChanging(app, event)
            app.sigma_d = event.Value;
            updatePlot(app)
        end

        % Value changing function: sigma_phiSpinner
        function sigma_phiSpinnerValueChanging(app, event)
            app.sigma_phi = deg2rad(event.Value);
            updatePlot(app)
        end

        % Value changing function: sigma_sSpinner
        function sigma_sSpinnerValueChanging(app, event)
            app.sigma_s = event.Value;
            updatePlot(app)
        end

        % Value changed function: SPButton
        function SPButtonValueChanged(app, event)
            app.toggle_points = app.SPButton.Value;
            drawFunc(app)
        end

        % Value changed function: MCButton
        function MCButtonValueChanged(app, event)
            app.toggle_monte = app.MCButton.Value;
            drawFunc(app)
        end

        % Value changed function: EKFButton
        function EKFButtonValueChanged(app, event)
            app.toggle_ekfp = app.EKFButton.Value;
            drawFunc(app)
        end

        % Value changed function: UKFButton
        function UKFButtonValueChanged(app, event)
            app.toggle_ukfp = app.UKFButton.Value;
            drawFunc(app)
        end

        % Value changed function: EnablemanualrobotposeCheckBox
        function EnablemanualrobotposeCheckBoxValueChanged(app, event)
            app.manual_on = app.EnablemanualrobotposeCheckBox.Value;
            updateManual(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            app.i_r = app.i_r + 1;
            
            if app.i_r > app.nStp-1
                app.i_r = 0;
                updateTrajectorySensor(app)
                updatePlot(app)
            else
                nextFunc(app)
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            pause(1)
            for i = 1:app.nStp-1
                app.NextButton.Enable = 'off';
                app.RunButton.Enable = 'off';
                app.i_r = app.i_r + 1;
                nextFunc(app)
                pause(1)
            end
            
            app.NextButton.Enable = 'on';
            app.RunButton.Enable = 'on';
            
            app.i_r = 0;
            updateTrajectorySensor(app)
            updatePlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KOM613EHomeworkAppUIFigure = uifigure('Visible', 'off');
            app.KOM613EHomeworkAppUIFigure.Name = 'KOM613E Homework App';
            app.KOM613EHomeworkAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @KOM613EHomeworkAppUIFigureSizeChanged, true);
            app.FileMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.ViewMenu.Text = 'View';
            app.DrawsymbolicrobotMenu = uimenu(app.ViewMenu);
            app.DrawsymbolicrobotMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawsymbolicrobotMenuSelected, true);
            app.DrawsymbolicrobotMenu.Text = 'Draw symbolic robot';
            app.DrawtrajectoryMenu = uimenu(app.ViewMenu);
            app.DrawtrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawtrajectoryMenuSelected, true);
            app.DrawtrajectoryMenu.Checked = 'on';
            app.DrawtrajectoryMenu.Text = 'Draw trajectory';
            app.HelpMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.KOM613EHomeworkAppUIFigure);
            app.MotionPanel = uipanel(app.GridLayout);
            app.MotionPanel.Title = 'Motion';
            app.TrajectoryButtonGroup = uibuttongroup(app.MotionPanel);
            app.TrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrajectoryButtonGroupSelectionChanged, true);
            app.TrajectoryButtonGroup.Title = 'Trajectory';
            app.CircularButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.UShapeButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.UShapeButton.Text = 'U-Shape';
            app.ProbabilisticmodelButtonGroup = uibuttongroup(app.MotionPanel);
            app.ProbabilisticmodelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProbabilisticmodelButtonGroupSelectionChanged, true);
            app.ProbabilisticmodelButtonGroup.Title = 'Probabilistic model';
            app.DeadReckoningButton = uiradiobutton(app.ProbabilisticmodelButtonGroup);
            app.DeadReckoningButton.Text = 'Dead Reckoning';
            app.DeadReckoningButton.Value = true;
            app.OdometryButton = uiradiobutton(app.ProbabilisticmodelButtonGroup);
            app.OdometryButton.Text = 'Odometry';
            app.ComputingalgoButtonGroup = uibuttongroup(app.MotionPanel);
            app.ComputingalgoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComputingalgoButtonGroupSelectionChanged, true);
            app.ComputingalgoButtonGroup.Title = 'Computing algo';
            app.SamplingButton = uiradiobutton(app.ComputingalgoButtonGroup);
            app.SamplingButton.Text = 'Sampling';
            app.SamplingButton.Value = true;
            app.DirectEvaluationButton = uiradiobutton(app.ComputingalgoButtonGroup);
            app.DirectEvaluationButton.Text = 'Direct Evaluation';
            app.SampleSpinnerLabel = uilabel(app.ComputingalgoButtonGroup);
            app.SampleSpinnerLabel.Text = 'Sample:';
            app.SampleSpinner = uispinner(app.ComputingalgoButtonGroup);
            app.SampleSpinner.ValueChangingFcn = createCallbackFcn(app, @SampleSpinnerValueChanging, true);
            app.SampleSpinner.Limits = [0 9999];
            app.NoisedistButtonGroup = uibuttongroup(app.MotionPanel);
            app.NoisedistButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoisedistButtonGroupSelectionChanged, true);
            app.NormalButton = uiradiobutton(app.NoisedistButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.TriangularButton = uiradiobutton(app.NoisedistButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.NoisedistLabel = uilabel(app.NoisedistButtonGroup);
            app.NoisedistLabel.Text = 'Noise dist:';
            app.ErrorparametersPanel = uipanel(app.MotionPanel);
            app.ErrorparametersPanel.Title = 'Error parameters';
            app.e2Label_1 = uilabel(app.ErrorparametersPanel);
            app.e2Label_1.Text = 'e-2';
            app.e2Label_2 = uilabel(app.ErrorparametersPanel);
            app.e2Label_2.Text = 'e-2';
            app.e2Label_3 = uilabel(app.ErrorparametersPanel);
            app.e2Label_3.Text = 'e-2';
            app.e2Label_4 = uilabel(app.ErrorparametersPanel);
            app.e2Label_4.Text = 'e-2';
            app.e2Label_5 = uilabel(app.ErrorparametersPanel);
            app.e2Label_5.Text = 'e-2';
            app.e2Label_6 = uilabel(app.ErrorparametersPanel);
            app.e2Label_6.Text = 'e-2';
            app.SpinnerLabel_3 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_3.Text = 'α 3';
            app.Spinner_3 = uispinner(app.ErrorparametersPanel);
            app.Spinner_3.Step = 0.001;
            app.Spinner_3.ValueChangingFcn = createCallbackFcn(app, @Spinner_3ValueChanging, true);
            app.Spinner_3.Limits = [0 10];
            app.SpinnerLabel_2 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_2.Text = 'α 2';
            app.Spinner_2 = uispinner(app.ErrorparametersPanel);
            app.Spinner_2.Step = 0.001;
            app.Spinner_2.ValueChangingFcn = createCallbackFcn(app, @Spinner_2_2ValueChanging, true);
            app.Spinner_2.Limits = [0 10];
            app.SpinnerLabel_5 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_5.Text = 'α 5';
            app.Spinner_5 = uispinner(app.ErrorparametersPanel);
            app.Spinner_5.Step = 0.001;
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_5_3ValueChanging, true);
            app.Spinner_5.Limits = [0 10];
            app.SpinnerLabel_4 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_4.Text = 'α 4';
            app.Spinner_4 = uispinner(app.ErrorparametersPanel);
            app.Spinner_4.Step = 0.001;
            app.Spinner_4.ValueChangingFcn = createCallbackFcn(app, @Spinner_4_4ValueChanging, true);
            app.Spinner_4.Limits = [0 10];
            app.Label = uilabel(app.ErrorparametersPanel);
            app.Label.Text = 'α 1';
            app.Spinner_1 = uispinner(app.ErrorparametersPanel);
            app.Spinner_1.Step = 0.001;
            app.Spinner_1.ValueChangingFcn = createCallbackFcn(app, @Spinner_1_7ValueChanging, true);
            app.Spinner_1.Limits = [0 10];
            app.SpinnerLabel_6 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_6.Text = 'α 6';
            app.Spinner_6 = uispinner(app.ErrorparametersPanel);
            app.Spinner_6.Step = 0.001;
            app.Spinner_6.ValueChangingFcn = createCallbackFcn(app, @Spinner_6_6ValueChanging, true);
            app.Spinner_6.Limits = [0 10];
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanel.Title = 'Main';
            app.ClearButton = uibutton(app.MainPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RefreshButton = uibutton(app.MainPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SwitchLabel = uilabel(app.MainPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.MainPanel, 'slider');
            app.Switch.Items = {'Prob. Motion', 'Prob. Measur.'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Prob. Motion';
            app.SPButton = uibutton(app.MainPanel, 'state');
            app.SPButton.ValueChangedFcn = createCallbackFcn(app, @SPButtonValueChanged, true);
            app.SPButton.Text = 'SP';
            app.SPButton.Value = true;
            app.MCButton = uibutton(app.MainPanel, 'state');
            app.MCButton.ValueChangedFcn = createCallbackFcn(app, @MCButtonValueChanged, true);
            app.MCButton.Text = 'MC';
            app.EKFButton = uibutton(app.MainPanel, 'state');
            app.EKFButton.ValueChangedFcn = createCallbackFcn(app, @EKFButtonValueChanged, true);
            app.EKFButton.Text = 'EKF';
            app.UKFButton = uibutton(app.MainPanel, 'state');
            app.UKFButton.ValueChangedFcn = createCallbackFcn(app, @UKFButtonValueChanged, true);
            app.UKFButton.Text = 'UKF';
            app.NextButton = uibutton(app.MainPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.RunButton = uibutton(app.MainPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NavigationPanel = uipanel(app.GridLayout);
            app.NavigationPanel.Title = 'Navigation';
            app.meterLabel_2 = uilabel(app.NavigationPanel);
            app.meterLabel_2.Text = 'meter';
            app.meterLabel_3 = uilabel(app.NavigationPanel);
            app.meterLabel_3.Text = 'meter';
            app.degreeLabel_4 = uilabel(app.NavigationPanel);
            app.degreeLabel_4.Text = 'degree';
            app.XSpinnerLabel = uilabel(app.NavigationPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.NavigationPanel);
            app.XSpinner.Step = 0.001;
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanging, true);
            app.YSpinnerLabel = uilabel(app.NavigationPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.NavigationPanel);
            app.YSpinner.Step = 0.001;
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @YSpinnerValueChanging, true);
            app.HeadingAngSpinnerLabel = uilabel(app.NavigationPanel);
            app.HeadingAngSpinnerLabel.Text = 'Heading Ang.';
            app.HeadingAngSpinner = uispinner(app.NavigationPanel);
            app.HeadingAngSpinner.ValueChangingFcn = createCallbackFcn(app, @HeadingAngSpinnerValueChanging, true);
            app.RobotPoseLabel = uilabel(app.NavigationPanel);
            app.RobotPoseLabel.Text = 'Robot Pose:';
            app.EnablemanualrobotposeCheckBox = uicheckbox(app.NavigationPanel);
            app.EnablemanualrobotposeCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablemanualrobotposeCheckBoxValueChanged, true);
            app.EnablemanualrobotposeCheckBox.Text = 'Enable manual robot pose';
            app.SensorPanel = uipanel(app.GridLayout);
            app.SensorPanel.Title = 'Sensor';
            app.RangeSpinnerLabel = uilabel(app.SensorPanel);
            app.RangeSpinnerLabel.Text = 'Range';
            app.RangeSpinner = uispinner(app.SensorPanel);
            app.RangeSpinner.Step = 0.001;
            app.RangeSpinner.ValueChangingFcn = createCallbackFcn(app, @RangeSpinnerValueChanging, true);
            app.RangeSpinner.Limits = [0 10];
            app.BearingSpinnerLabel = uilabel(app.SensorPanel);
            app.BearingSpinnerLabel.Text = 'Bearing';
            app.BearingSpinner = uispinner(app.SensorPanel);
            app.BearingSpinner.ValueChangingFcn = createCallbackFcn(app, @BearingSpinnerValueChanging, true);
            app.BearingSpinner.Limits = [0 180];
            app.ResSpinnerLabel = uilabel(app.SensorPanel);
            app.ResSpinnerLabel.Text = 'Res.';
            app.ResSpinner = uispinner(app.SensorPanel);
            app.ResSpinner.ValueChangingFcn = createCallbackFcn(app, @ResSpinnerValueChanging, true);
            app.ResSpinner.Limits = [1 90];
            app.ResSpinner.Value = 1;
            app.Marker1CheckBox = uicheckbox(app.SensorPanel);
            app.Marker1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Marker1CheckBoxValueChanged, true);
            app.Marker1CheckBox.Text = 'Marker #1';
            app.Marker2CheckBox = uicheckbox(app.SensorPanel);
            app.Marker2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Marker2CheckBoxValueChanged, true);
            app.Marker2CheckBox.Text = 'Marker #2';
            app.Marker3CheckBox = uicheckbox(app.SensorPanel);
            app.Marker3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Marker3CheckBoxValueChanged, true);
            app.Marker3CheckBox.Text = 'Marker #3';
            app.mLabel = uilabel(app.SensorPanel);
            app.mLabel.Text = 'm';
            app.degLabel = uilabel(app.SensorPanel);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.SensorPanel);
            app.degLabel_2.Text = 'deg';
            app.MxSpinnerLabel = uilabel(app.SensorPanel);
            app.MxSpinnerLabel.Text = 'Mx';
            app.MxSpinner = uispinner(app.SensorPanel);
            app.MxSpinner.Step = 0.001;
            app.MxSpinner.ValueChangingFcn = createCallbackFcn(app, @MxSpinnerValueChanging, true);
            app.MxSpinner_2Label = uilabel(app.SensorPanel);
            app.MxSpinner_2Label.Text = 'Mx';
            app.MxSpinner_2 = uispinner(app.SensorPanel);
            app.MxSpinner_2.Step = 0.001;
            app.MxSpinner_2.ValueChangingFcn = createCallbackFcn(app, @MxSpinner_2ValueChanging, true);
            app.MxSpinner_3Label = uilabel(app.SensorPanel);
            app.MxSpinner_3Label.Text = 'Mx';
            app.MxSpinner_3 = uispinner(app.SensorPanel);
            app.MxSpinner_3.Step = 0.001;
            app.MxSpinner_3.ValueChangingFcn = createCallbackFcn(app, @MxSpinner_3ValueChanging, true);
            app.MySpinnerLabel = uilabel(app.SensorPanel);
            app.MySpinnerLabel.Text = 'My';
            app.MySpinner = uispinner(app.SensorPanel);
            app.MySpinner.Step = 0.001;
            app.MySpinner.ValueChangingFcn = createCallbackFcn(app, @MySpinnerValueChanging, true);
            app.MySpinner_2Label = uilabel(app.SensorPanel);
            app.MySpinner_2Label.Text = 'My';
            app.MySpinner_2 = uispinner(app.SensorPanel);
            app.MySpinner_2.Step = 0.001;
            app.MySpinner_2.ValueChangingFcn = createCallbackFcn(app, @MySpinner_2ValueChanging, true);
            app.MySpinner_3Label = uilabel(app.SensorPanel);
            app.MySpinner_3Label.Text = 'My';
            app.MySpinner_3 = uispinner(app.SensorPanel);
            app.MySpinner_3.Step = 0.001;
            app.MySpinner_3.ValueChangingFcn = createCallbackFcn(app, @MySpinner_3ValueChanging, true);
            app.SgnDropDownLabel = uilabel(app.SensorPanel);
            app.SgnDropDownLabel.Text = 'Sgn';
            app.SgnDropDown = uidropdown(app.SensorPanel);
            app.SgnDropDown.Items = {'o', 's', '^', 'p'};
            app.SgnDropDown.ValueChangedFcn = createCallbackFcn(app, @SgnDropDownValueChanged, true);
            app.SgnDropDown.Value = 'o';
            app.SgnDropDown_2Label = uilabel(app.SensorPanel);
            app.SgnDropDown_2Label.Text = 'Sgn';
            app.SgnDropDown_2 = uidropdown(app.SensorPanel);
            app.SgnDropDown_2.Items = {'o', 's', '^', 'p'};
            app.SgnDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SgnDropDown_2ValueChanged, true);
            app.SgnDropDown_2.Value = 'o';
            app.SgnDropDown_3Label = uilabel(app.SensorPanel);
            app.SgnDropDown_3Label.Text = 'Sgn';
            app.SgnDropDown_3 = uidropdown(app.SensorPanel);
            app.SgnDropDown_3.Items = {'o', 's', '^', 'p'};
            app.SgnDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SgnDropDown_3ValueChanged, true);
            app.SgnDropDown_3.Value = 'o';
            app.sigma_dSpinnerLabel = uilabel(app.SensorPanel);
            app.sigma_dSpinnerLabel.Text = 'sigma_d';
            app.sigma_dSpinner = uispinner(app.SensorPanel);
            app.sigma_dSpinner.Step = 0.001;
            app.sigma_dSpinner.ValueChangingFcn = createCallbackFcn(app, @sigma_dSpinnerValueChanging, true);
            app.sigma_dSpinner.Limits = [0 1];
            app.sigma_phiSpinnerLabel = uilabel(app.SensorPanel);
            app.sigma_phiSpinnerLabel.Text = 'sigma_phi';
            app.sigma_phiSpinner = uispinner(app.SensorPanel);
            app.sigma_phiSpinner.Step = 0.1;
            app.sigma_phiSpinner.ValueChangingFcn = createCallbackFcn(app, @sigma_phiSpinnerValueChanging, true);
            app.sigma_phiSpinner.Limits = [0 10];
            app.sigma_sSpinnerLabel = uilabel(app.SensorPanel);
            app.sigma_sSpinnerLabel.Text = 'sigma_s';
            app.sigma_sSpinner = uispinner(app.SensorPanel);
            app.sigma_sSpinner.Step = 0.1;
            app.sigma_sSpinner.ValueChangingFcn = createCallbackFcn(app, @sigma_sSpinnerValueChanging, true);
            app.sigma_sSpinner.Limits = [0 2];
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.KOM613EHomeworkAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KOM613EHomeworkAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KOM613EHomeworkAppUIFigure)
        end
    end
end