classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KOM613EHomeworkAppUIFigure      matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        DrawsymbolicrobotMenu           matlab.ui.container.Menu
        DrawtrajectoryMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        MotionPanel                     matlab.ui.container.Panel
        TrajectoryButtonGroup           matlab.ui.container.ButtonGroup
        CircularButton                  matlab.ui.control.RadioButton
        UShapeButton                    matlab.ui.control.RadioButton
        ProbabilisticmodelButtonGroup   matlab.ui.container.ButtonGroup
        DeadReckoningButton             matlab.ui.control.RadioButton
        OdometryButton                  matlab.ui.control.RadioButton
        ComputingalgoButtonGroup        matlab.ui.container.ButtonGroup
        SamplingButton                  matlab.ui.control.RadioButton
        DirectEvaluationButton          matlab.ui.control.RadioButton
        SampleSpinnerLabel              matlab.ui.control.Label
        SampleSpinner                   matlab.ui.control.Spinner
        NoisedistButtonGroup            matlab.ui.container.ButtonGroup
        NormalButton                    matlab.ui.control.RadioButton
        TriangularButton                matlab.ui.control.RadioButton
        NoisedistLabel                  matlab.ui.control.Label
        ErrorparametersPanel            matlab.ui.container.Panel
        e2Label_1                       matlab.ui.control.Label
        e2Label_2                       matlab.ui.control.Label
        e2Label_3                       matlab.ui.control.Label
        e2Label_4                       matlab.ui.control.Label
        e2Label_5                       matlab.ui.control.Label
        e2Label_6                       matlab.ui.control.Label
        SpinnerLabel_3                  matlab.ui.control.Label
        Spinner_3                       matlab.ui.control.Spinner
        SpinnerLabel_2                  matlab.ui.control.Label
        Spinner_2                       matlab.ui.control.Spinner
        SpinnerLabel_5                  matlab.ui.control.Label
        Spinner_5                       matlab.ui.control.Spinner
        SpinnerLabel_4                  matlab.ui.control.Label
        Spinner_4                       matlab.ui.control.Spinner
        Label                           matlab.ui.control.Label
        Spinner_1                       matlab.ui.control.Spinner
        SpinnerLabel_6                  matlab.ui.control.Label
        Spinner_6                       matlab.ui.control.Spinner
        MainPanel                       matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        SwitchLabel                     matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        SamplePointsButton              matlab.ui.control.StateButton
        MonteCarloButton                matlab.ui.control.StateButton
        EKFPredButton                   matlab.ui.control.StateButton
        UKFPredButton                   matlab.ui.control.StateButton
        StartButton                     matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SensorSwitchLabel               matlab.ui.control.Label
        SensorSwitch                    matlab.ui.control.RockerSwitch
        NavigationPanel                 matlab.ui.container.Panel
        meterLabel_2                    matlab.ui.control.Label
        meterLabel_3                    matlab.ui.control.Label
        degreeLabel_4                   matlab.ui.control.Label
        XSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        HeadingAngSpinnerLabel          matlab.ui.control.Label
        HeadingAngSpinner               matlab.ui.control.Spinner
        RobotPoseLabel                  matlab.ui.control.Label
        Marker1CheckBox                 matlab.ui.control.CheckBox
        Marker2CheckBox                 matlab.ui.control.CheckBox
        Marker3CheckBox                 matlab.ui.control.CheckBox
        MxSpinnerLabel_1                matlab.ui.control.Label
        MxSpinner_1                     matlab.ui.control.Spinner
        MxSpinnerLabel_2                matlab.ui.control.Label
        MxSpinner_2                     matlab.ui.control.Spinner
        MxSpinnerLabel_3                matlab.ui.control.Label
        MxSpinner_3                     matlab.ui.control.Spinner
        MySpinnerLabel_1                matlab.ui.control.Label
        MySpinner_1                     matlab.ui.control.Spinner
        MySpinnerLabel_2                matlab.ui.control.Label
        MySpinner_2                     matlab.ui.control.Spinner
        MySpinnerLabel_3                matlab.ui.control.Label
        MySpinner_3                     matlab.ui.control.Spinner
        SgnDropDownLabel_1              matlab.ui.control.Label
        SgnDropDown_1                   matlab.ui.control.DropDown
        SgnDropDownLabel_2              matlab.ui.control.Label
        SgnDropDown_2                   matlab.ui.control.DropDown
        SgnDropDownLabel_3              matlab.ui.control.Label
        SgnDropDown_3                   matlab.ui.control.DropDown
        SensorPanel                     matlab.ui.container.Panel
        RangeSpinnerLabel               matlab.ui.control.Label
        RangeSpinner                    matlab.ui.control.Spinner
        BearingSpinnerLabel             matlab.ui.control.Label
        BearingSpinner                  matlab.ui.control.Spinner
        ResSpinnerLabel                 matlab.ui.control.Label
        ResSpinner                      matlab.ui.control.Spinner
        mLabel                          matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        dSpinnerLabel                   matlab.ui.control.Label
        dSpinner                        matlab.ui.control.Spinner
        phiSpinnerLabel                 matlab.ui.control.Label
        phiSpinner                      matlab.ui.control.Spinner
        sSpinnerLabel                   matlab.ui.control.Label
        sSpinner                        matlab.ui.control.Spinner
        OntrajectorypointsCheckBox      matlab.ui.control.CheckBox
        LandmarksLabel                  matlab.ui.control.Label
        AroundtrajectorypointsCheckBox  matlab.ui.control.CheckBox
        HandplacedCheckBox              matlab.ui.control.CheckBox
        distSpinnerLabel                matlab.ui.control.Label
        distSpinner                     matlab.ui.control.Spinner
        mLabel_2                        matlab.ui.control.Label
        PlaceonaxesButton               matlab.ui.control.StateButton
        SgnDropDown_4Label              matlab.ui.control.Label
        SgnDropDown_4                   matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        hw_switch = 0;
        sensor_on = 1;
        start_flag = 0;
        
        on_trj_lndmrk = 1;
        arnd_trj_lndmrk = 1;
        r_lm = 0.5;
        hand_pck_lndmrk = 0;
        hand_pck_sgn = 'o';
        hand_pck_place = 0;
        hpNum = 0;
        hpPos;
        hpSgn;
        randP;
        
        % robot dim
        rR = 0.3;
        
        % sensor dim
        rS = 0.075;
        dS_R = [0.15;0]; % sensor distance from robot center
        thetaS_R = 0;

        xR_t = 0.9;
        yR_t = 0.1;
        thetaR_t = pi/3.025;
        
        range = 2;
        bearing = pi/4;
        resolution = pi/20;
            
        nF = 2;
        nP;
        
        marker = [1,1,0];

        p1x = 1.8;
        p1y = 0.7;
        p1s = 'o';
        
        p2x = 1.25;
        p2y = 1.2;
        p2s = '^';
            
        p3x = 1.65;
        p3y = 0.9;
        p3s = 'p';
            
        nStp = 0;
        nSmp = 0;
        
        prob_type = 0;
        traj_type = 1;
        comp_type = 0;
        
        toggle_traj = 1;
        toggle_robot = 0;
        toggle_points = 1;
        toggle_monte = 0;
        toggle_ekfp = 0;
        toggle_ukfp = 0;
        
        x_array;
        y_array;
        theta_array;
        
        i_r = 0;
        
        x_t_array;
        v_t_array;
        
        x_t_sample;
        
        sample_fixed;
        
        R_pose;
        S_pose;
        S_para;
        F_info;
        L_info;
        M_info;

        mu_t;
        Sigma_t;
        
        mu_t_bar;
        Sigma_t_bar;
        
        xR_vec;
    end
    
    properties (Access = public)
        
        dt = 1;
        dist_type = 0;
        
        alpha1 = 0.1*1e-2;
        alpha2 = 0.1*1e-2;
        alpha3 = 0.1*1e-2;
        alpha4 = 0.1*1e-2;
        alpha5 = 0.1*1e-2;
        alpha6 = 0.1*1e-2;
        
        sigma_d = 0.025;
        sigma_phi = deg2rad(2.5);
        sigma_s = 0.4;
        
    end
    
    methods (Access = private)
        
        function n = normalizeFunc(~,value,start,finish) 
        
          width       = finish - start   ;
          offsetValue = value - start ;
        
          n = ( offsetValue - ( floor( offsetValue / width ) * width ) ) + start ;
          
        end
        
        function mS = markerI2S(~,mI)
            if rem(mI,4) == 1
                mS = 's';
            elseif rem(mI,4) == 2
                mS = '^';
            elseif rem(mI,4) == 3
                mS = 'p';
            else
                mS = 'o';
            end
        end
        
        function mI = markerS2I(~,mS)
            if mS == 's'
                mI = 1;
            elseif mS == '^'
                mI = 2;
            elseif mS == 'p'
                mI = 3;
            else
                mI = 0;
            end
        end

        function x_t = g_t(app,u_t,x_tprev)
        
            theta   = x_tprev(3);
            
            if app.prob_type == 0
                v       = u_t(1);
                omega   = u_t(2);
            
                if abs(omega) > 1e-5
                    x_t = x_tprev + [  -(v/omega)*(sin(theta) - sin(theta+omega*app.dt))
                                        (v/omega)*(cos(theta) - cos(theta+omega*app.dt))
                                        omega*app.dt ];
                else
                    x_t = x_tprev + [	v*cos(theta + omega*app.dt)
                                      	v*sin(theta + omega*app.dt)
                                     	omega*app.dt ];
                end
            else
                x_bar       = u_t(1);
                y_bar       = u_t(2);
                theta_bar	= u_t(3);
        
                x_bar_prm       = u_t(4);
                y_bar_prm       = u_t(5);
                theta_bar_prm   = u_t(6);
            
                x_t = x_tprev + [	sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*cos(theta + atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar)
                                    sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*sin(theta + atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar)
                                    theta_bar_prm - theta_bar ];
            end

        end
        
        function g_t_prm = G_t(app,u_t,x_tprev)
        
            theta   = x_tprev(3);
            
            if app.prob_type == 0
                v       = u_t(1);
                omega   = u_t(2);
            
                if abs(omega) > 1e-5
                    g_t_prm = [	1,0,(v/omega)*(cos(omega*app.dt + theta) - cos(theta))
                                0,1,(v/omega)*(sin(omega*app.dt + theta) - sin(theta))
                                0,0,1 ];
                else
                    g_t_prm = [	1,0,-v*app.dt*sin(omega*app.dt + theta)
                                0,1,v*app.dt*cos(omega*app.dt + theta)
                                0,0,1 ];
                end
            else
                x_bar       = u_t(1);
                y_bar       = u_t(2);
                theta_bar	= u_t(3);
        
                x_bar_prm 	= u_t(4);
                y_bar_prm 	= u_t(5);
            
                g_t_prm = [	1, 0, -sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*sin(atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar + theta)
                          	0, 1,  sqrt((x_bar-x_bar_prm)^2 + (y_bar-y_bar_prm)^2)*cos(atan2(y_bar_prm-y_bar, x_bar_prm-x_bar) - theta_bar + theta)
                           	0, 0,  1 ];
            end
        
        end

        function out = V_t(app,u_t,x_tprev)
        
            theta   = x_tprev(3);
            
            v       = u_t(1);
            omega   = u_t(2);
        
            if abs(omega) > 1e-5
                out = [	(-sin(theta)+sin(theta+omega*app.dt))/omega, v*((sin(theta)-sin(theta+omega*app.dt))/omega+cos(theta+omega*app.dt)*app.dt)/omega, 0
                        (cos(theta)-cos(theta+omega*app.dt))/omega, v*(-(cos(theta)-cos(theta+omega*app.dt))/omega+sin(theta+omega*app.dt)*app.dt)/omega, 0
                        0                                        , app.dt                                                                               , app.dt];
            else
        
                out = [ cos(theta+omega*app.dt), -v*sin(theta+omega*app.dt)*app.dt, 0
                        sin(theta+omega*app.dt),  v*cos(theta+omega*app.dt)*app.dt, 0
                        0                       ,  app.dt                           , app.dt];
            end
                
        end
        
        function out = M_t(app,u_t)
        
            v       = u_t(1);
            omega   = u_t(2);
        
            out = [ app.alpha1*v^2+app.alpha2*omega^2, 0                                , 0
                    0                               ,  app.alpha3*v^2+app.alpha4*omega^2, 0
                    0                               ,  0                                , app.alpha5*v^2+app.alpha6*omega^2];
                
        end
        
        function out = h_t(app,x_t,m_j)
        
            out = [ sqrt((m_j(1)-x_t(1))^2+(m_j(2)-x_t(2))^2)
                    normalizeFunc(app,atan2(m_j(2)-x_t(2),m_j(1)-x_t(1))-x_t(3),-pi,pi)
                    m_j(3) ];
                
        end

        function drawSamples(app)
            
            cmap = lines(app.nStp);

            x_steps = zeros(3,app.nSmp,app.nStp);
            for i = 1:app.nStp
                x_steps(:,:,i) = app.x_t_sample(:,i:app.nStp:app.nSmp*app.nStp);
            end
            
            if app.nSmp < 10
                sz = 20-app.nSmp;
            elseif app.nSmp < 100
                sz = 11-app.nSmp/100;
            elseif app.nSmp < 1000
                sz = 10-app.nSmp/200;
            else
                sz = 5-app.nSmp/2500;
            end

            if app.toggle_points == 1
%                 hLine = zeros(app.nStp,1);
                for i = 1:app.nStp
%                     hLine(i) = plot(app.UIAxes,x_steps(1,:,i),x_steps(2,:,i),'.','Color', cmap(i, :),'Marker','.');
                    hLine = scatter(app.UIAxes,x_steps(1,:,i),x_steps(2,:,i),sz,'o','filled','MarkerEdgeColor','none','MarkerFaceColor',cmap(i, :));
%                     hLine.AlphaData = 0*ones(size(x_steps(1,:,i)));
                    hLine.MarkerFaceAlpha = 0.2;
                end
%                 set(h(1),'Marker','x')
            end
            
            mu = zeros(3,app.nStp);
            Sigma = zeros(3,3,app.nStp);
            for k = 1:app.nStp
                mu(:,k) = mean(x_steps(:,:,k),2);
                if app.nSmp > 1
                    S12 = ((x_steps(1,:,k)-mu(1,k))*(x_steps(2,:,k)-mu(2,k))')/(app.nSmp-1);
                    S13 = ((x_steps(1,:,k)-mu(1,k))*(x_steps(3,:,k)-mu(3,k))')/(app.nSmp-1);
                    S23 = ((x_steps(2,:,k)-mu(2,k))*(x_steps(3,:,k)-mu(3,k))')/(app.nSmp-1);
                else
                    S12 = 0;
                    S13 = 0;
                    S23 = 0;
                end
                Sigma(:,:,k) = [var(x_steps(1,:,k)) S12 S13;S12 var(x_steps(2,:,k)) S23;S13 S23 var(x_steps(3,:,k))];
            end
            
            Sigma = real(Sigma);
            
            R = Sigma(:,:,2);

            if app.toggle_monte == 1
                for k = 1:app.nStp
                    drawEllipse(app,mu(:,k),Sigma(:,:,k),cmap(k, :))
                end
            end
            
%             if app.toggle_ekfp == 1
%                 
%                 xR_I = app.R_pose(1);
%                 yR_I = app.R_pose(2);
%                 thetaR_I = app.R_pose(3);
%     
%                 %% EKF localization known correspondences
%                 k = app.i_r + 1;
%     
%                 app.mu_t_bar(:,1) = app.mu_t(:,1);
%                 app.Sigma_t_bar(:,:,1) = app.Sigma_t(:,:,1);
%                 
%                 if app.prob_type == 0
%                     u_t = app.v_t_array(:,k);
%                 else
%                     if k < app.nStp
%                         u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
%                     else
%                         u_t = zeros(6,1);
%                     end
%                 end
%                 app.mu_t(:,k+1) = g_t(app,u_t,app.mu_t(:,k));
%                 app.Sigma_t(:,:,k+1) = G_t(app,u_t,app.mu_t(:,k))*app.Sigma_t(:,:,k)*G_t(app,u_t,app.mu_t(:,k))' ...
%                                     + V_t(app,u_t,app.mu_t(:,k))*M_t(app,u_t)*V_t(app,u_t,app.mu_t(:,k))';
%             
%                 app.mu_t_bar(:,k+1) = app.mu_t(:,k+1);
%                 app.Sigma_t_bar(:,:,k+1) = app.Sigma_t(:,:,k+1);
%             
%             end
            
            if app.toggle_ukfp == 1
                
                n = 3;
                chiNb = 2*n + 1;
                
                kappa = 10;      % kappa >= 0
                alpha = 0.5;    % alpha € (0,1]
                lambda = alpha^2*(n+kappa)-n;
                beta = 2;
                
                mu_bar_u = zeros(3,1,app.nStp);
                Sigma_bar_u = zeros(3,3,app.nStp);
                
                mu_bar_u(:,:,1) = app.x_t_array(:,1);
                Sigma_bar_u(:,:,1) = Sigma(:,:,1);

                drawEllipse(app,mu_bar_u(:,:,1),Sigma_bar_u(:,:,1),'#FF00FF')
                
                chi_bar = zeros(3,chiNb,app.nStp-1);
                for k = 1:app.nStp-1
                    chi = zeros(3,chiNb);
                    if sum(Sigma_bar_u(:,:,k),'all') > 1e-7
                        mu_offset = real(sqrtm((n+lambda)*Sigma_bar_u(:,:,k)));
                    else
                        mu_offset = zeros(size(Sigma_bar_u(:,:,k)));
                    end
                    for j = 1:chiNb
                        if j == 1
                            chi(:,j) = mu_bar_u(:,:,k);
                        elseif j <= n+1
                            chi(:,j) = mu_bar_u(:,:,k) + mu_offset(:,j-1);
                        else
                            chi(:,j) = mu_bar_u(:,:,k) - mu_offset(:,j-n-1);
                        end
                    end

                    omega_m = zeros(1,chiNb);
                    omega_c = zeros(1,chiNb);
                    for j = 1:chiNb
                        if j == 1
                            omega_m(j) = lambda/(n+lambda);
                            omega_c(j) = lambda/(n+lambda)+1-alpha^2+beta;
                        else
                            omega_m(j) = 1/(2*(n+lambda));
                            omega_c(j) = 1/(2*(n+lambda));
                        end
                    end
                
                    chi_tk_bar_s = zeros(3,chiNb);
                    if app.prob_type == 0
                        u_t = app.v_t_array(:,k);
                    else
                      	u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                    end
                    for j = 1:chiNb
                        chi_tk_bar_s(:,j) = g_t(app,u_t,chi(:,j));
                    end

                    for j = 1:chiNb
                        mu_bar_u(:,:,k+1) = mu_bar_u(:,:,k+1) + omega_m(j)*chi_tk_bar_s(:,j);
                    end

                    for j = 1:chiNb
                        Sigma_bar_u(:,:,k+1) = Sigma_bar_u(:,:,k+1) + omega_c(j)*(chi_tk_bar_s(:,j)-mu_bar_u(:,:,k+1))*(chi_tk_bar_s(:,j)-mu_bar_u(:,:,k+1))';
                    end
                    Sigma_bar_u(:,:,k+1) = Sigma_bar_u(:,:,k+1) + R;

                    if sum(Sigma_bar_u(:,:,k+1),'all') > 1e-7
                        mu_tk_offset_bar = real(sqrtm((n+lambda)*Sigma_bar_u(:,:,k+1)));
                    else
                        mu_tk_offset_bar = zeros(size(Sigma_bar_u(:,:,k+1)));
                    end
                    for j = 1:chiNb
                        if j == 1
                            chi_bar(:,j,k+1) = mu_bar_u(:,:,k+1);
                        elseif j <= n+1
                            chi_bar(:,j,k+1) = mu_bar_u(:,:,k+1) + mu_tk_offset_bar(:,j-1);
                        else
                            chi_bar(:,j,k+1) = mu_bar_u(:,:,k+1) - mu_tk_offset_bar(:,j-n-1);
                        end
                    end

                    drawEllipse(app,mu_bar_u(:,:,k+1),Sigma_bar_u(:,:,k+1),'#FF00FF')
                    plot(app.UIAxes,chi_bar(1,[1:3 5:6],k+1),chi_bar(2,[1:3 5:6],k+1),'Marker','.','MarkerSize',10,'LineStyle','none','Color','#FF00FF','LineWidth',1);

                end

            end

        end
        
        function drawTrajectory(app)
            
            hold(app.UIAxes,'on')
            if app.toggle_robot == 1                
                for k = 1:app.nStp
                    drawRobotSym(app,app.x_t_array(:,k))
                end
            else
%                 plot(app.UIAxes,app.x_array,app.y_array,'.k','Marker','*','MarkerSize',5,'LineWidth',2)
                plot(app.UIAxes,app.x_array,app.y_array,'.k')
            end
            
            if app.toggle_traj == 1
                if app.traj_type == 0
                    plot(app.UIAxes,cos(linspace(0,-7*pi/4)),sin(linspace(0,-7*pi/4)),':k')
                else
                    plot(app.UIAxes,app.x_array,app.y_array,':k')
                end
            end
            
        end
        
        function drawEllipse(app,mu,Sigma,color)
        
            if sum(isnan(Sigma),'all') || sum(~isreal(Sigma),'all')
                return
            else
        %         the 95% confidence interval error ellipse
                chisquare_val = sqrt(5.991);
                
        %         % the 99% confidence interval error ellipse
        %         chisquare_val = sqrt(9.210);
                
                theta_grid = linspace(0,2*pi);
        
                [eigenvec, eigenval] = eig(Sigma(1:2,1:2));
        
                % Get the index of the largest eigenvector
                [largest_eigenvec_ind_c, ~] = find(eigenval == max(max(eigenval)));
                largest_eigenvec = eigenvec(:, largest_eigenvec_ind_c);
        
                % Get the largest eigenvalue
                largest_eigenval = max(max(eigenval));
        
                % Get the smallest eigenvector and eigenvalue
                if(largest_eigenvec_ind_c == 1)
                    smallest_eigenval = max(eigenval(:,2));
        %             smallest_eigenvec = eigenvec(:,2);
                else
                    smallest_eigenval = max(eigenval(:,1));
        %             smallest_eigenvec = eigenvec(1,:);
                end
        
                % Calculate the angle between the x-axis and the largest eigenvector
                angle = atan2(largest_eigenvec(2), largest_eigenvec(1));
        
                % This angle is between -pi and pi.
                % Let's shift it such that the angle is between 0 and 2pi
                if(angle < 0)
                    angle = angle + 2*pi;
                end
        
                phi = angle;
                a = chisquare_val * sqrt(largest_eigenval);
                b = chisquare_val * sqrt(smallest_eigenval);
        
                % the ellipse in x and y coordinates 
                ellipse_x_r  = a*cos(theta_grid);
                ellipse_y_r  = b*sin(theta_grid);
        
                % rotation matrix of angle phi
                rot = [cos(phi) sin(phi); -sin(phi) cos(phi)];
        
                % rotation of the ellipse to angle phi
                r_ellipse = [ellipse_x_r;ellipse_y_r]' * rot;
        
                plot(app.UIAxes,mu(1),mu(2),'Marker','*','Color',color);
                plot(app.UIAxes,r_ellipse(:,1) + mu(1),r_ellipse(:,2) + mu(2),'Color',color);
            end
            
        end

        function drawRobotSym(app,x_vec)
                        
            xR = x_vec(1);
            yR = x_vec(2);
            tR = x_vec(3);
            
            if app.traj_type == 0
                r = 0.1;
            else
                r = 0.35;
            end
            
            hold(app.UIAxes,"on")
            plot(app.UIAxes,xR + r*cos(linspace(0,2*pi)),yR + r*sin(linspace(0,2*pi)),'-k',[xR,xR + r*cos(tR)],[yR,yR + r*sin(tR)],'-k',xR,yR,'.k')
            
        end
        
        function drawRobot(app)
        
            xR = app.R_pose(1);
            yR = app.R_pose(2);
            tR = app.R_pose(3);
        
            hold(app.UIAxes,"on")
            plot(app.UIAxes,xR + app.rR*cos(linspace(0,2*pi)),yR + app.rR*sin(linspace(0,2*pi)),'-k',xR,yR,'.k')
            plot(app.UIAxes,[xR,xR + app.rR*cos(tR)],[yR,yR + app.rR*sin(tR)],'-k')
        
            rng = app.S_para(1);
            psi = app.S_para(2);
            res = app.S_para(3);
        
            xS = app.S_pose(1);
            yS = app.S_pose(2);
            tS = app.S_pose(3);
        
            plot(app.UIAxes,xS + app.rS*cos(linspace(0,2*pi)),yS + app.rS*sin(linspace(0,2*pi)),'-b',xS,yS,'.b')
            if app.sensor_on == 1 && app.start_flag ~= 0
                ang = floor(psi/res);
                plot(app.UIAxes,xS,yS,'.r')
                pcd = plot(app.UIAxes,xS + rng*cos(linspace(tS-psi,tS+psi)),yS + rng*sin(linspace(tS-psi,tS+psi)),':r');
                pcd.Color = [pcd.Color 0.5];
                pnd = plot(app.UIAxes,[xS xS + rng*cos(tS-psi)],[yS yS + rng*sin(tS-psi)],':r');
                pnd.Color = [pnd.Color 0.5];
                ppd = plot(app.UIAxes,[xS xS + rng*cos(tS+psi)],[yS yS + rng*sin(tS+psi)],':r');
                ppd.Color = [ppd.Color 0.5];
                for i = 1:2*ang+1
                    pmd = plot(app.UIAxes,[xS, xS+rng*cos(tS+res*(i-1-ang))],[yS, yS+rng*sin(tS+res*(i-1-ang))],'-r');
                    pmd.Color = [pmd.Color 0.5];
                end
                
                dF = app.F_info(1,:);
                tF = app.F_info(2,:);
                iF = app.F_info(3,:);
                
                d_i = rng*ones(1,2*ang);
                t_i = zeros(1,2*ang);
                mx = zeros(1,2*ang);
                my = zeros(1,2*ang);
                ms = zeros(1,2*ang);
            
                for j = 1:length(dF)
                    if iF(j) > 0
                        d_i(iF(j)) = dF(j);
                        t_i(iF(j)) = tF(j);
                        mx(iF(j)) = app.M_info(1,j);
                        my(iF(j)) = app.M_info(2,j);
                        ms(iF(j)) = app.M_info(3,j);
                    end
                end
                
                for i = 1:2*ang
                    if d_i(i) < rng
                        plot(app.UIAxes, xS+d_i(i)*cos(linspace(t_i(i)-0.5*res,t_i(i)+0.5*res)), yS+d_i(i)*sin(linspace(t_i(i)-0.5*res,t_i(i)+0.5*res)), '-r')
                        plot(app.UIAxes, [xS, xS+d_i(i)*cos(t_i(i)-0.5*res)], [yS, yS+d_i(i)*sin(t_i(i)-0.5*res)], '-r')
                        plot(app.UIAxes, [xS, xS+d_i(i)*cos(t_i(i))], [yS, yS+d_i(i)*sin(t_i(i))],'--r')
                        plot(app.UIAxes, [xS, xS+d_i(i)*cos(t_i(i)+0.5*res)], [yS, yS+d_i(i)*sin(t_i(i)+0.5*res)], '-r')
                        plot(app.UIAxes, mx(i), my(i), 'r', 'Marker', markerI2S(app,ms(i)))
                    end
                end
            end
            
        end

        function drawRobotGhost(app,R_pose,alpha)
        
            xR = R_pose(1);
            yR = R_pose(2);
            tR = R_pose(3);
        
            hold(app.UIAxes,"on")
            pg1 = plot(app.UIAxes,xR + app.rR*cos(linspace(0,2*pi)),yR + app.rR*sin(linspace(0,2*pi)),'-k');
            pg1.Color = [pg1.Color alpha];
            pg2 = plot(app.UIAxes,[xR,xR + app.rR*cos(tR)],[yR,yR + app.rR*sin(tR)],'-k');
            pg2.Color = [pg2.Color alpha];
        
            xS = app.S_pose(1);
            yS = app.S_pose(2);
        
            pg3 = plot(app.UIAxes,xS + app.rS*cos(linspace(0,2*pi)),yS + app.rS*sin(linspace(0,2*pi)),'-b');
            pg3.Color = [pg3.Color alpha];
            
        end

        function runDirectEval(app)
            
            if app.traj_type == 0
                x_grid = -2:0.01:2;
                y_grid = -2:0.01:2;
            else
                x_grid = -1:0.05:10;
                y_grid = -1:0.05:10;
            end
            [x_mesh,y_mesh] = meshgrid(x_grid,y_grid);
        
            p_x_t = zeros(length(x_grid),length(y_grid),app.nStp-1);
            for k = 1:app.nStp-1
        
                for i = 1:length(x_grid)
                    for j = 1:length(y_grid)
                        x_t = [x_mesh(i,j);y_mesh(i,j);app.x_t_array(3,k+1)];
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                            p_x_t(i,j,k) = motion_model_velocity(app,x_t,u_t,app.x_t_array(:,k));
                        else
                            u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                            p_x_t(i,j,k) = motion_model_odometry(app,x_t,u_t,app.x_t_array(:,k));
                        end
                    end
                end
        
            end
        
            n_term = 1;
            cla(app.UIAxes)
            drawTrajectory(app)
            hold(app.UIAxes,'on')
            for k = 1:app.nStp-1
                surf(app.UIAxes,x_mesh,y_mesh,n_term*p_x_t(:,:,k),'FaceAlpha','interp','AlphaData',n_term*p_x_t(:,:,k));
                shading(app.UIAxes,'interp')
                if app.traj_type == 0
                    axis(app.UIAxes,[-2 2 -2 2])
                else
                    axis(app.UIAxes,[-1 9 -1 9])
                end
                zlim(app.UIAxes,'auto')
            end

%             for k = 1:app.nStp-1
%                 figure
%                 plot(app.x_t_array(1,:),app.x_t_array(2,:),'.k')
%                 surf(x_mesh,y_mesh,p_x_t(:,:,k),'FaceAlpha','interp','AlphaData',p_x_t(:,:,k));
%                 xlabel('x')
%                 ylabel('y')
%                 shading('interp')
%                 if app.traj_type == 0
%                     axis([-2 2 -2 2])
%                 else
%                     axis([-1 9 -1 9])
%                 end
%                 zlim('auto')
%             end
    
        end
        
        function getSamples(app)
            
            if app.nSmp > 0
                x_t = zeros(3,app.nSmp*app.nStp);
                for j = 1:app.nSmp
                    
                    k = 1;
                    x_t(:,1+(j-1)*app.nStp) = app.x_t_array(:,k);
    
                    for i = 1+(j-1)*app.nStp:j*(app.nStp)-1
                        
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                            x_t(:,i+1) = sample_motion_model_velocity(app,u_t,x_t(:,i));
                        else
                            u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                            x_t(:,i+1) = sample_motion_model_odometry(app,u_t,x_t(:,i));
                        end
    
                        k = k+1;
                    end
                end
    
                app.x_t_sample = x_t;
            
            end

        end
        
        function calculatePose(app)
            
            % robot pose
            xR_I = app.xR_t;
            yR_I = app.yR_t;
            thetaR_I = normalizeFunc(app,app.thetaR_t,-pi,pi);
            
            app.R_pose = [xR_I;yR_I;thetaR_I];

            % sensor pose
            xS_I = xR_I + app.dS_R(1)*cos(thetaR_I) - app.dS_R(2)*sin(thetaR_I);
            yS_I = yR_I + app.dS_R(1)*sin(thetaR_I) + app.dS_R(2)*cos(thetaR_I);
            thetaS_I = thetaR_I + app.thetaS_R;
            
            app.S_pose = [xS_I;yS_I;thetaS_I];
    
            app.S_para = [app.range;app.bearing;app.resolution]; 

        end
        
        function localizationNew(app)
            
            %% EKF localization known correspondences
            k = app.i_r + 1;

            app.mu_t_bar(:,1) = app.mu_t(:,1);
            app.Sigma_t_bar(:,:,1) = app.Sigma_t(:,:,1);
            
            if app.sensor_on == 1
                Q_t = diag([app.sigma_d,app.sigma_phi,app.sigma_s]);
                
                app.mu_t(:,k) = app.mu_t_bar(:,k);
                app.Sigma_t(:,:,k) = app.Sigma_t_bar(:,:,k);
                    
                for i = 1:app.nP
                    if app.F_info(3,i) > 0
                        m_j = app.M_info(:,i);
                        z_t = h_t(app,app.R_pose,m_j);
                        z_t_hat = h_t(app,app.mu_t_bar(:,k),m_j);
                        q = (m_j(1)-app.mu_t_bar(1,k))^2+(m_j(2)-app.mu_t_bar(2,k))^2;
                        H_t = [ -(m_j(1)-app.mu_t_bar(1,k))/sqrt(q), -(m_j(2)-app.mu_t_bar(2,k))/sqrt(q), 0
                                (m_j(2)-app.mu_t_bar(2,k))/q, -(m_j(1)-app.mu_t_bar(1,k))/q, -1
                                0, 0, 0];
                        S_t = H_t*app.Sigma_t_bar(:,:,k)*H_t'+Q_t;
                        K_t = app.Sigma_t_bar(:,:,k)*H_t'/S_t;
                        app.mu_t(:,k) = app.mu_t_bar(:,k) + K_t*(z_t-z_t_hat);
                        app.Sigma_t(:,:,k) = (eye(3)-K_t*H_t)*app.Sigma_t_bar(:,:,k);
                    end
                end

            end
            
            if app.prob_type == 0
                u_t = app.v_t_array(:,k);
            else
                if k < app.nStp
                    u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                else
                    u_t = zeros(6,1);
                end
            end
            
            app.mu_t_bar(:,k+1) = g_t(app,u_t,app.mu_t(:,k));
            app.Sigma_t_bar(:,:,k+1) = G_t(app,u_t,app.mu_t(:,k))*app.Sigma_t(:,:,k)*G_t(app,u_t,app.mu_t(:,k))' ...
                                        + V_t(app,u_t,app.mu_t(:,k))*M_t(app,u_t)*V_t(app,u_t,app.mu_t(:,k))';
        
        end
        
        function localization(app)
            
            xR_I = app.R_pose(1);
            yR_I = app.R_pose(2);
            thetaR_I = app.R_pose(3);

            %% EKF localization known correspondences
            k = app.i_r + 1;

            app.mu_t_bar(:,1) = app.mu_t(:,1);
            app.Sigma_t_bar(:,:,1) = app.Sigma_t(:,:,1);
            
            if app.prob_type == 0
                u_t = app.v_t_array(:,k);
            else
                if k < app.nStp
                    u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                else
                    u_t = zeros(6,1);
                end
            end
            app.mu_t(:,k+1) = g_t(app,u_t,app.mu_t(:,k));
            app.Sigma_t(:,:,k+1) = G_t(app,u_t,app.mu_t(:,k))*app.Sigma_t(:,:,k)*G_t(app,u_t,app.mu_t(:,k))' ...
                                + V_t(app,u_t,app.mu_t(:,k))*M_t(app,u_t)*V_t(app,u_t,app.mu_t(:,k))';
        
            app.mu_t_bar(:,k+1) = app.mu_t(:,k+1);
            app.Sigma_t_bar(:,:,k+1) = app.Sigma_t(:,:,k+1);
            
            % Correction
            if app.sensor_on == 1
                Q_t = diag([app.sigma_d,app.sigma_phi,app.sigma_s]);
                
                for i = 1:app.nP
                    if app.F_info(3,i) > 0
                        m_j = app.M_info(:,i);
                        app.F_info(1:2,i)
                        z_t = h_t(app,[xR_I;yR_I;thetaR_I],m_j);
                        z_t_hat = h_t(app,app.mu_t(:,k),m_j);
                        q = (m_j(1)-app.mu_t(1,k+1))^2+(m_j(2)-app.mu_t(2,k+1))^2;
                        H_t = [ -(m_j(1)-app.mu_t(1,k+1))/sqrt(q), -(m_j(2)-app.mu_t(2,k+1))/sqrt(q), 0
                                (m_j(2)-app.mu_t(2,k+1))/q, -(m_j(1)-app.mu_t(1,k+1))/q, -1
                                0, 0, 0];
                        S_t = H_t*app.Sigma_t(:,:,k+1)*H_t'+Q_t;
                        K_t = app.Sigma_t(:,:,k+1)*H_t'/S_t;
                        app.mu_t(:,k+1) = app.mu_t(:,k+1) + K_t*(z_t-z_t_hat);
                        app.Sigma_t(:,:,k+1) = (eye(3)-K_t*H_t)*app.Sigma_t(:,:,k+1);
                    end
%                     if app.F_info(3,i) > 0
%                         m_j = app.M_info(:,i);
% %                         app.F_info(1:2,i)
% %                         x_real = [xR_I;yR_I;thetaR_I]
% %                         x_hat = app.mu_t(:,k+1)
% %                         sitep = k
% %                         indi = app.F_info(3,i)
%                         z_t = h_t(app,[xR_I;yR_I;thetaR_I],m_j);
%                         z_t_hat = h_t(app,app.mu_t(:,k),m_j);
% %                         err = z_t-z_t_hat;
%                         q = (m_j(1)-app.mu_t(1,k+1))^2+(m_j(2)-app.mu_t(2,k+1))^2;
%                         H_t = [ -(m_j(1)-app.mu_t(1,k+1))/sqrt(q), -(m_j(2)-app.mu_t(2,k+1))/sqrt(q), 0
%                                 (m_j(2)-app.mu_t(2,k+1))/q, -(m_j(1)-app.mu_t(1,k+1))/q, -1
%                                 0, 0, 0];
%                         S_t = H_t*app.Sigma_t(:,:,k+1)*H_t'+Q_t;
%                         K_t = app.Sigma_t(:,:,k+1)*H_t'/S_t;
%                         sum1 = sum1 + K_t*(z_t-z_t_hat);
%                         sum2 = sum2 + K_t*H_t;
%                     end
%                     
%                     app.mu_t(:,k+1) = app.mu_t_bar(:,k+1) + sum1;
%                     app.Sigma_t(:,:,k+1) = (eye(3)-sum2)*app.Sigma_t_bar(:,:,k+1);
                    
%                     print_mub = app.mu_t_bar(:,k+1)
%                     print_sigb = app.Sigma_t_bar(:,:,k+1)
%                     
%                     print_mu = app.mu_t(:,k+1)
%                     print_sig = app.Sigma_t(:,:,k+1)
    %                 q = (m_j(1)-mu_t_bar(1))^2+(m_j(2)-mu_t_bar(2))^2;
    %                 z_t_hat = [sqrt(q);atan2(m_j(2)-mu_t_bar(2),m_j(1)-mu_t_bar(1))-mu_t_bar(3);m_j(3)];
    %                 H_t = [ -(m_j(1)-mu_t_bar(1))/sqrt(q), -(m_j(2)-mu_t_bar(2))/sqrt(q), 0
    %                         (m_j(2)-mu_t_bar(2))/q, -(m_j(1)-mu_t_bar(1))/q, -1
    %                         0, 0, 0];
    %                 S_t = H_t*Sigma_t_bar*H_t'+Q_t;
    %                 K_t = Sigma_t_bar*H_t'/S_t;
    %                 mu_t_bar = mu_t_bar + K_t*(z_t-z_t_hat);
    %                 Sigma_t_bar = (eye(3)-K_t*H_t)*Sigma_t_bar;
                end
            end
        end
        
        function updatePlot(app)
            
            cla(app.UIAxes)
            
            if app.hw_switch == 0
                
                drawTrajectory(app)
                
                if app.nSmp > 0
                    drawSamples(app)
                end
                
                if app.toggle_ekfp == 1
                    
                    mu_bar = zeros(3,app.nStp-1);
                    Sigma_bar = zeros(3,3,app.nStp-1);
                    
                    mu_bar(:,1)         = app.x_t_array(:,1);
                    Sigma_bar(:,:,1)	= zeros(3,3);
                    
                    drawEllipse(app,mu_bar(:,1),Sigma_bar(:,:,1),'#00FFFF')
                    
                    for k = 1:app.nStp-1
                        if app.prob_type == 0
                            u_t = app.v_t_array(:,k);
                        else
                          	u_t = [app.x_t_array(:,k);app.x_t_array(:,k+1)];
                        end
                        g_tk = g_t(app,u_t,mu_bar(:,k));
                        G_tk = G_t(app,u_t,mu_bar(:,k));
                        V_tk = V_t(app,u_t,mu_bar(:,k));
                        M_tk = M_t(app,u_t);
                    
                        % prediction
                        mu_bar(:,k+1)       = g_tk;
                        Sigma_bar(:,:,k+1)	= G_tk*Sigma_bar(:,:,k)*G_tk' + + V_tk*M_tk*V_tk';
                                        
                        drawEllipse(app,mu_bar(:,k+1),Sigma_bar(:,:,k+1),'#00FFFF')
                    end
                    plot(app.UIAxes,app.x_t_array(1,:),app.x_t_array(2,:),'.c')
    
                end
        
            end
            
            if app.nP > 0
                for i = 1:app.nP
                    plot(app.UIAxes,app.L_info(1,i),app.L_info(2,i),char(app.L_info(3,i)),'MarkerEdgeColor','k')
                end
            end
                
            drawRobot(app)
            
            if app.start_flag ~= 0
                if app.nP > 0 && app.sensor_on == 1
                    for i = 1:app.nP
                        if app.F_info(3,i) > 0
                            drawEllipse(app,app.M_info(:,i),diag([app.sigma_d,app.sigma_phi,app.sigma_s]),'#FF0000')
                        end
                    end
                end
                
                for k = 1:app.i_r+1
                    if app.sensor_on == 1
                        drawEllipse(app,app.mu_t(:,k),app.Sigma_t(:,:,k),'#FF00FF')
                    else
                        drawEllipse(app,app.mu_t_bar(:,k),app.Sigma_t_bar(:,:,k),'#00FFFF')
                    end
                    if k <= app.i_r
                        drawRobotGhost(app,app.xR_vec(:,k),0.5)
                    end
                end
            end
            
        end
        
        function sensorMeasurement(app)
            
            thetaR_I = app.R_pose(3,:);
            
            xS_I = app.S_pose(1,:);
            yS_I = app.S_pose(2,:);
            thetaS_I = app.S_pose(3,:);
            
            xM = app.L_info(1,:);
            yM = app.L_info(2,:);
            sM = app.L_info(3,:);
            
            % sensor model
            rti = zeros(1,app.nP);
            phi = zeros(1,app.nP);
            sti = zeros(1,app.nP);
            for j = 1:app.nP
                rti(j) = sqrt((xM(j)-xS_I)^2 + (yM(j)-yS_I)^2) + sample(app,app.sigma_d^2);
            
                phi(j) = atan2(yM(j)-yS_I, xM(j)-xS_I) - thetaR_I + sample(app,app.sigma_phi^2);    
                phi(j) = normalizeFunc(app,phi(j),-pi,pi);
            
                sti(j) = markerS2I(app,char(sM(j)));    
                sti(j) = round(sti(j) + sample(app,app.sigma_s^2));
            end
            
            ang = floor(app.bearing/app.resolution);

            ind = zeros(1,app.nP);
            phiD = zeros(1,app.nP);
            for i = 1:2*ang
                for j = 1:app.nP
                    if rti(1,j) < app.range && phi(j) > app.resolution*(i-1-ang) && phi(j) < app.resolution*(i-ang)
                        if j > 1
                            if rti(1,j-1) < app.range && phi(j-1) > app.resolution*(i-1-ang) && phi(j-1) < app.resolution*(i-ang)
                                if rti(1,j) < rti(1,j-1)
                                    ind(j-1) = 0;
                                    ind(j) = i;
                                end
                            else
                                ind(j) = i;
                            end
                        else
                            ind(j) = i;
                        end
                    end
                    phiD(j) = thetaS_I+app.resolution*(ind(j)-ang-0.5);
                end
            end
            
            app.F_info = [rti;phiD;ind];

            d_i = app.range*ones(1,2*ang);
            mrk = zeros(1,2*ang);
            
            for j = 1:length(rti)
                if ind(j) > 0
                    d_i(ind(j)) = rti(j);
                    mrk(ind(j)) = sti(j);
                end
            end
            
            mx = zeros(1,app.nP);
            my = zeros(1,app.nP);
            for i = 1:app.nP
                mx(1,i) = xS_I+rti(i)*cos(thetaS_I+app.resolution*(ind(i)-ang-0.5));
                my(1,i) = yS_I+rti(i)*sin(thetaS_I+app.resolution*(ind(i)-ang-0.5));
            end
            
            app.M_info = [mx;my;sti];

        end
        
        function updateTrajectory(app)
            
            if app.hw_switch == 1
                app.xR_t = app.XSpinner.Value;
                app.yR_t = app.YSpinner.Value;
                app.thetaR_t = deg2rad(app.HeadingAngSpinner.Value);
            else
                if app.traj_type == 0
                    % circular trajectory
                    
                    app.nStp = 8;
                    
                    x = 1;
                    y = 0;
                    theta = normalizeFunc(app,-pi/2,-pi,pi);
                    
                    v = pi/4*ones(1,app.nStp); % m/s
                    omega = -pi/4*ones(1,app.nStp); % rad/s
                    
                    app.x_array     = x*ones(1,app.nStp);
                    app.y_array     = y*ones(1,app.nStp);
                    app.theta_array = theta*ones(1,app.nStp);
                    
                    for i = 1:app.nStp-1
                        app.x_array(1,i+1) = app.x_array(1,i) - (v(1,i)/omega(1,i))*(sin(app.theta_array(1,i)) - sin(app.theta_array(1,i)+omega(1,i)));
                        app.y_array(1,i+1) = app.y_array(1,i) + (v(1,i)/omega(1,i))*(cos(app.theta_array(1,i)) - cos(app.theta_array(1,i)+omega(1,i)));
                        app.theta_array(1,i+1) = normalizeFunc(app,app.theta_array(1,i) + omega(1,i),-pi,pi);
                    end
                else
                    % u-shape trajectory
                    
                    app.i_r = 0;
                    
                    app.nStp = 15;
                    
                    app.x_array = [0,2,4,6,8,8,8,8,8,8,8,6,4,2,0];
                    app.y_array = [0,0,0,0,0,0,2,4,6,8,8,8,8,8,8];
                    app.theta_array = [0,0,0,0,0,pi/2,pi/2,pi/2,pi/2,pi/2,pi,pi,pi,pi,pi];
                    
                    v = zeros(1,app.nStp); % m/s
                    omega = zeros(1,app.nStp); % rad/s
                    
                    for i = 1:app.nStp-1
                        v(1,i) = sqrt((app.x_array(1,i+1)-app.x_array(1,i))^2+(app.y_array(1,i+1)-app.y_array(1,i))^2); % m/s
                        omega(1,i) = app.theta_array(1,i+1)-app.theta_array(1,i); % rad/s
                    end
                end
                
                app.x_t_array = [app.x_array;app.y_array;app.theta_array];
                app.v_t_array = [v;omega];
                
                app.xR_t = app.x_array(:,1);
                app.yR_t = app.y_array(:,1);
                app.thetaR_t = app.theta_array(:,1);
                
                app.mu_t = zeros(3,app.nStp);
                app.mu_t(:,1) = app.x_t_array(:,1);
                app.Sigma_t = zeros(3,3,app.nStp);
                
                app.xR_vec = zeros(3,app.nStp);
                app.xR_vec(:,1) = app.x_t_array(:,1);
                
                drawTrajectory(app)
                calculatePose(app)
                updateLandmark(app)
                
            end
        end            
        
        function updateRand(app)
            
            app.randP = [];
            
            if app.traj_type == 1
                app.randP = zeros(2,13);
                for i = 1:13
                    app.randP(1,i) = cos((rand()-0.5)*2*pi);
                    app.randP(2,i) = sin((rand()-0.5)*2*pi);
                end
            else
                app.randP = zeros(2,8);
                for i = 1:8
                    app.randP(1,i) = cos((rand()-0.5)*2*pi);
                    app.randP(2,i) = sin((rand()-0.5)*2*pi);
                end
            end
            
        end
        
        function updateLandmark(app)
            
            if app.hw_switch == 1

                point(1).x = app.p1x;
                point(1).y = app.p1y;
                point(1).s = app.p1s;
                
                point(2).x = app.p2x;
                point(2).y = app.p2y;
                point(2).s = app.p2s;
                    
                point(3).x = app.p3x;
                point(3).y = app.p3y;
                point(3).s = app.p3s;

                app.nP = sum(app.marker);
                % nP = length(marker);
                
                xM = zeros(1,app.nP);
                yM = zeros(1,app.nP);
                sM = zeros(1,app.nP);
                k = 0;
                for i = 1:length(app.marker)
                    if app.marker(i) == 1
                        k = k+1;
                        xM(k) = point(i).x;
                        yM(k) = point(i).y;
                        sM(k) = point(i).s;
                    end
                end
                
            else
                
                if app.traj_type == 1
                    if app.on_trj_lndmrk == 1
                        tlNum = 13;
                        
                        point(1).x = app.x_array(1,1);
                        point(1).y = app.y_array(1,1);
                        point(1).s = 'o';
        
                        point(2).x = app.x_array(1,2);
                        point(2).y = app.y_array(1,2);
                        point(2).s = '^';
                        
                        point(3).x = app.x_array(1,3);
                        point(3).y = app.y_array(1,3);
                        point(3).s = '^';
                            
                        point(4).x = app.x_array(1,4);
                        point(4).y = app.y_array(1,4);
                        point(4).s = '^';
                            
                        point(5).x = app.x_array(1,5);
                        point(5).y = app.y_array(1,5);
                        point(5).s = 'o';
                            
                        point(6).x = app.x_array(1,7);
                        point(6).y = app.y_array(1,7);
                        point(6).s = '^';
                            
                        point(7).x = app.x_array(1,8);
                        point(7).y = app.y_array(1,8);
                        point(7).s = '^';
                            
                        point(8).x = app.x_array(1,9);
                        point(8).y = app.y_array(1,9);
                        point(8).s = '^';
                            
                        point(9).x = app.x_array(1,11);
                        point(9).y = app.y_array(1,11);
                        point(9).s = 'o';
                            
                        point(10).x = app.x_array(1,12);
                        point(10).y = app.y_array(1,12);
                        point(10).s = '^';
                            
                        point(11).x = app.x_array(1,13);
                        point(11).y = app.y_array(1,13);
                        point(11).s = '^';
                            
                        point(12).x = app.x_array(1,14);
                        point(12).y = app.y_array(1,14);
                        point(12).s = '^';
                            
                        point(13).x = app.x_array(1,15);
                        point(13).y = app.y_array(1,15);
                        point(13).s = 'p';
                    else
                        tlNum = 0;
                    end
                    
                    if app.arnd_trj_lndmrk == 1
                        atlNum = 13;
                        
                        point(tlNum + 1).x = app.x_array(1,1)+app.r_lm*app.randP(1,1);
                        point(tlNum + 1).y = app.y_array(1,1)+app.r_lm*app.randP(2,1);
                        point(tlNum + 1).s = 's';
        
                        point(tlNum + 2).x = app.x_array(1,2)+app.r_lm*app.randP(1,2);
                        point(tlNum + 2).y = app.y_array(1,2)+app.r_lm*app.randP(2,2);
                        point(tlNum + 2).s = 's';
        
                        point(tlNum + 3).x = app.x_array(1,3)+app.r_lm*app.randP(1,3);
                        point(tlNum + 3).y = app.y_array(1,3)+app.r_lm*app.randP(2,3);
                        point(tlNum + 3).s = 's';
        
                        point(tlNum + 4).x = app.x_array(1,4)+app.r_lm*app.randP(1,4);
                        point(tlNum + 4).y = app.y_array(1,4)+app.r_lm*app.randP(2,4);
                        point(tlNum + 4).s = 's';
        
                        point(tlNum + 5).x = app.x_array(1,5)+app.r_lm*app.randP(1,5);
                        point(tlNum + 5).y = app.y_array(1,5)+app.r_lm*app.randP(2,5);
                        point(tlNum + 5).s = 's';
        
                        point(tlNum + 6).x = app.x_array(1,7)+app.r_lm*app.randP(1,6);
                        point(tlNum + 6).y = app.y_array(1,7)+app.r_lm*app.randP(2,6);
                        point(tlNum + 6).s = 's';
        
                        point(tlNum + 7).x = app.x_array(1,8)+app.r_lm*app.randP(1,7);
                        point(tlNum + 7).y = app.y_array(1,8)+app.r_lm*app.randP(2,7);
                        point(tlNum + 7).s = 's';
        
                        point(tlNum + 8).x = app.x_array(1,9)+app.r_lm*app.randP(1,8);
                        point(tlNum + 8).y = app.y_array(1,9)+app.r_lm*app.randP(2,8);
                        point(tlNum + 8).s = 's';
        
                        point(tlNum + 9).x = app.x_array(1,11)+app.r_lm*app.randP(1,9);
                        point(tlNum + 9).y = app.y_array(1,11)+app.r_lm*app.randP(2,9);
                        point(tlNum + 9).s = 's';
        
                        point(tlNum + 10).x = app.x_array(1,12)+app.r_lm*app.randP(1,10);
                        point(tlNum + 10).y = app.y_array(1,12)+app.r_lm*app.randP(2,10);
                        point(tlNum + 10).s = 's';
        
                        point(tlNum + 11).x = app.x_array(1,13)+app.r_lm*app.randP(1,11);
                        point(tlNum + 11).y = app.y_array(1,13)+app.r_lm*app.randP(2,11);
                        point(tlNum + 11).s = 's';
        
                        point(tlNum + 12).x = app.x_array(1,14)+app.r_lm*app.randP(1,12);
                        point(tlNum + 12).y = app.y_array(1,14)+app.r_lm*app.randP(2,12);
                        point(tlNum + 12).s = 's';
        
                        point(tlNum + 13).x = app.x_array(1,15)+app.r_lm*app.randP(1,13);
                        point(tlNum + 13).y = app.y_array(1,15)+app.r_lm*app.randP(2,13);
                        point(tlNum + 13).s = 's';
                    else
                        atlNum = 0;
                    end
                else
                    if app.on_trj_lndmrk == 1
                        tlNum = 8;
                        
                        point(1).x = app.x_array(1,1);
                        point(1).y = app.y_array(1,1);
                        point(1).s = 'o';
        
                        point(2).x = app.x_array(1,2);
                        point(2).y = app.y_array(1,2);
                        point(2).s = '^';
                        
                        point(3).x = app.x_array(1,3);
                        point(3).y = app.y_array(1,3);
                        point(3).s = 'o';
                            
                        point(4).x = app.x_array(1,4);
                        point(4).y = app.y_array(1,4);
                        point(4).s = '^';
                        
                        point(5).x = app.x_array(1,5);
                        point(5).y = app.y_array(1,5);
                        point(5).s = 'o';
                        
                        point(6).x = app.x_array(1,6);
                        point(6).y = app.y_array(1,6);
                        point(6).s = '^';
                        
                        point(7).x = app.x_array(1,7);
                        point(7).y = app.y_array(1,7);
                        point(7).s = 'o';
                        
                        point(8).x = app.x_array(1,8);
                        point(8).y = app.y_array(1,8);
                        point(8).s = 'p';
                    else
                        tlNum = 0;
                    end
                    
                    if app.arnd_trj_lndmrk == 1
                        point(tlNum + 1).x = app.x_array(1,1)+0.1;
                        point(tlNum + 1).y = app.y_array(1,1)+0.15;
                        point(tlNum + 1).s = 's';
        
                        point(tlNum + 2).x = app.x_array(1,2)+0.13;
                        point(tlNum + 2).y = app.y_array(1,2)-0.21;
                        point(tlNum + 2).s = 's';
                        
                        point(tlNum + 3).x = app.x_array(1,3)-0.12;
                        point(tlNum + 3).y = app.y_array(1,3)-0.2;
                        point(tlNum + 3).s = 's';
                            
                        point(tlNum + 4).x = app.x_array(1,4)-0.1;
                        point(tlNum + 4).y = app.y_array(1,4)+0.24;
                        point(tlNum + 4).s = 's';
                            
                        point(tlNum + 5).x = app.x_array(1,5)+0.14;
                        point(tlNum + 5).y = app.y_array(1,5)+0.12;
                        point(tlNum + 5).s = 's';
                            
                        point(tlNum + 6).x = app.x_array(1,6)-0.06;
                        point(tlNum + 6).y = app.y_array(1,6)-0.1;
                        point(tlNum + 6).s = 's';
                            
                        point(tlNum + 7).x = app.x_array(1,7)+0.06;
                        point(tlNum + 7).y = app.y_array(1,7)-0.11;
                        point(tlNum + 7).s = 's';
                            
                        point(tlNum + 8).x = app.x_array(1,8)+0.06;
                        point(tlNum + 8).y = app.y_array(1,8)-0.11;
                        point(tlNum + 8).s = 's';
                    end
                end
                
                if app.hand_pck_lndmrk == 1
                    for i = 1:app.hpNum
                        point(tlNum + atlNum + i).x = app.hpPos(1,i);
                        point(tlNum + atlNum + i).y = app.hpPos(2,i);
                        point(tlNum + atlNum + i).s = app.hpSgn(i);
                    end
                end
                    
                if app.on_trj_lndmrk == 1 || app.arnd_trj_lndmrk == 1 || (app.hand_pck_lndmrk == 1 && app.hpNum > 0)
                    app.nP = length(point);
                else
                    app.nP = 0;
                end
                    
                xM = zeros(1,app.nP);
                yM = zeros(1,app.nP);
                sM = zeros(1,app.nP);
                for i = 1:app.nP
                    xM(i) = point(i).x;
                    yM(i) = point(i).y;
                    sM(i) = point(i).s;
                end
            end

            app.L_info = [xM;yM;sM];
            
        end
        
        function nextFunc(app)
            
            if app.prob_type == 0
                u_t = app.v_t_array(:,app.i_r);
                xR_t_vec = sample_motion_model_velocity(app,u_t,[app.xR_t;app.yR_t;app.thetaR_t]);
            else
                u_t = [app.x_t_array(:,app.i_r);app.x_t_array(:,app.i_r+1)];
                xR_t_vec = sample_motion_model_odometry(app,u_t,[app.xR_t;app.yR_t;app.thetaR_t]);
            end
    
            app.xR_t = xR_t_vec(1);
            app.yR_t = xR_t_vec(2);
            app.thetaR_t = xR_t_vec(3);

            app.xR_vec(:,app.i_r+1) = xR_t_vec;
        end
        
        function updateProbModel(app)
            
            if app.prob_type == 0
                app.Spinner_5.Visible = 'on';
                app.SpinnerLabel_5.Visible = 'on';
                app.e2Label_3.Visible = 'on';
                app.Spinner_6.Visible = 'on';
                app.SpinnerLabel_6.Visible = 'on';
                app.e2Label_6.Visible = 'on';
            else
                app.Spinner_5.Visible = 'off';
                app.SpinnerLabel_5.Visible = 'off';
                app.e2Label_3.Visible = 'off';
                app.Spinner_6.Visible = 'off';
                app.SpinnerLabel_6.Visible = 'off';
                app.e2Label_6.Visible = 'off';
            end
            
        end
        
        function updateSwitch(app)
            
            if app.hw_switch == 0
                
                app.StartButton.Enable = 'on';
                app.RunButton.Enable = 'on';
                
                app.XSpinner.Enable = 'off';
                app.YSpinner.Enable = 'off';
                app.HeadingAngSpinner.Enable = 'off';
                
                app.Marker1CheckBox.Enable = 'off';
                app.Marker2CheckBox.Enable = 'off';
                app.Marker3CheckBox.Enable = 'off';
                
                app.MxSpinner_1.Enable = 'off';
                app.MxSpinner_2.Enable = 'off';
                app.MxSpinner_3.Enable = 'off';
                
                app.MySpinner_1.Enable = 'off';
                app.MySpinner_2.Enable = 'off';
                app.MySpinner_3.Enable = 'off';
                
                app.SgnDropDown_1.Enable = 'off';
                app.SgnDropDown_2.Enable = 'off';
                app.SgnDropDown_3.Enable = 'off';
                
                updateTrajectory(app)
            else
                
                app.xR_t = app.XSpinner.Value;
                app.yR_t = app.YSpinner.Value;
                app.thetaR_t = deg2rad(app.HeadingAngSpinner.Value);

                app.StartButton.Enable = 'off';
                app.RunButton.Enable = 'off';
                
                app.XSpinner.Enable = 'on';
                app.YSpinner.Enable = 'on';
                app.HeadingAngSpinner.Enable = 'on';
                
                app.Marker1CheckBox.Enable = 'on';
                app.Marker2CheckBox.Enable = 'on';
                app.Marker3CheckBox.Enable = 'on';

                updateMarker(app)
                
            end
            
            calculatePose(app)
            updatePlot(app)
                
        end
        
        function updateMarker(app)
            if app.marker(1) == 1
                app.MxSpinner_1.Enable = 'on';
                app.MySpinner_1.Enable = 'on';
                app.SgnDropDown_1.Enable = 'on';
            else
                app.MxSpinner_1.Enable = 'off';
                app.MySpinner_1.Enable = 'off';
                app.SgnDropDown_1.Enable = 'off';
            end
            if app.marker(2) == 1
                app.MxSpinner_2.Enable = 'on';
                app.MySpinner_2.Enable = 'on';
                app.SgnDropDown_2.Enable = 'on';
            else
                app.MxSpinner_2.Enable = 'off';
                app.MySpinner_2.Enable = 'off';
                app.SgnDropDown_2.Enable = 'off';
            end
            if app.marker(3) == 1
                app.MxSpinner_3.Enable = 'on';
                app.MySpinner_3.Enable = 'on';
                app.SgnDropDown_3.Enable = 'on';
            else
                app.MxSpinner_3.Enable = 'off';
                app.MySpinner_3.Enable = 'off';
                app.SgnDropDown_3.Enable = 'off';
            end
            
            app.nF = app.marker(1) + app.marker(2) + app.marker(3);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            updateRand(app)
            
            app.XSpinner.Value = app.xR_t;
            app.YSpinner.Value = app.yR_t;
            app.HeadingAngSpinner.Value = rad2deg(app.thetaR_t);
            
            updateTrajectory(app)
            updateLandmark(app)
            
            app.RangeSpinner.Value = app.range;
            app.BearingSpinner.Value = rad2deg(app.bearing);
            app.ResSpinner.Value = rad2deg(app.resolution);
            
            app.dSpinner.Value = app.sigma_d;
            app.phiSpinner.Value = rad2deg(app.sigma_phi);
            app.sSpinner.Value = app.sigma_s;
            
            app.Marker1CheckBox.Value = app.marker(1);
            app.Marker2CheckBox.Value = app.marker(2);
            app.Marker3CheckBox.Value = app.marker(3);
            updateMarker(app)
            
            app.MxSpinner_1.Value = app.p1x;
            app.MySpinner_1.Value = app.p1y;
            app.SgnDropDown_1.Value = app.p1s;
            
            app.MxSpinner_2.Value = app.p2x;
            app.MySpinner_2.Value = app.p2y;
            app.SgnDropDown_2.Value = app.p2s;
            
            app.MxSpinner_3.Value = app.p3x;
            app.MySpinner_3.Value = app.p3y;
            app.SgnDropDown_3.Value = app.p3s;
            
            if app.on_trj_lndmrk == 0
                app.OntrajectorypointsCheckBox.Value = 0;
            else
                app.OntrajectorypointsCheckBox.Value = 1;
            end
            
            if app.arnd_trj_lndmrk == 0
                app.AroundtrajectorypointsCheckBox.Value = 0;
            else
                app.AroundtrajectorypointsCheckBox.Value = 1;
            end
            app.distSpinner.Value = app.r_lm;
            
            if app.hand_pck_lndmrk == 0
                app.HandplacedCheckBox.Value = 0;
                app.SgnDropDown_4.Enable = 'Off';
                app.PlaceonaxesButton.Enable = 'Off';
            else
                app.HandplacedCheckBox.Value = 1;
            end
            
            if app.hw_switch == 0
                app.Switch.Value = 'Trajectory';
            else
                app.Switch.Value = 'Manual';
            end
            
            if app.traj_type == 1
                app.CircularButton.Value = 0;
            else
                app.CircularButton.Value = 1;
            end
                        
            if app.prob_type == 1
                app.DeadReckoningButton.Value = 0;
            else
                app.DeadReckoningButton.Value = 1;
            end
            
            app.Spinner_1.Value = app.alpha1*1e2;
            app.Spinner_2.Value = app.alpha2*1e2;
            app.Spinner_3.Value = app.alpha3*1e2;
            app.Spinner_4.Value = app.alpha4*1e2;
            app.Spinner_5.Value = app.alpha5*1e2;
            app.Spinner_6.Value = app.alpha6*1e2;
            
            app.StartButton.Enable = 'on';
            app.RunButton.Enable = 'on';
            
            updateProbModel(app)
            
            updateSwitch(app)
            
            grid(app.UIAxes,'on')
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'padded')
%             app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.PlotBoxAspectRatio = [1.6 1 1];
%             app.UIAxes.ButtonDownFcn = @mouseClick;
            
            updateTrajectory(app)
            calculatePose(app)
            updatePlot(app)
        end

        % Size changed function: KOM613EHomeworkAppUIFigure
        function KOM613EHomeworkAppUIFigureSizeChanged(app, event)
%             position = app.KOM613EHomeworkAppUIFigure.Position;
%             
%             if position(3) < 700
%                 app.KOM613EHomeworkAppUIFigure.Position(3) = 700;
%             end
%             if position(4) < 450
%                 app.KOM613EHomeworkAppUIFigure.Position(4) = 450;
%             end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
            if app.hw_switch == 0
                if app.comp_type == 0
%                     runFunc(app)
                    calculatePose(app)
                    updatePlot(app)
                else
                    runDirectEval(app)
                end
            else
                calculatePose(app)
                updatePlot(app)
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.start_flag = 0;
            app.StartButton.Text = 'Start';
            app.i_r = 0;
            
            app.hpNum = 0;
                
            close all
            cla(app.UIAxes)
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'padded')
            app.UIAxes.PlotBoxAspectRatio = [1.6 1 1];
            if app.hw_switch == 0
                updateTrajectory(app)
                updateLandmark(app)
                updatePlot(app)
            else
%                 updateTrajectorySensor(app)
                updateTrajectory(app)
                updatePlot(app)
            end
            
        end

        % Value changing function: Spinner_1
        function Spinner_1ValueChanging(app, event)
            app.alpha1 = event.Value*0.01;
            
            if app.hw_switch == 0
%                 runFunc(app)
                getSamples(app)
                updatePlot(app)
            end
        end

        % Value changing function: Spinner_2
        function Spinner_2ValueChanging(app, event)
            app.alpha2 = event.Value*0.01;
            
            if app.hw_switch == 0
%                 runFunc(app)
                getSamples(app)
                updatePlot(app)
            end
        end

        % Value changing function: Spinner_3
        function Spinner_3ValueChanging(app, event)
            app.alpha3 = event.Value*0.01;

            if app.hw_switch == 0
%                 runFunc(app)
                getSamples(app)
                updatePlot(app)
            end
        end

        % Value changing function: Spinner_4
        function Spinner_4ValueChanging(app, event)
            app.alpha4 = event.Value*0.01;

            if app.hw_switch == 0
%                 runFunc(app)
                getSamples(app)
                updatePlot(app)
            end
        end

        % Value changing function: Spinner_5
        function Spinner_5ValueChanging(app, event)
            app.alpha5 = event.Value*0.01;

            if app.hw_switch == 0
%                 runFunc(app)
                getSamples(app)
                updatePlot(app)
            end
        end

        % Value changing function: Spinner_6
        function Spinner_6ValueChanging(app, event)
            app.alpha6 = event.Value*0.01;

            if app.hw_switch == 0
%                 runFunc(app)
                getSamples(app)
                updatePlot(app)
            end
        end

        % Selection changed function: NoisedistButtonGroup
        function NoisedistButtonGroupSelectionChanged(app, event)
            selectedButton = app.NoisedistButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Triangular')
                app.dist_type = 1;
            else
                app.dist_type = 0;
            end
            
            if app.hw_switch == 0
%                 runFunc(app)
                getSamples(app)
                updatePlot(app)
            end
        end

        % Selection changed function: ProbabilisticmodelButtonGroup
        function ProbabilisticmodelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProbabilisticmodelButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Odometry')
                app.prob_type = 1;
            else
                app.prob_type = 0;
            end
            
            updateProbModel(app)            
            updateTrajectory(app)
%             runFunc(app)
            getSamples(app)
            updatePlot(app)
        end

        % Selection changed function: TrajectoryButtonGroup
        function TrajectoryButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrajectoryButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'U-Shape')
                app.traj_type = 1;
            else
                app.traj_type = 0;
            end
            
            app.start_flag = 0;
            app.StartButton.Text = 'Start';
            app.i_r = 0;
            cla(app.UIAxes)
            updateTrajectory(app)
            getSamples(app)
            updatePlot(app)
        end

        % Selection changed function: ComputingalgoButtonGroup
        function ComputingalgoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ComputingalgoButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Sampling')
                app.SampleSpinner.Enable = 'on';
                app.comp_type = 0;
                app.RefreshButton.Text = 'Refresh';
%                 runFunc(app)
                updatePlot(app)
            else
                app.SampleSpinner.Enable = 'off';
                app.comp_type = 1;
                app.RefreshButton.Text = 'Execute';
                cla(app.UIAxes)
                drawTrajectory(app)
            end
            
        end

        % Value changing function: SampleSpinner
        function SampleSpinnerValueChanging(app, event)
            app.nSmp = event.Value;
            
            getSamples(app)
            updatePlot(app)
        end

        % Menu selected function: DrawsymbolicrobotMenu
        function DrawsymbolicrobotMenuSelected(app, event)
            if app.toggle_robot == 0
                app.toggle_robot = 1;
                app.DrawsymbolicrobotMenu.Checked = 'on';
            else
                app.toggle_robot = 0;
                app.DrawsymbolicrobotMenu.Checked = 'off';
            end
            
%             drawFunc(app)
            updatePlot(app)
        end

        % Menu selected function: DrawtrajectoryMenu
        function DrawtrajectoryMenuSelected(app, event)
            if app.toggle_traj == 0
                app.toggle_traj = 1;
                app.DrawtrajectoryMenu.Checked = 'on';
            else
                app.toggle_traj = 0;
                app.DrawtrajectoryMenu.Checked = 'off';
            end
            
%             drawFunc(app)
            updatePlot(app)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('KOM 613E-Homework #5\nMertcan Kaya\n518192004');
            uialert(app.KOM613EHomeworkAppUIFigure,message,'About','Icon','info');
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            if strcmp(app.Switch.Value,"Trajectory")
                app.hw_switch = 0;
            else
                app.hw_switch = 1;
            end
            
            cla(app.UIAxes)            
            updateSwitch(app)
            updatePlot(app)
            
        end

        % Value changed function: Marker1CheckBox
        function Marker1CheckBoxValueChanged(app, event)
            app.marker(1) = app.Marker1CheckBox.Value;
            updateMarker(app)
            calculatePose(app)
            updatePlot(app)
        end

        % Value changed function: Marker2CheckBox
        function Marker2CheckBoxValueChanged(app, event)
            app.marker(2) = app.Marker2CheckBox.Value;
            updateMarker(app)
            calculatePose(app)
            updatePlot(app)
        end

        % Value changed function: Marker3CheckBox
        function Marker3CheckBoxValueChanged(app, event)
            app.marker(3) = app.Marker3CheckBox.Value;
            updateMarker(app)
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: MxSpinner_1
        function MxSpinner_1ValueChanging(app, event)
            app.p1x = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: MySpinner_1
        function MySpinner_1ValueChanging(app, event)
            app.p1y = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changed function: SgnDropDown_1
        function SgnDropDown_1ValueChanged(app, event)
            app.p1s = app.SgnDropDown_1.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: MxSpinner_2
        function MxSpinner_2_2ValueChanging(app, event)
            app.p2x = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: MySpinner_2
        function MySpinner_2_2ValueChanging(app, event)
            app.p2y = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changed function: SgnDropDown_2
        function SgnDropDown_2_2ValueChanged(app, event)
            app.p2s = app.SgnDropDown_2.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: MxSpinner_3
        function MxSpinner_3_3ValueChanging(app, event)
            app.p3x = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: MySpinner_3
        function MySpinner_3_3ValueChanging(app, event)
            app.p3y = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changed function: SgnDropDown_3
        function SgnDropDown_3_3ValueChanged(app, event)
            app.p3s = app.SgnDropDown_3.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(app, event)
            app.xR_t = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: YSpinner
        function YSpinnerValueChanging(app, event)
            app.yR_t = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: HeadingAngSpinner
        function HeadingAngSpinnerValueChanging(app, event)
            app.thetaR_t = normalizeFunc(app,deg2rad(event.Value),-pi,pi);
            
            if event.Value < 0
                app.HeadingAngSpinner.Value = event.Value + 360;
            elseif event.Value > 360
                app.HeadingAngSpinner.Value = event.Value - 360;
            end
            
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: RangeSpinner
        function RangeSpinnerValueChanging(app, event)
            app.range = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: BearingSpinner
        function BearingSpinnerValueChanging(app, event)
            app.bearing = deg2rad(event.Value);
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: ResSpinner
        function ResSpinnerValueChanging(app, event)
            app.resolution = deg2rad(event.Value);
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: dSpinner
        function dSpinnerValueChanging(app, event)
            app.sigma_d = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: phiSpinner
        function phiSpinnerValueChanging(app, event)
            app.sigma_phi = deg2rad(event.Value);
            calculatePose(app)
            updatePlot(app)
        end

        % Value changing function: sSpinner
        function sSpinnerValueChanging(app, event)
            app.sigma_s = event.Value;
            calculatePose(app)
            updatePlot(app)
        end

        % Value changed function: SamplePointsButton
        function SamplePointsButtonValueChanged(app, event)
            app.toggle_points = app.SamplePointsButton.Value;
%             drawFunc(app)
            updatePlot(app)
        end

        % Value changed function: MonteCarloButton
        function MonteCarloButtonValueChanged(app, event)
            app.toggle_monte = app.MonteCarloButton.Value;
%             drawFunc(app)
            updatePlot(app)
        end

        % Value changed function: EKFPredButton
        function EKFPredButtonValueChanged(app, event)
            app.toggle_ekfp = app.EKFPredButton.Value;
%             drawFunc(app)
            updatePlot(app)
        end

        % Value changed function: UKFPredButton
        function UKFPredButtonValueChanged(app, event)
            app.toggle_ukfp = app.UKFPredButton.Value;
%             drawFunc(app)
            updatePlot(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if app.start_flag == 0
                app.start_flag = 1;
                app.StartButton.Text = 'Next';
                sensorMeasurement(app)
                localizationNew(app)
                updatePlot(app)
            else
                app.i_r = app.i_r + 1;
                
                if app.i_r > app.nStp-1
                    app.start_flag = 0;
                    app.StartButton.Text = 'Start';
                    app.i_r = 0;
    %                 updateTrajectorySensor(app)
                    updateTrajectory(app)
                    calculatePose(app)
                    updatePlot(app)
                else
                    if app.i_r == app.nStp-1
                        app.StartButton.Text = 'Finish';
                    end
                    nextFunc(app)
                    calculatePose(app)            
                    sensorMeasurement(app)
                    localizationNew(app)
                    updatePlot(app)
                end
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.i_r = 0;
            app.start_flag = 1;
            updateTrajectory(app)
            calculatePose(app)       
            sensorMeasurement(app)
            localizationNew(app)
            updatePlot(app)
            
            pause(1)
            for i = 1:app.nStp-1
                app.StartButton.Enable = 'off';
                app.RunButton.Enable = 'off';
                app.i_r = app.i_r + 1;
                nextFunc(app)
                calculatePose(app)
                sensorMeasurement(app)
                localizationNew(app)
                updatePlot(app)
                pause(1)
            end
            
            app.StartButton.Enable = 'on';
            app.RunButton.Enable = 'on';
            
            app.start_flag = 0;
            app.i_r = 0;
%             updateTrajectorySensor(app)
            updateTrajectory(app)
            calculatePose(app)
            updatePlot(app)
        end

        % Value changed function: SensorSwitch
        function SensorSwitchValueChanged(app, event)
            if strcmp(app.SensorSwitch.Value,"Off")
                app.sensor_on = 0;
            else
                app.sensor_on = 1;
            end
            
            cla(app.UIAxes)            
%             updateSwitch(app)
            updatePlot(app)
        end

        % Value changed function: OntrajectorypointsCheckBox
        function OntrajectorypointsCheckBoxValueChanged(app, event)
            app.on_trj_lndmrk = app.OntrajectorypointsCheckBox.Value;
            
            updateLandmark(app)
            updatePlot(app)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if app.hand_pck_place == 1
                app.hpNum = app.hpNum + 1;
                temp = get(app.UIAxes,'CurrentPoint');
                app.hpPos(:,app.hpNum) = temp(1,1:2)';
                app.hpSgn(app.hpNum) = app.hand_pck_sgn;
                
                updateLandmark(app)
                updatePlot(app)
            end
        end

        % Value changed function: AroundtrajectorypointsCheckBox
        function AroundtrajectorypointsCheckBoxValueChanged(app, event)
            app.arnd_trj_lndmrk = app.AroundtrajectorypointsCheckBox.Value;
            
            if app.arnd_trj_lndmrk == 0
                app.distSpinner.Enable = 'Off';
            else
                app.distSpinner.Enable = 'On';
            end
            
            updateLandmark(app)
            updatePlot(app)
        end

        % Value changing function: distSpinner
        function distSpinnerValueChanging(app, event)
            app.r_lm = event.Value;
            
            updateRand(app)
            updateLandmark(app)
            updatePlot(app)
        end

        % Value changed function: HandplacedCheckBox
        function HandplacedCheckBoxValueChanged(app, event)
            app.hand_pck_lndmrk = app.HandplacedCheckBox.Value;
            
            if app.hand_pck_lndmrk == 0
%                 app.hpNum = 0;
                app.SgnDropDown_4.Enable = 'Off';
                app.PlaceonaxesButton.Enable = 'Off';
            else
                app.SgnDropDown_4.Enable = 'On';
                app.PlaceonaxesButton.Enable = 'On';
            end
            
            updateLandmark(app)
            updatePlot(app)
        end

        % Value changed function: SgnDropDown_4
        function SgnDropDown_4ValueChanged(app, event)
            app.hand_pck_sgn = app.SgnDropDown_4.Value;
        end

        % Value changed function: PlaceonaxesButton
        function PlaceonaxesButtonValueChanged(app, event)
            app.hand_pck_place = app.PlaceonaxesButton.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KOM613EHomeworkAppUIFigure = uifigure('Visible', 'off');
            app.KOM613EHomeworkAppUIFigure.Name = 'KOM613E Homework App';
            app.KOM613EHomeworkAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @KOM613EHomeworkAppUIFigureSizeChanged, true);
            app.FileMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.ViewMenu.Text = 'View';
            app.DrawsymbolicrobotMenu = uimenu(app.ViewMenu);
            app.DrawsymbolicrobotMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawsymbolicrobotMenuSelected, true);
            app.DrawsymbolicrobotMenu.Text = 'Draw symbolic robot';
            app.DrawtrajectoryMenu = uimenu(app.ViewMenu);
            app.DrawtrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawtrajectoryMenuSelected, true);
            app.DrawtrajectoryMenu.Checked = 'on';
            app.DrawtrajectoryMenu.Text = 'Draw trajectory';
            app.HelpMenu = uimenu(app.KOM613EHomeworkAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.KOM613EHomeworkAppUIFigure);
            app.MotionPanel = uipanel(app.GridLayout);
            app.MotionPanel.Title = 'Motion';
            app.TrajectoryButtonGroup = uibuttongroup(app.MotionPanel);
            app.TrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrajectoryButtonGroupSelectionChanged, true);
            app.TrajectoryButtonGroup.Title = 'Trajectory';
            app.CircularButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.UShapeButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.UShapeButton.Text = 'U-Shape';
            app.ProbabilisticmodelButtonGroup = uibuttongroup(app.MotionPanel);
            app.ProbabilisticmodelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProbabilisticmodelButtonGroupSelectionChanged, true);
            app.ProbabilisticmodelButtonGroup.Title = 'Probabilistic model';
            app.DeadReckoningButton = uiradiobutton(app.ProbabilisticmodelButtonGroup);
            app.DeadReckoningButton.Text = 'Dead Reckoning';
            app.DeadReckoningButton.Value = true;
            app.OdometryButton = uiradiobutton(app.ProbabilisticmodelButtonGroup);
            app.OdometryButton.Text = 'Odometry';
            app.ComputingalgoButtonGroup = uibuttongroup(app.MotionPanel);
            app.ComputingalgoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComputingalgoButtonGroupSelectionChanged, true);
            app.ComputingalgoButtonGroup.Title = 'Computing algo';
            app.SamplingButton = uiradiobutton(app.ComputingalgoButtonGroup);
            app.SamplingButton.Text = 'Sampling';
            app.SamplingButton.Value = true;
            app.DirectEvaluationButton = uiradiobutton(app.ComputingalgoButtonGroup);
            app.DirectEvaluationButton.Text = 'Direct Evaluation';
            app.SampleSpinnerLabel = uilabel(app.ComputingalgoButtonGroup);
            app.SampleSpinnerLabel.Text = 'Sample:';
            app.SampleSpinner = uispinner(app.ComputingalgoButtonGroup);
            app.SampleSpinner.ValueChangingFcn = createCallbackFcn(app, @SampleSpinnerValueChanging, true);
            app.SampleSpinner.Limits = [0 9999];
            app.NoisedistButtonGroup = uibuttongroup(app.MotionPanel);
            app.NoisedistButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoisedistButtonGroupSelectionChanged, true);
            app.NormalButton = uiradiobutton(app.NoisedistButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.TriangularButton = uiradiobutton(app.NoisedistButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.NoisedistLabel = uilabel(app.NoisedistButtonGroup);
            app.NoisedistLabel.Text = 'Noise dist:';
            app.ErrorparametersPanel = uipanel(app.MotionPanel);
            app.ErrorparametersPanel.Title = 'Error parameters';
            app.e2Label_1 = uilabel(app.ErrorparametersPanel);
            app.e2Label_1.Text = 'e-2';
            app.e2Label_2 = uilabel(app.ErrorparametersPanel);
            app.e2Label_2.Text = 'e-2';
            app.e2Label_3 = uilabel(app.ErrorparametersPanel);
            app.e2Label_3.Text = 'e-2';
            app.e2Label_4 = uilabel(app.ErrorparametersPanel);
            app.e2Label_4.Text = 'e-2';
            app.e2Label_5 = uilabel(app.ErrorparametersPanel);
            app.e2Label_5.Text = 'e-2';
            app.e2Label_6 = uilabel(app.ErrorparametersPanel);
            app.e2Label_6.Text = 'e-2';
            app.SpinnerLabel_3 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_3.Text = 'α 3';
            app.Spinner_3 = uispinner(app.ErrorparametersPanel);
            app.Spinner_3.Step = 0.001;
            app.Spinner_3.ValueChangingFcn = createCallbackFcn(app, @Spinner_3ValueChanging, true);
            app.Spinner_3.Limits = [0 10];
            app.SpinnerLabel_2 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_2.Text = 'α 2';
            app.Spinner_2 = uispinner(app.ErrorparametersPanel);
            app.Spinner_2.Step = 0.001;
            app.Spinner_2.ValueChangingFcn = createCallbackFcn(app, @Spinner_2ValueChanging, true);
            app.Spinner_2.Limits = [0 10];
            app.SpinnerLabel_5 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_5.Text = 'α 5';
            app.Spinner_5 = uispinner(app.ErrorparametersPanel);
            app.Spinner_5.Step = 0.001;
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_5ValueChanging, true);
            app.Spinner_5.Limits = [0 10];
            app.SpinnerLabel_4 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_4.Text = 'α 4';
            app.Spinner_4 = uispinner(app.ErrorparametersPanel);
            app.Spinner_4.Step = 0.001;
            app.Spinner_4.ValueChangingFcn = createCallbackFcn(app, @Spinner_4ValueChanging, true);
            app.Spinner_4.Limits = [0 10];
            app.Label = uilabel(app.ErrorparametersPanel);
            app.Label.Text = 'α 1';
            app.Spinner_1 = uispinner(app.ErrorparametersPanel);
            app.Spinner_1.Step = 0.001;
            app.Spinner_1.ValueChangingFcn = createCallbackFcn(app, @Spinner_1ValueChanging, true);
            app.Spinner_1.Limits = [0 10];
            app.SpinnerLabel_6 = uilabel(app.ErrorparametersPanel);
            app.SpinnerLabel_6.Text = 'α 6';
            app.Spinner_6 = uispinner(app.ErrorparametersPanel);
            app.Spinner_6.Step = 0.001;
            app.Spinner_6.ValueChangingFcn = createCallbackFcn(app, @Spinner_6ValueChanging, true);
            app.Spinner_6.Limits = [0 10];
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanel.Title = 'Main';
            app.ResetButton = uibutton(app.MainPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RefreshButton = uibutton(app.MainPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SwitchLabel = uilabel(app.MainPanel);
            app.SwitchLabel.Visible = 'off';
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.MainPanel, 'slider');
            app.Switch.Items = {'Trajectory', 'Manual'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Visible = 'off';
            app.Switch.Value = 'Trajectory';
            app.SamplePointsButton = uibutton(app.MainPanel, 'state');
            app.SamplePointsButton.ValueChangedFcn = createCallbackFcn(app, @SamplePointsButtonValueChanged, true);
            app.SamplePointsButton.Text = 'Sample Points';
            app.SamplePointsButton.Value = true;
            app.MonteCarloButton = uibutton(app.MainPanel, 'state');
            app.MonteCarloButton.ValueChangedFcn = createCallbackFcn(app, @MonteCarloButtonValueChanged, true);
            app.MonteCarloButton.Text = 'Monte Carlo';
            app.EKFPredButton = uibutton(app.MainPanel, 'state');
            app.EKFPredButton.ValueChangedFcn = createCallbackFcn(app, @EKFPredButtonValueChanged, true);
            app.EKFPredButton.Text = 'EKF Pred.';
            app.UKFPredButton = uibutton(app.MainPanel, 'state');
            app.UKFPredButton.ValueChangedFcn = createCallbackFcn(app, @UKFPredButtonValueChanged, true);
            app.UKFPredButton.Text = 'UKF Pred.';
            app.StartButton = uibutton(app.MainPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RunButton = uibutton(app.MainPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SensorSwitchLabel = uilabel(app.MainPanel);
            app.SensorSwitchLabel.Text = 'Sensor';
            app.SensorSwitch = uiswitch(app.MainPanel, 'rocker');
            app.SensorSwitch.ValueChangedFcn = createCallbackFcn(app, @SensorSwitchValueChanged, true);
            app.SensorSwitch.Value = 'On';
            app.NavigationPanel = uipanel(app.GridLayout);
            app.NavigationPanel.Title = 'Navigation';
            app.meterLabel_2 = uilabel(app.NavigationPanel);
            app.meterLabel_2.Visible = 'off';
            app.meterLabel_2.Text = 'meter';
            app.meterLabel_3 = uilabel(app.NavigationPanel);
            app.meterLabel_3.Visible = 'off';
            app.meterLabel_3.Text = 'meter';
            app.degreeLabel_4 = uilabel(app.NavigationPanel);
            app.degreeLabel_4.Visible = 'off';
            app.degreeLabel_4.Text = 'degree';
            app.XSpinnerLabel = uilabel(app.NavigationPanel);
            app.XSpinnerLabel.Visible = 'off';
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.NavigationPanel);
            app.XSpinner.Step = 0.01;
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanging, true);
            app.XSpinner.Visible = 'off';
            app.YSpinnerLabel = uilabel(app.NavigationPanel);
            app.YSpinnerLabel.Visible = 'off';
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.NavigationPanel);
            app.YSpinner.Step = 0.01;
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @YSpinnerValueChanging, true);
            app.YSpinner.Visible = 'off';
            app.HeadingAngSpinnerLabel = uilabel(app.NavigationPanel);
            app.HeadingAngSpinnerLabel.Visible = 'off';
            app.HeadingAngSpinnerLabel.Text = 'Heading Ang.';
            app.HeadingAngSpinner = uispinner(app.NavigationPanel);
            app.HeadingAngSpinner.ValueChangingFcn = createCallbackFcn(app, @HeadingAngSpinnerValueChanging, true);
            app.HeadingAngSpinner.Limits = [-359 719];
            app.HeadingAngSpinner.Visible = 'off';
            app.RobotPoseLabel = uilabel(app.NavigationPanel);
            app.RobotPoseLabel.Visible = 'off';
            app.RobotPoseLabel.Text = 'Robot Pose:';
            app.Marker1CheckBox = uicheckbox(app.NavigationPanel);
            app.Marker1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Marker1CheckBoxValueChanged, true);
            app.Marker1CheckBox.Visible = 'off';
            app.Marker1CheckBox.Text = 'Marker #1';
            app.Marker2CheckBox = uicheckbox(app.NavigationPanel);
            app.Marker2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Marker2CheckBoxValueChanged, true);
            app.Marker2CheckBox.Visible = 'off';
            app.Marker2CheckBox.Text = 'Marker #2';
            app.Marker3CheckBox = uicheckbox(app.NavigationPanel);
            app.Marker3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Marker3CheckBoxValueChanged, true);
            app.Marker3CheckBox.Visible = 'off';
            app.Marker3CheckBox.Text = 'Marker #3';
            app.MxSpinnerLabel_1 = uilabel(app.NavigationPanel);
            app.MxSpinnerLabel_1.Visible = 'off';
            app.MxSpinnerLabel_1.Text = 'Mx';
            app.MxSpinner_1 = uispinner(app.NavigationPanel);
            app.MxSpinner_1.Step = 0.001;
            app.MxSpinner_1.ValueChangingFcn = createCallbackFcn(app, @MxSpinner_1ValueChanging, true);
            app.MxSpinner_1.Visible = 'off';
            app.MxSpinnerLabel_2 = uilabel(app.NavigationPanel);
            app.MxSpinnerLabel_2.Visible = 'off';
            app.MxSpinnerLabel_2.Text = 'Mx';
            app.MxSpinner_2 = uispinner(app.NavigationPanel);
            app.MxSpinner_2.Step = 0.001;
            app.MxSpinner_2.ValueChangingFcn = createCallbackFcn(app, @MxSpinner_2_2ValueChanging, true);
            app.MxSpinner_2.Visible = 'off';
            app.MxSpinnerLabel_3 = uilabel(app.NavigationPanel);
            app.MxSpinnerLabel_3.Visible = 'off';
            app.MxSpinnerLabel_3.Text = 'Mx';
            app.MxSpinner_3 = uispinner(app.NavigationPanel);
            app.MxSpinner_3.Step = 0.001;
            app.MxSpinner_3.ValueChangingFcn = createCallbackFcn(app, @MxSpinner_3_3ValueChanging, true);
            app.MxSpinner_3.Visible = 'off';
            app.MySpinnerLabel_1 = uilabel(app.NavigationPanel);
            app.MySpinnerLabel_1.Visible = 'off';
            app.MySpinnerLabel_1.Text = 'My';
            app.MySpinner_1 = uispinner(app.NavigationPanel);
            app.MySpinner_1.Step = 0.001;
            app.MySpinner_1.ValueChangingFcn = createCallbackFcn(app, @MySpinner_1ValueChanging, true);
            app.MySpinner_1.Visible = 'off';
            app.MySpinnerLabel_2 = uilabel(app.NavigationPanel);
            app.MySpinnerLabel_2.Visible = 'off';
            app.MySpinnerLabel_2.Text = 'My';
            app.MySpinner_2 = uispinner(app.NavigationPanel);
            app.MySpinner_2.Step = 0.001;
            app.MySpinner_2.ValueChangingFcn = createCallbackFcn(app, @MySpinner_2_2ValueChanging, true);
            app.MySpinner_2.Visible = 'off';
            app.MySpinnerLabel_3 = uilabel(app.NavigationPanel);
            app.MySpinnerLabel_3.Visible = 'off';
            app.MySpinnerLabel_3.Text = 'My';
            app.MySpinner_3 = uispinner(app.NavigationPanel);
            app.MySpinner_3.Step = 0.001;
            app.MySpinner_3.ValueChangingFcn = createCallbackFcn(app, @MySpinner_3_3ValueChanging, true);
            app.MySpinner_3.Visible = 'off';
            app.SgnDropDownLabel_1 = uilabel(app.NavigationPanel);
            app.SgnDropDownLabel_1.Visible = 'off';
            app.SgnDropDownLabel_1.Text = 'Sgn';
            app.SgnDropDown_1 = uidropdown(app.NavigationPanel);
            app.SgnDropDown_1.Items = {'o', 's', '^', 'p'};
            app.SgnDropDown_1.ValueChangedFcn = createCallbackFcn(app, @SgnDropDown_1ValueChanged, true);
            app.SgnDropDown_1.Visible = 'off';
            app.SgnDropDown_1.Value = 'o';
            app.SgnDropDownLabel_2 = uilabel(app.NavigationPanel);
            app.SgnDropDownLabel_2.Visible = 'off';
            app.SgnDropDownLabel_2.Text = 'Sgn';
            app.SgnDropDown_2 = uidropdown(app.NavigationPanel);
            app.SgnDropDown_2.Items = {'o', 's', '^', 'p'};
            app.SgnDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SgnDropDown_2_2ValueChanged, true);
            app.SgnDropDown_2.Visible = 'off';
            app.SgnDropDown_2.Value = 'o';
            app.SgnDropDownLabel_3 = uilabel(app.NavigationPanel);
            app.SgnDropDownLabel_3.Visible = 'off';
            app.SgnDropDownLabel_3.Text = 'Sgn';
            app.SgnDropDown_3 = uidropdown(app.NavigationPanel);
            app.SgnDropDown_3.Items = {'o', 's', '^', 'p'};
            app.SgnDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SgnDropDown_3_3ValueChanged, true);
            app.SgnDropDown_3.Visible = 'off';
            app.SgnDropDown_3.Value = 'o';
            app.SensorPanel = uipanel(app.GridLayout);
            app.SensorPanel.Title = 'Sensor';
            app.RangeSpinnerLabel = uilabel(app.SensorPanel);
            app.RangeSpinnerLabel.Text = 'Range';
            app.RangeSpinner = uispinner(app.SensorPanel);
            app.RangeSpinner.Step = 0.001;
            app.RangeSpinner.ValueChangingFcn = createCallbackFcn(app, @RangeSpinnerValueChanging, true);
            app.RangeSpinner.Limits = [0 10];
            app.BearingSpinnerLabel = uilabel(app.SensorPanel);
            app.BearingSpinnerLabel.Text = 'Bearing';
            app.BearingSpinner = uispinner(app.SensorPanel);
            app.BearingSpinner.ValueChangingFcn = createCallbackFcn(app, @BearingSpinnerValueChanging, true);
            app.BearingSpinner.Limits = [0 180];
            app.ResSpinnerLabel = uilabel(app.SensorPanel);
            app.ResSpinnerLabel.Text = 'Res.';
            app.ResSpinner = uispinner(app.SensorPanel);
            app.ResSpinner.ValueChangingFcn = createCallbackFcn(app, @ResSpinnerValueChanging, true);
            app.ResSpinner.Limits = [1 90];
            app.ResSpinner.Value = 1;
            app.mLabel = uilabel(app.SensorPanel);
            app.mLabel.Text = 'm';
            app.degLabel = uilabel(app.SensorPanel);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.SensorPanel);
            app.degLabel_2.Text = 'deg';
            app.dSpinnerLabel = uilabel(app.SensorPanel);
            app.dSpinnerLabel.Text = 'σ_d';
            app.dSpinner = uispinner(app.SensorPanel);
            app.dSpinner.Step = 0.001;
            app.dSpinner.ValueChangingFcn = createCallbackFcn(app, @dSpinnerValueChanging, true);
            app.dSpinner.Limits = [0 1];
            app.phiSpinnerLabel = uilabel(app.SensorPanel);
            app.phiSpinnerLabel.Text = 'σ_phi';
            app.phiSpinner = uispinner(app.SensorPanel);
            app.phiSpinner.Step = 0.1;
            app.phiSpinner.ValueChangingFcn = createCallbackFcn(app, @phiSpinnerValueChanging, true);
            app.phiSpinner.Limits = [0 10];
            app.sSpinnerLabel = uilabel(app.SensorPanel);
            app.sSpinnerLabel.Text = 'σ_s';
            app.sSpinner = uispinner(app.SensorPanel);
            app.sSpinner.Step = 0.1;
            app.sSpinner.ValueChangingFcn = createCallbackFcn(app, @sSpinnerValueChanging, true);
            app.sSpinner.Limits = [0 2];
            app.OntrajectorypointsCheckBox = uicheckbox(app.SensorPanel);
            app.OntrajectorypointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OntrajectorypointsCheckBoxValueChanged, true);
            app.OntrajectorypointsCheckBox.Text = 'On trajectory points';
            app.LandmarksLabel = uilabel(app.SensorPanel);
            app.LandmarksLabel.Text = 'Landmarks:';
            app.AroundtrajectorypointsCheckBox = uicheckbox(app.SensorPanel);
            app.AroundtrajectorypointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AroundtrajectorypointsCheckBoxValueChanged, true);
            app.AroundtrajectorypointsCheckBox.Text = 'Around trajectory points';
            app.HandplacedCheckBox = uicheckbox(app.SensorPanel);
            app.HandplacedCheckBox.ValueChangedFcn = createCallbackFcn(app, @HandplacedCheckBoxValueChanged, true);
            app.HandplacedCheckBox.Text = 'Hand-placed';
            app.distSpinnerLabel = uilabel(app.SensorPanel);
            app.distSpinnerLabel.Text = 'dist';
            app.distSpinner = uispinner(app.SensorPanel);
            app.distSpinner.Step = 0.01;
            app.distSpinner.ValueChangingFcn = createCallbackFcn(app, @distSpinnerValueChanging, true);
            app.distSpinner.Limits = [0 10];
            app.mLabel_2 = uilabel(app.SensorPanel);
            app.mLabel_2.Text = 'm';
            app.PlaceonaxesButton = uibutton(app.SensorPanel, 'state');
            app.PlaceonaxesButton.ValueChangedFcn = createCallbackFcn(app, @PlaceonaxesButtonValueChanged, true);
            app.PlaceonaxesButton.Text = 'Place on axes';
            app.SgnDropDown_4Label = uilabel(app.SensorPanel);
            app.SgnDropDown_4Label.Text = 'Sgn';
            app.SgnDropDown_4 = uidropdown(app.SensorPanel);
            app.SgnDropDown_4.Items = {'o', 's', '^', 'p'};
            app.SgnDropDown_4.ValueChangedFcn = createCallbackFcn(app, @SgnDropDown_4ValueChanged, true);
            app.SgnDropDown_4.Value = 'o';
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.KOM613EHomeworkAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KOM613EHomeworkAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KOM613EHomeworkAppUIFigure)
        end
    end
end